classdef retrospective10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GitHubHyperlink                 matlab.ui.control.Hyperlink
        MessageBox                      matlab.ui.control.TextArea
        AutoRecoLabel                   matlab.ui.control.Label
        AutoRecoCheckBox                matlab.ui.control.CheckBox
        RetrospectiveTitleImage         matlab.ui.control.Image
        JoveHyperlink                   matlab.ui.control.Hyperlink
        RetrospectiveCreditLabel        matlab.ui.control.Label
        DashboardTabGroup               matlab.ui.container.TabGroup
        DashboardTab                    matlab.ui.container.Tab
        ReconstructionTypeButtonGroup   matlab.ui.container.ButtonGroup
        DeepLearningButton              matlab.ui.control.RadioButton
        CompressedSensingButton         matlab.ui.control.RadioButton
        ProgressGauge                   matlab.ui.control.LinearGauge
        ExitButton                      matlab.ui.control.Button
        ExportMRDButton                 matlab.ui.control.Button
        ExportGIFButton                 matlab.ui.control.Button
        ExportDCMButton                 matlab.ui.control.Button
        ReconstructButton               matlab.ui.control.Button
        SortKspaceButton                matlab.ui.control.Button
        FilterButton                    matlab.ui.control.Button
        LoadMRDButton                   matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        ReconstructionPanel             matlab.ui.container.Panel
        CinePanel                       matlab.ui.container.Panel
        DynamicsEditFieldLabel          matlab.ui.control.Label
        FramesEditFieldLabel            matlab.ui.control.Label
        RecoTypeDropDownLabel           matlab.ui.control.Label
        DynamicsEditField               matlab.ui.control.NumericEditField
        FramesEditField                 matlab.ui.control.NumericEditField
        RecoTypeDropDown                matlab.ui.control.DropDown
        DetectedRatesBpmPanel           matlab.ui.container.Panel
        RespWindowEditFieldLabel        matlab.ui.control.Label
        RespEditFieldLabel              matlab.ui.control.Label
        HeartEditFieldLabel             matlab.ui.control.Label
        WidthLabel                      matlab.ui.control.Label
        CenterLabel                     matlab.ui.control.Label
        ResetFilterValuesButton         matlab.ui.control.Button
        RespWindowEditField             matlab.ui.control.NumericEditField
        RespWidthEditField              matlab.ui.control.NumericEditField
        RespEditField                   matlab.ui.control.NumericEditField
        HeartWidthEditField             matlab.ui.control.NumericEditField
        HeartEditField                  matlab.ui.control.NumericEditField
        Lamp7                           matlab.ui.control.Lamp
        Lamp6                           matlab.ui.control.Lamp
        Lamp5                           matlab.ui.control.Lamp
        Lamp4                           matlab.ui.control.Lamp
        Lamp3                           matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp1                           matlab.ui.control.Lamp
        NavTabGroup                     matlab.ui.container.TabGroup
        NavTab                          matlab.ui.container.Tab
        HeartCoilSpinner1Label          matlab.ui.control.Label
        HeartCoilSpinner1               matlab.ui.control.Spinner
        RespCoilSpinner1Label           matlab.ui.control.Label
        RespCoilSpinner1                matlab.ui.control.Spinner
        StopMotionButton                matlab.ui.control.Button
        StartMotionButton               matlab.ui.control.Button
        NavNRPoints2EditField           matlab.ui.control.NumericEditField
        NavNRPoints2EditFieldLabel      matlab.ui.control.Label
        NavEnd2EditField                matlab.ui.control.NumericEditField
        NavEnd2EditFieldLabel           matlab.ui.control.Label
        Nav2ButtonGroup                 matlab.ui.container.ButtonGroup
        ExtECHO2Button                  matlab.ui.control.RadioButton
        ExtFID2Button                   matlab.ui.control.RadioButton
        SliceFID2Button                 matlab.ui.control.RadioButton
        Nav1ButtonGroup                 matlab.ui.container.ButtonGroup
        ExtECHO1Button                  matlab.ui.control.RadioButton
        ExtFID1Button                   matlab.ui.control.RadioButton
        SliceFID1Button                 matlab.ui.control.RadioButton
        NavNRPoints1EditField           matlab.ui.control.NumericEditField
        NavNRPoints1EditFieldLabel      matlab.ui.control.Label
        NavEnd1EditField                matlab.ui.control.NumericEditField
        NavEnd1EditFieldLabel           matlab.ui.control.Label
        Nav1Fig                         matlab.ui.control.UIAxes
        Nav2Fig                         matlab.ui.control.UIAxes
        HeartTab                        matlab.ui.container.Tab
        HeartMaxEditField               matlab.ui.control.NumericEditField
        HeartMaxEditFieldLabel          matlab.ui.control.Label
        HeartMinEditField               matlab.ui.control.NumericEditField
        HeartMinEditFieldLabel          matlab.ui.control.Label
        HeartNavFlipSwitch              matlab.ui.control.ToggleSwitch
        HeartNavFlipSwitchLabel         matlab.ui.control.Label
        HeartLogLinButton               matlab.ui.control.Button
        HeartCoilSpinner2Label          matlab.ui.control.Label
        HeartCoilSpinner2               matlab.ui.control.Spinner
        HeartSpectrumFig                matlab.ui.control.UIAxes
        HeartRawNavFig                  matlab.ui.control.UIAxes
        RespTab                         matlab.ui.container.Tab
        RespMaxEditField                matlab.ui.control.NumericEditField
        RespMaxEditFieldLabel           matlab.ui.control.Label
        RespMinEditField                matlab.ui.control.NumericEditField
        RespMinEditFieldLabel           matlab.ui.control.Label
        RespNavFlipSwitch               matlab.ui.control.ToggleSwitch
        RespNavFlipSwitchLabel          matlab.ui.control.Label
        RespLogLinButton                matlab.ui.control.Button
        RespCoilSpinner2Label           matlab.ui.control.Label
        RespCoilSpinner2                matlab.ui.control.Spinner
        RespSpectrumFig                 matlab.ui.control.UIAxes
        RespRawNavFig                   matlab.ui.control.UIAxes
        NavSettingsTab                  matlab.ui.container.Tab
        FilterPerSliceLabel             matlab.ui.control.Label
        RespShiftEditFieldLabel         matlab.ui.control.Label
        MaxRespRateEditFieldLabel       matlab.ui.control.Label
        MinRespRateEditFieldLabel       matlab.ui.control.Label
        MaxHeartRateEditFieldLabel      matlab.ui.control.Label
        MinHeartRateEditFieldLabel      matlab.ui.control.Label
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        MessageLevelSpinnerLabel        matlab.ui.control.Label
        AutoSoundsCheckBoxLabel         matlab.ui.control.Label
        IgnoreRespToggleCheckBoxLabel   matlab.ui.control.Label
        RespToggleCheckBoxLabel         matlab.ui.control.Label
        DiscardEditFieldLabel           matlab.ui.control.Label
        NavLabel2                       matlab.ui.control.Label
        RespLabel                       matlab.ui.control.Label
        SoundMessagesLabel              matlab.ui.control.Label
        ColorsLabel                     matlab.ui.control.Label
        PhysioFilteringLabel            matlab.ui.control.Label
        FilterPerSliceCheckBox          matlab.ui.control.CheckBox
        RespShiftEditField              matlab.ui.control.NumericEditField
        MaxRespRateEditField            matlab.ui.control.NumericEditField
        MinRespRateEditField            matlab.ui.control.NumericEditField
        MaxHeartRateEditField           matlab.ui.control.NumericEditField
        MinHeartRateEditField           matlab.ui.control.NumericEditField
        FilterOrderEditField            matlab.ui.control.NumericEditField
        SpeciesButton                   matlab.ui.container.ButtonGroup
        ZebraFishButton                 matlab.ui.control.RadioButton
        RatButton                       matlab.ui.control.RadioButton
        MouseButton                     matlab.ui.control.RadioButton
        PresetsLabel                    matlab.ui.control.Label
        ColorsButtonGroup               matlab.ui.container.ButtonGroup
        BlackWhiteButton                matlab.ui.control.RadioButton
        ClassicButton                   matlab.ui.control.RadioButton
        MessageLevelSpinner             matlab.ui.control.Spinner
        AutoSoundsCheckBox              matlab.ui.control.CheckBox
        IgnoreRespToggleCheckBox        matlab.ui.control.CheckBox
        RespToggleCheckBox              matlab.ui.control.CheckBox
        DiscardEditField                matlab.ui.control.NumericEditField
        NavAnalysisTabGroup             matlab.ui.container.TabGroup
        NavAnalysisTab                  matlab.ui.container.Tab
        ExcludeEndSlider                matlab.ui.control.Slider
        ExcludeStartSlider              matlab.ui.control.Slider
        ExcludeCheckBoxLabel            matlab.ui.control.Label
        ExcludeCheckBox                 matlab.ui.control.CheckBox
        IncludeEndSlider                matlab.ui.control.Slider
        IncludeStartSlider              matlab.ui.control.Slider
        IncludeCheckBoxLabel            matlab.ui.control.Label
        IncludeCheckBox                 matlab.ui.control.CheckBox
        NavFastSlowSwitch               matlab.ui.control.RockerSwitch
        NavStopButton                   matlab.ui.control.Button
        NavStartButton                  matlab.ui.control.Button
        RangeSlider                     matlab.ui.control.Slider
        RangeEditField                  matlab.ui.control.NumericEditField
        RangeEditFieldLabel             matlab.ui.control.Label
        StartSlider                     matlab.ui.control.Slider
        StartEditField                  matlab.ui.control.NumericEditField
        startsLabel                     matlab.ui.control.Label
        DisplayRangeLabel               matlab.ui.control.Label
        HeartNavFig                     matlab.ui.control.UIAxes
        RespNavFig                      matlab.ui.control.UIAxes
        HeartRateTimeFig                matlab.ui.control.UIAxes
        RespRateTimeFig                 matlab.ui.control.UIAxes
        ParametersTabGroup              matlab.ui.container.TabGroup
        SequenceTab                     matlab.ui.container.Tab
        FlipAnglesViewField             matlab.ui.control.EditField
        FlipAnglesViewFieldLabel        matlab.ui.control.Label
        TEViewField                     matlab.ui.control.NumericEditField
        TEViewFiedLabel                 matlab.ui.control.Label
        FieldOfViewViewField            matlab.ui.control.NumericEditField
        FieldOfViewViewFieldLabel       matlab.ui.control.Label
        NrFlipAnglesViewField           matlab.ui.control.NumericEditField
        NrFlipAnglesViewFieldLabel      matlab.ui.control.Label
        TimeDynViewField                matlab.ui.control.EditField
        TimeDynViewFieldLabel           matlab.ui.control.Label
        ScanTimeViewField               matlab.ui.control.EditField
        ScanTimeViewFieldLabel          matlab.ui.control.Label
        NcViewField                     matlab.ui.control.NumericEditField
        NcViewFieldLabel                matlab.ui.control.Label
        NzViewField                     matlab.ui.control.NumericEditField
        NzViewFieldLabel                matlab.ui.control.Label
        TrajectoryViewField             matlab.ui.control.EditField
        TrajectoryViewFieldLabel        matlab.ui.control.Label
        TRViewField                     matlab.ui.control.NumericEditField
        TRViewFieldLabel                matlab.ui.control.Label
        FillingViewField                matlab.ui.control.NumericEditField
        FillingViewFieldLabel           matlab.ui.control.Label
        AveragesViewField               matlab.ui.control.NumericEditField
        AveragesViewFieldLabel          matlab.ui.control.Label
        NrKlinesViewField               matlab.ui.control.NumericEditField
        NrKlinesViewFieldLabel          matlab.ui.control.Label
        RepetitionsViewField            matlab.ui.control.NumericEditField
        RepetitionsViewFieldLabel       matlab.ui.control.Label
        TrajectorySizeViewField         matlab.ui.control.NumericEditField
        TrajectorySizeViewFieldLabel    matlab.ui.control.Label
        NxViewField                     matlab.ui.control.NumericEditField
        NxViewFieldLabel                matlab.ui.control.Label
        NyViewField                     matlab.ui.control.NumericEditField
        NyViewFieldLabel                matlab.ui.control.Label
        DataFileLabel                   matlab.ui.control.Label
        DataFile                        matlab.ui.control.TextArea
        TrajectoryFig                   matlab.ui.control.UIAxes
        GeometryTab                     matlab.ui.container.Tab
        SliceGapViewField               matlab.ui.control.NumericEditField
        SliceGapViewFieldLabel          matlab.ui.control.Label
        OffsetXViewField                matlab.ui.control.NumericEditField
        OffsetXViewFieldlabel           matlab.ui.control.Label
        OffsetYViewField                matlab.ui.control.NumericEditField
        OffsetYViewFieldLabel           matlab.ui.control.Label
        OffsetZViewField                matlab.ui.control.NumericEditField
        OffsetZViewFieldLabel           matlab.ui.control.Label
        AngleXViewField                 matlab.ui.control.NumericEditField
        AngleXViewFieldLabel            matlab.ui.control.Label
        AngleYViewField                 matlab.ui.control.NumericEditField
        AngleYViewFieldLabel            matlab.ui.control.Label
        AngleZViewField                 matlab.ui.control.NumericEditField
        AngleZViewFieldLabel            matlab.ui.control.Label
        FieldOfViewViewField2           matlab.ui.control.NumericEditField
        FieldOfViewViewField2Label      matlab.ui.control.Label
        AspectRatioViewField            matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel       matlab.ui.control.Label
        SliceThicknessViewField         matlab.ui.control.NumericEditField
        SliceThicknessViewFieldLabel    matlab.ui.control.Label
        PhaseOrientationViewField       matlab.ui.control.NumericEditField
        PhaseOrientationViewFieldLabel  matlab.ui.control.Label
        RecoParametersTab               matlab.ui.container.Tab
        LLRxyzEditFieldLabel            matlab.ui.control.Label
        TVdynEditFieldLabel             matlab.ui.control.Label
        TVxyzEditFieldLabel             matlab.ui.control.Label
        TVcineEditFieldLabel            matlab.ui.control.Label
        ESPIRiTCheckBoxLabel            matlab.ui.control.Label
        VariousLabel                    matlab.ui.control.Label
        CSrecoLabel                     matlab.ui.control.Label
        RingRmCheckBoxLabel             matlab.ui.control.Label
        NLMFCheckBoxLabel               matlab.ui.control.Label
        SharingEditFieldLabel           matlab.ui.control.Label
        FramesharingLabel               matlab.ui.control.Label
        MatlabRecoCheckBox              matlab.ui.control.CheckBox
        MatlabRecoCheckBoxLabel         matlab.ui.control.Label
        AutoGuessRecoCheckBoxLabel      matlab.ui.control.Label
        ReconstructionpresetsLabel      matlab.ui.control.Label
        RecoPresetsButtonGroup          matlab.ui.container.ButtonGroup
        VfaFunctionButton               matlab.ui.control.RadioButton
        RealTimeFunctionButton          matlab.ui.control.RadioButton
        RespFunctionButton              matlab.ui.control.RadioButton
        DiastolicFunctionButton         matlab.ui.control.RadioButton
        SystolicFunctionButton          matlab.ui.control.RadioButton
        ScoutButton                     matlab.ui.control.RadioButton
        AutoGuessRecoCheckBox           matlab.ui.control.CheckBox
        SharingEditField                matlab.ui.control.NumericEditField
        RingRmCheckBox                  matlab.ui.control.CheckBox
        NLMFfilterStrengthEditField     matlab.ui.control.NumericEditField
        NLMFCheckBox                    matlab.ui.control.CheckBox
        ESPIRiTCheckBox                 matlab.ui.control.CheckBox
        TVdynEditField                  matlab.ui.control.NumericEditField
        TVcineEditField                 matlab.ui.control.NumericEditField
        LLRxyzEditField                 matlab.ui.control.NumericEditField
        TVxyzEditField                  matlab.ui.control.NumericEditField
        WVxyzEditField                  matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel             matlab.ui.control.Label
        RadialTab                       matlab.ui.container.Tab
        DensityCorrectCheckBoxLabel     matlab.ui.control.Label
        DensityCorrectCheckBox          matlab.ui.control.CheckBox
        CenterEchoCheckBoxLabel         matlab.ui.control.Label
        CenterEchoCheckBox              matlab.ui.control.CheckBox
        PhaseCorrectCheckBoxLabel       matlab.ui.control.Label
        PhaseCorrectCheckBox            matlab.ui.control.CheckBox
        TrajectoryButtonGroup           matlab.ui.container.ButtonGroup
        RadialAngleEditField            matlab.ui.control.NumericEditField
        HalfCircleRadialButton          matlab.ui.control.RadioButton
        FullCircleRadialButton          matlab.ui.control.RadioButton
        UserDefinedRadialButton         matlab.ui.control.RadioButton
        RadialTrajectoryLabel           matlab.ui.control.Label
        RingMethodCheckBoxLabel         matlab.ui.control.Label
        RingMethodCheckBox              matlab.ui.control.CheckBox
        DataOffsetRadialEditField       matlab.ui.control.NumericEditField
        DataOffsetRadialEditFieldLabel  matlab.ui.control.Label
        GzDelayEditField                matlab.ui.control.NumericEditField
        GzdelayEditFieldLabel           matlab.ui.control.Label
        GyDelayEditField                matlab.ui.control.NumericEditField
        GydelayEditFieldLabel           matlab.ui.control.Label
        GxDelayEditField                matlab.ui.control.NumericEditField
        GxdelayEditFieldLabel           matlab.ui.control.Label
        GradientdelaysLabel             matlab.ui.control.Label
        GradDelayCalibrationCheckBoxLabel  matlab.ui.control.Label
        GradDelayCalibrationCheckBox    matlab.ui.control.CheckBox
        ReconstructionTabGroup          matlab.ui.container.TabGroup
        RecoTab                         matlab.ui.container.Tab
        ShowNavButton                   matlab.ui.control.Button
        OrientationLabel4               matlab.ui.control.Label
        OrientationLabel3               matlab.ui.control.Label
        OrientationLabel1               matlab.ui.control.Label
        OrientationLabel2               matlab.ui.control.Label
        AutoWLButton                    matlab.ui.control.StateButton
        MovieDynEditFieldLabel          matlab.ui.control.Label
        MovieDynEditField               matlab.ui.control.NumericEditField
        LevelScaleSlider                matlab.ui.control.Slider
        LevelScaleSliderLabel           matlab.ui.control.Label
        WindowScaleSlider               matlab.ui.control.Slider
        WindowScaleSliderLabel          matlab.ui.control.Label
        MovieSliceEditField             matlab.ui.control.NumericEditField
        MovieSliceEditFieldLabel        matlab.ui.control.Label
        FinalRespViewField              matlab.ui.control.NumericEditField
        FinalRespViewFieldLabel         matlab.ui.control.Label
        FinalHeartViewField             matlab.ui.control.NumericEditField
        FinalHeartViewFieldLabel        matlab.ui.control.Label
        MovieFrameEditField             matlab.ui.control.NumericEditField
        MovieFrameEditFieldLabel        matlab.ui.control.Label
        MovieCheckBox1                  matlab.ui.control.CheckBox
        MovieCheckBox2                  matlab.ui.control.CheckBox
        MovieCheckBox3                  matlab.ui.control.CheckBox
        MovieDynDecrButton              matlab.ui.control.Button
        MovieDynLabel                   matlab.ui.control.Label
        MovieDynIncrButton              matlab.ui.control.Button
        SenseMapSpinner2                matlab.ui.control.Spinner
        SenseMapSpinner1                matlab.ui.control.Spinner
        MovieCoilSpinner                matlab.ui.control.Spinner
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SenseMapButton                  matlab.ui.control.RadioButton
        kSpaceButton                    matlab.ui.control.RadioButton
        AveragesButton                  matlab.ui.control.RadioButton
        MovieButton                     matlab.ui.control.RadioButton
        MovieSliceDecrButton            matlab.ui.control.Button
        MovieSliceLabel                 matlab.ui.control.Label
        MovieSliceIncrButton            matlab.ui.control.Button
        MovieFrameDecrButton            matlab.ui.control.Button
        MovieFrameLabel                 matlab.ui.control.Label
        MovieFrameIncrButton            matlab.ui.control.Button
        MovieRotateLabel                matlab.ui.control.Label
        MovieccwButton                  matlab.ui.control.Button
        MoviecwButton                   matlab.ui.control.Button
        ScaleLabel                      matlab.ui.control.Label
        MovieStartButton                matlab.ui.control.Button
        MovieStopButton                 matlab.ui.control.Button
        MovieFig                        matlab.ui.control.UIAxes
        LcurveTab                       matlab.ui.container.Tab
        LcurveTVdynCheckBoxLabel        matlab.ui.control.Label
        LcurveTVcineCheckBoxLabel       matlab.ui.control.Label
        LcurveLLRxyzCheckBoxLabel       matlab.ui.control.Label
        LcurveTVxyzCheckBoxLabel        matlab.ui.control.Label
        LcurveWVxyzCheckBoxLabel        matlab.ui.control.Label
        LcurveTVdynCheckBox             matlab.ui.control.CheckBox
        LcurveTVcineCheckBox            matlab.ui.control.CheckBox
        LcurveLLRxyzCheckBox            matlab.ui.control.CheckBox
        LcurveTVxyzCheckBox             matlab.ui.control.CheckBox
        LcurveWVxyzCheckBox             matlab.ui.control.CheckBox
        RegularizationLabel             matlab.ui.control.Label
        LambdaIterationsEditField       matlab.ui.control.NumericEditField
        LambdaIterationsEditFieldLabel  matlab.ui.control.Label
        LambdaStepsEditField            matlab.ui.control.NumericEditField
        LambdaStepsEditFieldLabel       matlab.ui.control.Label
        Lambda2EditField                matlab.ui.control.NumericEditField
        Lambda2EditFieldLabel           matlab.ui.control.Label
        Lambda1EditField                matlab.ui.control.NumericEditField
        Lambda1EditFieldLabel           matlab.ui.control.Label
        LambdaLabel                     matlab.ui.control.Label
        LcurveStopButton                matlab.ui.control.Button
        LcurveStartButton               matlab.ui.control.Button
        LcurveModelErrorFig             matlab.ui.control.UIAxes
        LcurveFig                       matlab.ui.control.UIAxes
        RadialRecoTab                   matlab.ui.container.Tab
        TrajMovie3DStopButton           matlab.ui.control.Button
        TrajMovie3DStartButton          matlab.ui.control.Button
        Traj3DLabel                     matlab.ui.control.Label
        SpokeEditFieldLabel             matlab.ui.control.Label
        SpokeEditField                  matlab.ui.control.NumericEditField
        TrajMovieStopButton             matlab.ui.control.Button
        TrajMovieStartButton            matlab.ui.control.Button
        TrajMovieDynEditField           matlab.ui.control.NumericEditField
        TrajMovieSliceEditField         matlab.ui.control.NumericEditField
        TrajMovieFrameEditField         matlab.ui.control.NumericEditField
        TrajMovieCheckBox3              matlab.ui.control.CheckBox
        TrajMovieCheckBox2              matlab.ui.control.CheckBox
        TrajMovieCheckBox1              matlab.ui.control.CheckBox
        TrajMovieFrameEditFieldLabel    matlab.ui.control.Label
        TrajMovieSliceEditFieldLabel    matlab.ui.control.Label
        TrajMovieDynEditFieldLabel      matlab.ui.control.Label
        TrajectoryLargeFig              matlab.ui.control.UIAxes
        StatusLabel                     matlab.ui.control.Label
        StatusImage                     matlab.ui.control.Image
        StatusBackgroundBorder          matlab.ui.control.EditField
        StatusBackgroundImage           matlab.ui.control.Image
        MRSImage                        matlab.ui.control.Image
        TulipImage                      matlab.ui.control.Image
        Credits                         matlab.ui.control.Label
    end


    % RETROSPECTIVE TRIGGERING RECONSTRUCTION
    %
    % Gustav Strijkers
    % Amsterdam UMC, Academic Medical Center (AMC)
    % Amsterdam, the Netherlands
    % g.j.strijkers@amsterdamumc.nl
    %
    %
    %
    % Version: May, 2025
    % Matlab 2025a
    % 
    %
    % Version 1.0
    % - initial commit
    %
    % Version 2.0 changes:
    % - app designer GUI
    %
    % Version 2.1 changes:
    % - Cleaned-up the code
    % - GIF export file name now includes the number of frames
    % - Automatically start sorting k-space when the number of frames is changed
    % - Added buttons to rotate movie
    % - Added buttons to shift movie in the frame/time dimension
    % - Animated gif export image intensity is scaled by the intensity scaling slider
    % - Trajectory figure added
    % - Full x-scale in plot windows
    % - Added sliders to adjust navigator view range
    % - Parameter no_effective_views is now obsolete
    %
    % Version 3.0 changes:
    % - Added a matlab Conjugent Gradient method for CS-reconstruction without Bart toolbox
    %
    % Version 3.1 changes:
    % - Added the possibility to reconstruction a respiratory CINE
    % - Corrected a bug in the calculation of the total acquisition time
    % - Added the possibility to reconstruct only part of the data
    % - Added detected respiration and heart rate markers and values in navigator power spectrum
    % - Bug fixed number_of_cardiac frames
    % - Improved check on correct loading of MRD file
    % - Cleaned up the code
    %
    % Version 3.2 changes:
    % - Added edit fields for cardiac and respiratory filter values
    % - Fixed an issue with the image size of the movie in the app
    % - Added a reconstruction progress bar
    %
    % Version 3.3 changes:
    % - Bug fixes
    %
    % Version 3.4 changes:
    % - Major clean up of the code to accomodate future versions
    % - Added app message box
    % - Bug fixes
    % - Simplification of how to switch the buttons and lights on and off
    %
    % Version 3.5 changes:
    % - Bug fix for movie frame circular rotation DICOM export
    % - Fix a crash when respiratory or heart filter width > center value
    %
    % Version 3.6 changes:
    % - Added Total Variation in space regularization - Only works with Bart toolbox
    % - Now reading DICOM information from scanner reconstruction, including correct orientations
    % - VNSA weighting added
    % - Added additional search for DICOM information, compatible with PET/MRI systems
    %
    % Version 4.0 changes:
    % - Bug fixes
    % - Reconstructions of 3D+time data implemented
    % - Cleaned-up Matlab conjugate-gradient reconstruction code
    % - Added busy icons
    %
    % Version 5.0 changes:
    % - Added averages and k-space view
    % - Reconstruction of multi-coil data, tested with 2 receiver coils
    % - PCA analysis of navigator data and multi-coil data
    % - ESPIRiT coil sensitivity maps for multi-coil data
    % - Cosmetic changes
    % - Window/Level intensity scaling
    % - Respiration filtering with main peak + 1 harmonic
    % - Image orientation corrected for phase-encoding-orientation direction
    %
    % Version 5.1 changes:
    % - Added MRD export button, not functional yet
    % - Added cardiac absolute binning sorting option
    %
    % Version 5.2 changes:
    % - Added average navigator window (not very useful it seems)
    %
    % Version 5.3 changes:
    % - Radial import, first attempts
    % - Cleaned up code to include radial option
    % - Data window includes now an 'include' and 'exclude' data option
    % - Cleaned up code to make it easier to include more navigator types in the future
    %
    % Version 6.0 changes:
    % - Now supports reconstructions of dynamics
    % - VNSA removed (does not seem very useful)
    % - Average navigator removed (idem)
    % - MRD export and reconstruction by scanner reconstructor
    % - Improved check for loading unique dataset in loadMRD
    %
    % Version 6.1 changes:
    % - Implemented 1 frame reconstruction for 2D and 3D recos
    % - Corrected sequential and centric k-space filling for views2
    % - SUR file export to /1 directory on MRI/PET systems
    % - Multi-slice 2D acquisitions supported
    % - If on pc, check for system MRD and data directories
    %
    % Version 6.2 changes:
    % - Correction on 3D navigator code / bug fixes
    % - Discard points parameter added to prevent zipper artifact
    %
    % Version 6.3 changes:
    % - 2D and 3D Tukey filter of k-space, prevents zipper artifact
    % - Added autointensity button
    % - Navigator movie function added
    % - 3D P2ROUD trajectory import implemented, with pe1_order = 4
    %
    % Version 7.0 changes:
    % - Saves textfile with reconstruction setting parameters
    % - L-curve analysis of single slice 2D cine data
    % - Working on radial reconstruction
    % - Zoom of movie possible
    %
    % Version 7.1 changes:
    % - Advanced navigator panel with presets for mouse and rat
    % - Preset panel with presets for different reconstructions
    %
    % Version 7.2 changes:
    % - Bug fix in initial values navigator sliders
    % - Improved respiration detection for multi-slice acquistions
    %
    % Version 7.3 changes:
    % - Reconstruction of realtime = 1 dynamic per heart beat added
    % - Improved export of dynamic series
    % - Added Locally Low-Rank regularization parameter
    %
    % Version 7.3.1 changes:
    % - Import for B-type scanners
    %
    % Version 7.4
    % - Removed CLEAR option - does not seem to do much
    % - Weighted k-line sharing option
    % - Bug fix in L-curve analysis
    %
    % Version 7.5
    % - Support for non-square FOV
    %
    % Version 8.0
    % - VFA T1 mapping import added
    % - 3D gif data is exported as collage
    %
    % Version 8.1
    % - Cleaned up the code
    % - Software version added
    % - Introduced classes for better maintainablity
    %  
    % Version 8.2 & 8.3
    % - Several bug fixes
    % - TR = 0 bug
    % 
    % Version 8.4
    % - RescaleSlope & RescaleIntercept output in dicom file
    % 
    % Version 8.5
    % - Added button to toggle reconstruction to inspiration or expiration
    % - Fixed a bug with MRD export
    % - Fixed a bug in the image orientation with DICOM export
    %
    % Version 8.6
    % - 3D UTE
    % - median filter for navigator spectrum
    % - GIF export also for averages and k-space
    % - Increased MRD import speed dramatically
    % - Improved L-curve analysis
    %
    % Version 9
    % - Gradient delay calibration for 3D UTE
    % - Worked on 2D (gradient-echo) radial scanning
    % - Aspectratio bug fixed
    % - Viewing of trajectory and trajectory movie
    % - Total generalized variation regularization if available
    % - 2D (multi-slice) gradient-echo radial scanning available
    % - 3D UTE scanning available
    % - Some fancy trajectory visualizations for UTE and radial scans
    % - beta: image orientation indications
    % - gif export bug fix
    % - Auto detect nr discard samples
    % - PCA image denoising
    % - spoke increment added for radial imaging
    % - Bug fixes in the 3D P2ROUD reco
    % - Pressing 's' in the app saves an image of the app
    % - Cleaned up gif, dicom, mrd, sur export functions
    % - Fixed error on exit bug
    %
    % Version 9.4
    % - Massive cleanup, reduced number of methods classes to 1 (r) to improve readability
    % - Updated tool tips  
    % - Better error check for writing MRD, Dicom, and GIF files
    %
    % Version 9.5
    % - Non-square matrix implementation in app
    % - Square pixel size for DICOM export and MEDIS compatibility
    % - Dicom attributes imageType and ScanningSequence added for MEDIS import
    %
    % Version 9.6
    % - Black/white and classic color interface
    % - Analyzed code to remove dependencies on too many toolboxes
    % - Remaining toolboxes: 
    %           MATLAB
    %           Image processing toolbox
    %           Parallel computing toolbox
    %           Signal processing toolbox
    % - Improved B-type scanner import   
    % - Some bug-fixes to the interface
    % - Improved DICOM export
    % - Shift respiratory window option
    % - Export K-space option
    %
    % Version 9.7
    % - FLASH sequence version check for off-center corrections
    % - Adjustments to DICOM export to accomodate ITKsnap import
    % - 'a' hotkey to import matlab file back into app
    %
    % Version 9.8
    % - Some preps to include AI reconstructor  
    % - Some bug fixes to on and off switching of buttons
    % - Pixel interpolation of movie
    % - AI reconstructor implemented
    % - Fixed orientation labels
    % - Fixed filtering overflow error with large data (hopefully fixed)
    % - Some bug fixes to 3D B-type data
    % - Nifti export
    % - Added debug/message levels 1,2,3
    % 
    % Version 10.0
    % - New functionality for external navigator
    % - Cleaned up retro.m class functions
    % - Much improved algorithm for detecting positive respiratory peaks
    % - Fixed an error in the PCA denoising algorithm
    % - Introduced log file in temp directory
    % - Navigator plots x-axes in seconds
    % - Centering of k-space
    % - Check for GPU support with BART
    %
    % Version 10.1
    % - Fixed broken radial import
    % - Improved external navigator analysis
    % - Some bug fixes
    % - Successfully tested bart gpu implementation
    % - Non-local means filter replaces PCA 
    % - External navigator visualization
    %
    % Version 10.2
    % - Bug fixes in MRD export
    % -


    % Variables within the app
    properties (Access = public)

        %#ok<*ST2NM> 
        %#ok<*AGROW>

        % Software version 
        appVersion = "10.2.0"                                           % software version
        bartVersion                                                     % Bart version
        GPUpresentFlag = false                                          % Bart compiled with GPU or not (will be checked automatically)
        AImodule = false                                                % compile with (true) or without (false) AI module
        pcType                                                          % AppleIntel, AppleSilicon, Windows, Unix
     
        % Classes
        r                                   retro                       % methods class
    
        % Directories & filenames
        appPath                                                         % application path
        oldPath                                                         % starting path
        currentPath                                                     % search path for mrdpath.txt
        mrdFileName                                                     % mrd file name
        mrdImportPath                                                   % mrd import path
        mrdAutoImportPath                                               % auto loading mrd import path
        dicomExportPath                                                 % dicom export path
        gifExportPath                                                   % gif export path
        mrdExportPath                                                   % mrd export path
        tag                                                             % filename tag for dicom export
        mrdFile                                                         % mrd data file
        mrdAutoFile                                                     % auto loading mrd file
        logFile                                                         % log file ID
     
        % Acquisition
        acqDur                                                          % total acquisition time

        % Movie parameters
        nrFrames                                                        % number of movie frames
        nrDynamics                                                      % number of dynamics or flip-angles
        nrSlices                                                        % number of slices
        nrCardFrames                                                    % number of cardiac frames
        nrRespFrames                                                    % number of respiratory frames
   
        % Flags
        bartDetectedFlag = false                                        % bart toolbox available true or false
        aiDetectedFlag = false                                          % AI engine detected
        validFilterFlag                                                 % navigator data filtered true or false
        validSortFlag                                                   % valid sorting of the data true or false
        validRecoFlag                                                   % valid reconstruction true or false
        validSenseMapFlag                                               % sense maps exists, true or false
        stopMovieFlag                                                   % flag to stop the movie (true / false)
        stopLcurveFlag                                                  % flag to stop Lcurve calculation (true/false)
        stopNavTimeMovieFlag                                            % flag to stop the navigator movie (true / false)
        stopRawNavMovieFlag                                             % flag to stop the raw navigator FID or ECHO movie (true / false)
        stopRadialMovieFlag                                             % flag to stop the radial trajectorie movie (true / false)
        stop3DMovieFlag                                                 % flag to stop the 3D trajectorie movie (true / false)
        orientationsFlag                                                % plot image orientations (true / false)
        fidNavFlag = false                                              % slice fid navigator (true / false)
        extFidNavFlag = false                                           % external fid navigator (true / false)
        extEchoNavFlag = false                                          % external echo navigator (true / false)
        showExtNavFlag = false                                          % show external navigator slice (true / false)
        blockMessagesFlag = false                                       % block messages in message window
       
        % Plot and movie elements
        progress                                                        % reconstruction progress bar
        cbar                                                            % color bar
        level                                                           % movie intensity level
        window                                                          % movie intensity window
        navMovieSpeed = 25                                              % speed of the navigator movie
        sliceNr                                                         % slice shown in the movie
        dynamicNr                                                       % dynamic shown in the movie
        dWin1                                                           % inclusion datawindow in heartrate plot
        dWin2                                                           % inclusion datawindow in respirationrate plot
        dWin3                                                           % exclusion datawindow in heartrate plot
        dWin4                                                           % exclusion datawindow in respirationrate plot
        dPlot1                                                          % dynamics indication in the heartrate plot
        dPlot2                                                          % dynamics indication in the respirationrate plot
        dPlot1Txt                                                       % dynamics indication text
        dPlot2Txt                                                       % dynamics indication text
        msPlot1                                                         % multislice indication in the heartrate plot
        msPlot2                                                         % multislice indication in the respirationrate plot
        msPlot1Txt                                                      % multislice indication text
        msPlot2Txt                                                      % multislice indication text
        moviePlot                                                       % movie plot handle
        cardRateWindow                                                  % start-end range indicator in time-heartrate figure
        respRateWindow                                                  % start-end range indicator in time-respirationrate figure
        trajPlotData                                                    % trajectory plot array
        trajPlot                                                        % trajectory plot
        traj3DPlot                                                      % trajectory 3D plot handle
        traj3Dcnt                                                       % trajectory 3D plot counter     
        imageRot                                                        % image rotation (0,1,2,3)
        maxNrSpokesPlot = 500                                           % maximum number of spokes visualized in 3D plot
        navTrace3                                                       % navigator trace raw heart navigator
        navTrace4                                                       % navigator trace raw resp navigator
        traceLength = 60                                                % number of points in navigator trace
        navLines                                                        % navigator lines handle
        navArea                                                         % shaded nav area
      
        % Colors and sounds
        green                                                           % other shade of green
        brightgreen                                                     % green
        traceGreen                                                      % green for navigator trace
        red                                                             % other shade of red
        brightred                                                       % red
        blue                                                            % blue
        lightblue                                                       % lightblue
        lightgrey                                                       % lightgray
        yellow                                                          % yellow
        orange                                                          % orange
        traceOrange                                                     % orange for nav trace
        black                                                           % black
        guiBlack                                                        % app black
        guiGrey                                                         % app grey
        guiMiddleGrey                                                   % app middle grey
        guiDarkGrey                                                     % app dark grey
        guiWhite                                                        % app white
        guiBlackBlue                                                    % app black/blueish
        autoRecoColor                                                   % automatic reconstruction background color
        manualRecoColor                                                 % manual reconstruction background color
        warningSound                                                    % warning sound
        errorSound                                                      % error sound
     
    end % properties


    methods (Access = private)

        % -------------------------------------------------
        % Buttons and lights controls
        % -------------------------------------------------


        % Traffic lights
        function SetLightsFcn(app,bitmap)

            code = flip(dec2bin(bitmap,7));

            if code(1) == '0' app.Lamp1.Color = app.brightred; else app.Lamp1.Color = app.brightgreen; end %#ok<*SEPEX> 
            if code(2) == '0' app.Lamp2.Color = app.brightred; else app.Lamp2.Color = app.brightgreen; end
            if code(3) == '0' app.Lamp3.Color = app.brightred; else app.Lamp3.Color = app.brightgreen; end
            if code(4) == '0' app.Lamp4.Color = app.brightred; else app.Lamp4.Color = app.brightgreen; end
            if code(5) == '0' app.Lamp5.Color = app.brightred; else app.Lamp5.Color = app.brightgreen; end
            if code(6) == '0' app.Lamp6.Color = app.brightred; else app.Lamp6.Color = app.brightgreen; end
            if code(7) == '0' app.Lamp7.Color = app.brightred; else app.Lamp7.Color = app.brightgreen; end

            drawnow;

        end % Traffic lights


        % Dashboard buttons
        function SetDashboardButtonsFcn(app, bitmap)

            code = flip(dec2bin(bitmap,8));

            if code(1) == '0' app.LoadMRDButton.Enable = 'off';     else app.LoadMRDButton.Enable = 'on'; end
            if code(2) == '0' app.FilterButton.Enable = 'off';      else app.FilterButton.Enable = 'on'; end
            if code(3) == '0' app.SortKspaceButton.Enable = 'off';  else app.SortKspaceButton.Enable = 'on'; end
            if code(4) == '0' app.ReconstructButton.Enable = 'off'; else app.ReconstructButton.Enable = 'on'; end
            if code(5) == '0' app.ExportDCMButton.Enable = 'off';   else app.ExportDCMButton.Enable = 'on'; end
            if code(6) == '0' app.ExportGIFButton.Enable = 'off';   else app.ExportGIFButton.Enable = 'on'; end
            if code(7) == '0' app.ExportMRDButton.Enable = 'off';   else app.ExportMRDButton.Enable = 'on'; end
            if code(8) == '0' app.ExitButton.Enable = 'off';        else app.ExitButton.Enable = 'on'; end

            drawnow;

        end % Dashboard buttons


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            % The colors
            backgroundColor = guiColors{1}; 
            fontColor = guiColors{2}; 
            boxColor = guiColors{3}; 
            gaugeColor = guiColors{4}; 
            gaugeBackgroundColor = guiColors{5}; 
            figureColor = guiColors{6}; 
            movieFigureColor = guiColors{7}; 
            gridColor = guiColors{8}; 
            app.autoRecoColor = guiColors{9};
            app.manualRecoColor = guiColors{10};

            % Main tabs
            app.UIFigure.Color = backgroundColor;
            app.DashboardTab.BackgroundColor = backgroundColor;
            app.NavTab.BackgroundColor = backgroundColor;
            app.HeartTab.BackgroundColor = backgroundColor;
            app.RespTab.BackgroundColor = backgroundColor;
            app.NavAnalysisTab.BackgroundColor = backgroundColor;
            app.NavSettingsTab.BackgroundColor = backgroundColor;
            app.SequenceTab.BackgroundColor = backgroundColor;
            app.GeometryTab.BackgroundColor = backgroundColor;
            app.RecoParametersTab.BackgroundColor = backgroundColor;
            app.RadialTab.BackgroundColor = backgroundColor;
            app.RecoTab.BackgroundColor = backgroundColor;
            app.LcurveTab.BackgroundColor = backgroundColor;
            app.RadialRecoTab.BackgroundColor = backgroundColor;
            app.MessageBox.FontColor = boxColor;
            app.MessageBox.BackgroundColor = figureColor;
            app.StatusBackgroundImage.BackgroundColor = figureColor;
           
            % Panels
            app.DetectedRatesBpmPanel.BackgroundColor = backgroundColor;
            app.CinePanel.BackgroundColor = backgroundColor;
            app.ReconstructionPanel.BackgroundColor = backgroundColor;
            app.ExportPanel.BackgroundColor = backgroundColor;
            app.SpeciesButton.BackgroundColor = backgroundColor;
            app.ColorsButtonGroup.BackgroundColor = backgroundColor;
            app.RecoPresetsButtonGroup.BackgroundColor = backgroundColor;
            app.TrajectoryButtonGroup.BackgroundColor = backgroundColor;
            app.ButtonGroup.BackgroundColor = backgroundColor;
            app.ReconstructionTypeButtonGroup.BackgroundColor = backgroundColor;
            app.Nav1ButtonGroup.BackgroundColor = backgroundColor;
            app.Nav2ButtonGroup.BackgroundColor = backgroundColor;

            % Label colors
            app.AutoRecoLabel.FontColor = app.guiBlack;
            app.CenterLabel.FontColor = fontColor;
            app.WidthLabel.FontColor = fontColor;
            app.HeartEditFieldLabel.FontColor = fontColor;
            app.RespEditFieldLabel.FontColor = fontColor;
            app.RespWindowEditFieldLabel.FontColor = fontColor;
            app.RecoTypeDropDownLabel.FontColor = fontColor;
            app.FramesEditFieldLabel.FontColor = fontColor;
            app.DynamicsEditFieldLabel.FontColor = fontColor;
            app.WVxyzEditFieldLabel.FontColor = fontColor;
            app.TVxyzEditFieldLabel.FontColor = fontColor;
            app.LLRxyzEditFieldLabel.FontColor = fontColor;
            app.TVcineEditFieldLabel.FontColor = fontColor;
            app.TVdynEditFieldLabel.FontColor = fontColor;
            app.ESPIRiTCheckBoxLabel.FontColor = fontColor;
            app.DeepLearningButton.FontColor = fontColor;
            app.CompressedSensingButton.FontColor = fontColor;
            app.HeartCoilSpinner1Label.FontColor = fontColor;
            app.HeartCoilSpinner2Label.FontColor = fontColor;
            app.HeartNavFlipSwitch.FontColor = fontColor;
            app.HeartNavFlipSwitchLabel.FontColor = fontColor;
            app.HeartLogLinButton.FontColor = fontColor;
            app.HeartLogLinButton.BackgroundColor = backgroundColor;
            app.HeartMinEditFieldLabel.FontColor = fontColor;
            app.HeartMaxEditFieldLabel.FontColor = fontColor;
            app.RespCoilSpinner1Label.FontColor = fontColor;
            app.RespCoilSpinner2Label.FontColor = fontColor;
            app.RespNavFlipSwitch.FontColor = fontColor;
            app.RespNavFlipSwitchLabel.FontColor = fontColor;
            app.RespLogLinButton.FontColor = fontColor;
            app.RespLogLinButton.BackgroundColor = backgroundColor;
            app.RespMinEditFieldLabel.FontColor = fontColor;
            app.RespMaxEditFieldLabel.FontColor = fontColor;
            app.NavEnd1EditFieldLabel.FontColor = fontColor;
            app.NavNRPoints1EditFieldLabel.FontColor = fontColor;
            app.NavEnd2EditFieldLabel.FontColor = fontColor;
            app.NavNRPoints2EditFieldLabel.FontColor = fontColor;
            app.DiscardEditFieldLabel.FontColor = fontColor;
            app.RespToggleCheckBoxLabel.FontColor = fontColor;
            app.IgnoreRespToggleCheckBoxLabel.FontColor = fontColor;
            app.AutoSoundsCheckBoxLabel.FontColor = fontColor;
            app.MessageLevelSpinnerLabel.FontColor = fontColor;
            app.ClassicButton.FontColor = fontColor;
            app.BlackWhiteButton.FontColor = fontColor;
            app.MouseButton.FontColor = fontColor;
            app.ZebraFishButton.FontColor = fontColor;
            app.RatButton.FontColor = fontColor;
            app.FilterOrderEditFieldLabel.FontColor = fontColor;
            app.MinHeartRateEditFieldLabel.FontColor = fontColor;
            app.MaxHeartRateEditFieldLabel.FontColor = fontColor;
            app.MinRespRateEditFieldLabel.FontColor = fontColor;
            app.MaxRespRateEditFieldLabel.FontColor = fontColor;
            app.RespShiftEditFieldLabel.FontColor = fontColor;
            app.FilterPerSliceLabel.FontColor = fontColor;
            app.startsLabel.FontColor = fontColor;
            app.RangeEditFieldLabel.FontColor = fontColor;
            app.NavFastSlowSwitch.FontColor = fontColor;
            app.IncludeCheckBoxLabel.FontColor = fontColor;
            app.ExcludeCheckBoxLabel.FontColor = fontColor;
            app.DataFileLabel.FontColor = fontColor;
            app.NxViewFieldLabel.FontColor = fontColor;
            app.NyViewFieldLabel.FontColor = fontColor;
            app.NzViewFieldLabel.FontColor = fontColor;
            app.NcViewFieldLabel.FontColor = fontColor;
            app.RepetitionsViewFieldLabel.FontColor = fontColor;
            app.NrFlipAnglesViewFieldLabel.FontColor = fontColor;
            app.FlipAnglesViewFieldLabel.FontColor = fontColor;
            app.FieldOfViewViewFieldLabel.FontColor = fontColor;
            app.TRViewFieldLabel.FontColor = fontColor;
            app.TEViewFiedLabel.FontColor = fontColor;
            app.AveragesViewFieldLabel.FontColor = fontColor;
            app.FillingViewFieldLabel.FontColor = fontColor;
            app.NrKlinesViewFieldLabel.FontColor = fontColor;
            app.TrajectoryViewFieldLabel.FontColor = fontColor;
            app.TrajectorySizeViewFieldLabel.FontColor = fontColor;
            app.ScanTimeViewFieldLabel.FontColor = fontColor;
            app.TimeDynViewFieldLabel.FontColor = fontColor;
            app.FieldOfViewViewField2Label.FontColor = fontColor;
            app.SliceGapViewFieldLabel.FontColor = fontColor;
            app.OffsetXViewFieldlabel.FontColor = fontColor;
            app.OffsetYViewFieldLabel.FontColor = fontColor;
            app.OffsetZViewFieldLabel.FontColor = fontColor;
            app.AngleXViewFieldLabel.FontColor = fontColor;
            app.AngleYViewFieldLabel.FontColor = fontColor;
            app.AngleZViewFieldLabel.FontColor = fontColor;
            app.AspectRatioViewFieldLabel.FontColor = fontColor;
            app.SliceThicknessViewFieldLabel.FontColor = fontColor;
            app.PhaseOrientationViewFieldLabel.FontColor = fontColor;
            app.NLMFCheckBoxLabel.FontColor = fontColor;
            app.RingRmCheckBoxLabel.FontColor = fontColor;
            app.AutoGuessRecoCheckBoxLabel.FontColor = fontColor;
            app.MatlabRecoCheckBoxLabel.FontColor = fontColor;
            app.SharingEditFieldLabel.FontColor = fontColor;
            app.ScoutButton.FontColor = fontColor;
            app.SystolicFunctionButton.FontColor = fontColor;
            app.DiastolicFunctionButton.FontColor = fontColor;
            app.RespFunctionButton.FontColor = fontColor;
            app.RealTimeFunctionButton.FontColor = fontColor;
            app.VfaFunctionButton.FontColor = fontColor;
            app.GradDelayCalibrationCheckBoxLabel.FontColor = fontColor;
            app.RingMethodCheckBoxLabel.FontColor = fontColor;
            app.GxdelayEditFieldLabel.FontColor = fontColor;
            app.GydelayEditFieldLabel.FontColor = fontColor;
            app.GzdelayEditFieldLabel.FontColor = fontColor;
            app.DataOffsetRadialEditFieldLabel.FontColor = fontColor;
            app.HalfCircleRadialButton.FontColor = fontColor;
            app.FullCircleRadialButton.FontColor = fontColor;
            app.UserDefinedRadialButton.FontColor = fontColor;
            app.CenterEchoCheckBoxLabel.FontColor = fontColor;
            app.PhaseCorrectCheckBoxLabel.FontColor = fontColor;
            app.DensityCorrectCheckBoxLabel.FontColor = fontColor;
            app.MovieFrameEditFieldLabel.FontColor = fontColor;
            app.MovieSliceEditFieldLabel.FontColor = fontColor;
            app.MovieDynEditFieldLabel.FontColor = fontColor;
            app.FinalHeartViewFieldLabel.FontColor = fontColor;
            app.FinalRespViewFieldLabel.FontColor = fontColor;
            app.LevelScaleSliderLabel.FontColor = fontColor;
            app.WindowScaleSliderLabel.FontColor = fontColor;
            app.MovieFrameLabel.FontColor = fontColor;
            app.MovieSliceLabel.FontColor = fontColor;
            app.MovieDynLabel.FontColor = fontColor;
            app.MovieRotateLabel.FontColor = fontColor;
            app.MovieButton.FontColor = fontColor;
            app.AveragesButton.FontColor = fontColor;
            app.kSpaceButton.FontColor = fontColor;
            app.SenseMapButton.FontColor = fontColor;
            app.Lambda1EditFieldLabel.FontColor = fontColor;
            app.Lambda2EditFieldLabel.FontColor = fontColor;
            app.LambdaStepsEditFieldLabel.FontColor = fontColor;
            app.LambdaIterationsEditFieldLabel.FontColor = fontColor;
            app.LcurveWVxyzCheckBoxLabel.FontColor = fontColor;
            app.LcurveTVxyzCheckBoxLabel.FontColor = fontColor;
            app.LcurveLLRxyzCheckBoxLabel.FontColor = fontColor;
            app.LcurveTVcineCheckBoxLabel.FontColor = fontColor;
            app.LcurveTVdynCheckBoxLabel.FontColor = fontColor;
            app.TrajMovieFrameEditFieldLabel.FontColor = fontColor;
            app.TrajMovieSliceEditFieldLabel.FontColor = fontColor;
            app.TrajMovieDynEditFieldLabel.FontColor = fontColor;
            app.SpokeEditFieldLabel.FontColor = fontColor;
            app.SliceFID1Button.FontColor = fontColor;
            app.ExtFID1Button.FontColor = fontColor;
            app.ExtECHO1Button.FontColor = fontColor;
            app.SliceFID2Button.FontColor = fontColor;
            app.ExtFID2Button.FontColor = fontColor;
            app.ExtECHO2Button.FontColor = fontColor;

            % Gauges
            app.ProgressGauge.FontColor = gaugeColor;
            app.ProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Figures
            app.Nav1Fig.Title.Color = fontColor;
            app.Nav1Fig.XColor = fontColor;
            app.Nav1Fig.YColor = fontColor;
            app.Nav1Fig.Color = figureColor;
            app.Nav2Fig.Title.Color = fontColor;
            app.Nav2Fig.XColor = fontColor;
            app.Nav2Fig.YColor = fontColor;
            app.Nav2Fig.Color = figureColor;
            app.HeartRawNavFig.Title.Color = fontColor;
            app.HeartRawNavFig.XColor = fontColor;
            app.HeartRawNavFig.YColor = fontColor;
            app.HeartRawNavFig.Color = figureColor;
            app.HeartSpectrumFig.Title.Color = fontColor;
            app.HeartSpectrumFig.XColor = fontColor;
            app.HeartSpectrumFig.YColor = fontColor;
            app.HeartSpectrumFig.Color = figureColor;
            app.RespRawNavFig.Title.Color = fontColor;
            app.RespRawNavFig.XColor = fontColor;
            app.RespRawNavFig.YColor = fontColor;
            app.RespRawNavFig.Color = figureColor;
            app.RespSpectrumFig.Title.Color = fontColor;
            app.RespSpectrumFig.XColor = fontColor;
            app.RespSpectrumFig.YColor = fontColor;
            app.RespSpectrumFig.Color = figureColor;
            app.HeartNavFig.Title.Color = fontColor;
            app.HeartNavFig.XColor = fontColor;
            app.HeartNavFig.YColor = fontColor;
            app.HeartNavFig.Color = figureColor;
            app.RespNavFig.Title.Color = fontColor;
            app.RespNavFig.XColor = fontColor;
            app.RespNavFig.YColor = fontColor;
            app.RespNavFig.Color = figureColor;
            app.HeartRateTimeFig.Title.Color = fontColor;
            app.HeartRateTimeFig.XColor = fontColor;
            app.HeartRateTimeFig.YColor = fontColor;
            app.HeartRateTimeFig.Color = figureColor;
            app.RespRateTimeFig.Title.Color = fontColor;
            app.RespRateTimeFig.XColor = fontColor;
            app.RespRateTimeFig.YColor = fontColor;
            app.RespRateTimeFig.Color = figureColor;
            app.LcurveFig.Title.Color = fontColor;
            app.LcurveFig.XColor = fontColor;
            app.LcurveFig.YColor = fontColor;
            app.LcurveFig.Color = figureColor;
            app.LcurveFig.GridColor = gridColor;
            app.LcurveModelErrorFig.Title.Color = fontColor;
            app.LcurveModelErrorFig.XColor = fontColor;
            app.LcurveModelErrorFig.YColor = fontColor;
            app.LcurveModelErrorFig.Color = figureColor;
            app.LcurveModelErrorFig.GridColor = gridColor;
            app.MovieFig.Color = movieFigureColor;
            app.MovieFig.XColor = movieFigureColor;
            app.MovieFig.YColor = movieFigureColor;
            app.TrajectoryLargeFig.Color = figureColor;
            app.TrajectoryLargeFig.XColor = movieFigureColor;
            app.TrajectoryLargeFig.YColor = movieFigureColor;
            app.TrajectoryFig.Color = figureColor;
            app.TrajectoryFig.XColor = movieFigureColor;
            app.TrajectoryFig.YColor = movieFigureColor;

        end % SetAppColors


        % App version check
        function CheckVersionFnc(app)

            try

                currentVersion = convertStringsToChars(app.appVersion);

                newestVersions = {"10.5","10.4","10.3","10.2","10.1","10.0","9.9","9.8","9.7","9.6","9.5","9.4"};

                webtxt = [];
                ver = 0;
                while isempty(webtxt) && ver<=length(newestVersions)
                    try
                        ver = ver + 1;
                        newVersionFile = strcat("https://github.com/Moby1971/Retrospective-v",newestVersions{ver},"/blob/main/appversion.txt");
                        webtxt = webread(newVersionFile);
                    catch
                    end
                end
              
                pos = strfind(lower(webtxt),"retrospective_version=");
                newVersion = webtxt(pos+22:pos+22+strlength(newestVersions{ver})+1);

                app.TextMessage(strcat("Current version = ",currentVersion),1);
                app.TextMessage(strcat("Newest version = ",newVersion),1);
                
                newVersion = strrep(newVersion,".","");
                newVersionNr = str2num(newVersion);
                currentVersion = strrep(currentVersion,".","");
                currentVersionNr = str2num(currentVersion);

                if currentVersionNr < newVersionNr
                    app.TextMessage('A new version of RETROSPECTIVE is available ...');
                    app.TextMessage('Visit https://github.com/Moby1971/ to update ...');
                else
                    app.TextMessage('RETROSPECTIVE is up to date ...');
                end

            catch ME

                app.TextMessage(ME.message,2);
                app.TextMessage('Could not check retrospective version ...');

            end

        end % CheckVersionFnc


        % Navigator selection buttons
        function NavSelButtonsFcn(app, on)

            if on

                app.NavEnd1EditField.Enable = 'on';
                app.NavEnd1EditFieldLabel.Enable = 'on';
                app.NavNRPoints1EditField.Enable = 'on';
                app.NavNRPoints1EditFieldLabel.Enable = 'on';
                app.HeartNavFlipSwitch.Enable = 'on';
                app.HeartNavFlipSwitchLabel.Enable = 'on';
                app.NavEnd2EditField.Enable = 'on';
                app.NavEnd2EditFieldLabel.Enable = 'on';
                app.NavNRPoints2EditField.Enable = 'on';
                app.NavNRPoints2EditFieldLabel.Enable = 'on';
                app.RespNavFlipSwitch.Enable = 'on';
                app.RespNavFlipSwitchLabel.Enable = 'on';
                app.DiscardEditField.Enable = 'on';
                app.DiscardEditFieldLabel.Enable = 'on';
                app.StartMotionButton.Enable = 'on';
                app.StopMotionButton.Enable = 'on';

                if app.fidNavFlag buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.SliceFID1Button.Enable = buttonStatus;
                app.SliceFID2Button.Enable = buttonStatus;

                if app.extFidNavFlag buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.ExtFID1Button.Enable = buttonStatus;
                app.ExtFID2Button.Enable = buttonStatus;

                if app.extEchoNavFlag buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.ExtECHO1Button.Enable = buttonStatus;
                app.ExtECHO2Button.Enable = buttonStatus;

                if app.r.multiCoilFlag buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.HeartCoilSpinner1.Enable = buttonStatus;
                app.HeartCoilSpinner1Label.Enable = buttonStatus;
                app.RespCoilSpinner1.Enable = buttonStatus;
                app.RespCoilSpinner1Label.Enable = buttonStatus;
                app.HeartCoilSpinner2.Enable = buttonStatus;
                app.HeartCoilSpinner2Label.Enable = buttonStatus;
                app.RespCoilSpinner2.Enable = buttonStatus;
                app.RespCoilSpinner2Label.Enable = buttonStatus;

                app.HeartMinEditField.Enable = 'on';
                app.HeartMinEditFieldLabel.Enable = 'on';
                app.HeartMaxEditField.Enable = 'on';
                app.HeartMaxEditFieldLabel.Enable = 'on';
                app.RespMinEditField.Enable = 'on';
                app.RespMinEditFieldLabel.Enable = 'on';
                app.RespMaxEditField.Enable = 'on';
                app.RespMaxEditFieldLabel.Enable = 'on';
                app.HeartLogLinButton.Enable = 'on';
                app.RespLogLinButton.Enable = 'on';
                app.CenterLabel.Enable = 'on';
                app.WidthLabel.Enable = 'on';
                app.HeartEditField.Enable = 'on';
                app.HeartEditFieldLabel.Enable = 'on';
                app.RespEditField.Enable = 'on';
                app.RespEditFieldLabel.Enable = 'on';
                app.HeartWidthEditField.Enable = 'on';
                app.RespWidthEditField.Enable = 'on';
                app.RespWindowEditField.Enable = 'on';
                app.RespWindowEditFieldLabel.Enable = 'on';
                app.RespEditFieldLabel.Enable = 'on';
                app.ResetFilterValuesButton.Enable = 'on';
                app.MouseButton.Enable = 'on';
                app.RatButton.Enable = 'on';
                app.ZebraFishButton.Enable = 'on';
                app.FilterOrderEditField.Enable = 'on';
                app.FilterOrderEditFieldLabel.Enable = 'on';
                app.MaxHeartRateEditField.Enable ='on';
                app.MaxHeartRateEditFieldLabel.Enable = 'on';
                app.MinHeartRateEditField.Enable ='on';
                app.MinHeartRateEditFieldLabel.Enable = 'on';
                app.MinRespRateEditField.Enable = 'on';
                app.MinRespRateEditFieldLabel.Enable = 'on';
                app.MaxRespRateEditField.Enable = 'on';
                app.MaxRespRateEditFieldLabel.Enable = 'on';
                app.RespShiftEditField.Enable = 'on';
                app.RespShiftEditFieldLabel.Enable = 'on';
                app.FilterPerSliceLabel.Enable = 'on';
                app.FilterPerSliceCheckBox.Enable = 'on';
                app.RespToggleCheckBox.Enable = 'on';
                app.RespToggleCheckBoxLabel.Enable = 'on';
                app.IgnoreRespToggleCheckBox.Enable = 'on';
                app.IgnoreRespToggleCheckBoxLabel.Enable = 'on';
               
            else

                app.NavEnd1EditField.Enable = 'off';
                app.NavEnd1EditFieldLabel.Enable = 'off';
                app.NavNRPoints1EditField.Enable = 'off';
                app.NavNRPoints1EditFieldLabel.Enable = 'off';
                app.NavEnd2EditField.Enable = 'off';
                app.NavEnd2EditFieldLabel.Enable = 'off';
                app.NavNRPoints2EditField.Enable = 'off';
                app.NavNRPoints2EditFieldLabel.Enable = 'off';
                app.HeartNavFlipSwitch.Enable = 'off';
                app.HeartNavFlipSwitchLabel.Enable = 'off';
                app.RespNavFlipSwitch.Enable = 'off';
                app.RespNavFlipSwitchLabel.Enable = 'off';
                app.DiscardEditField.Enable = 'off';
                app.DiscardEditFieldLabel.Enable = 'off';
                app.SharingEditField.Enable = 'off';
                app.SharingEditFieldLabel.Enable = 'off';
                app.HeartCoilSpinner2.Enable = 'off';
                app.HeartCoilSpinner2Label.Enable = 'off';
                app.RespCoilSpinner2.Enable = 'off';
                app.RespCoilSpinner2Label.Enable = 'off';
                app.HeartMinEditField.Enable = 'off';
                app.HeartMinEditFieldLabel.Enable = 'off';
                app.HeartMaxEditField.Enable = 'off';
                app.HeartMaxEditFieldLabel.Enable = 'off';
                app.RespMinEditField.Enable = 'off';
                app.RespMinEditFieldLabel.Enable = 'off';
                app.RespMaxEditField.Enable = 'off';
                app.RespMaxEditFieldLabel.Enable = 'off';
                app.SliceFID1Button.Enable = 'off';
                app.SliceFID2Button.Enable = 'off';
                app.ExtFID1Button.Enable = 'off';
                app.ExtFID2Button.Enable = 'off';
                app.ExtECHO1Button.Enable = 'off';
                app.ExtECHO2Button.Enable = 'off';
                app.StartMotionButton.Enable = 'off';
                app.StopMotionButton.Enable = 'off';
                app.HeartLogLinButton.Enable = 'off';
                app.RespLogLinButton.Enable = 'off';
                app.CenterLabel.Enable = 'off';
                app.WidthLabel.Enable = 'off';
                app.HeartEditField.Enable = 'off';
                app.HeartEditFieldLabel.Enable = 'off';
                app.RespEditField.Enable = 'off';
                app.RespEditFieldLabel.Enable = 'off';
                app.HeartWidthEditField.Enable = 'off';
                app.RespWidthEditField.Enable = 'off';
                app.RespWindowEditField.Enable = 'off';
                app.RespWindowEditFieldLabel.Enable = 'off';
                app.ResetFilterValuesButton.Enable = 'off';
                app.MouseButton.Enable = 'off';
                app.RatButton.Enable = 'off';
                app.ZebraFishButton.Enable = 'off';
                app.FilterOrderEditField.Enable = 'off';
                app.FilterOrderEditFieldLabel.Enable = 'off';
                app.MaxHeartRateEditField.Enable ='off';
                app.MaxHeartRateEditFieldLabel.Enable = 'off';
                app.MinHeartRateEditField.Enable ='off';
                app.MinHeartRateEditFieldLabel.Enable = 'off';
                app.MinRespRateEditField.Enable = 'off';
                app.MinRespRateEditFieldLabel.Enable = 'off';
                app.MaxRespRateEditField.Enable = 'off';
                app.MaxRespRateEditFieldLabel.Enable = 'off';
                app.RespShiftEditField.Enable = 'off';
                app.RespShiftEditFieldLabel.Enable = 'off';
                app.FilterPerSliceLabel.Enable = 'off';
                app.FilterPerSliceCheckBox.Enable = 'off';
                app.RespToggleCheckBox.Enable = 'off';
                app.RespToggleCheckBoxLabel.Enable = 'off';
                app.IgnoreRespToggleCheckBox.Enable = 'off';
                app.IgnoreRespToggleCheckBoxLabel.Enable = 'off';

            end

            drawnow;

        end % Navigator selection buttons


        % Navigator viewing buttons
        function NavViewingButtonsFcn(app,on)

            if on

                app.StartEditField.Enable = 'on';
                app.startsLabel.Enable = 'on';
                app.RangeEditField.Enable = 'on';
                app.RangeEditFieldLabel.Enable = 'on';
                app.StartSlider.Enable = 'on';
                app.RangeSlider.Enable = 'on';
                app.NavStartButton.Enable = 'on';
                app.NavStopButton.Enable = 'on';
                app.NavFastSlowSwitch.Enable = 'on';

                if (app.DynamicsEditField.Value == 1) && ~app.r.multi2DFlag && ~app.r.vfaDataFlag
                    app.IncludeCheckBox.Enable = 'on';
                    app.IncludeCheckBoxLabel.Enable = 'on';
                    app.ExcludeCheckBox.Enable = 'on';
                    app.ExcludeCheckBoxLabel.Enable = 'on';
                else
                    app.IncludeCheckBox.Enable = 'off';
                    app.IncludeCheckBoxLabel.Enable = 'off';
                    app.ExcludeCheckBox.Enable = 'off';
                    app.ExcludeCheckBoxLabel.Enable = 'off';
                    app.IncludeStartSlider.Enable = 'off';
                    app.IncludeEndSlider.Enable = 'off';
                    app.ExcludeStartSlider.Enable = 'off';
                    app.ExcludeEndSlider.Enable = 'off';
                end

                if app.IncludeCheckBox.Value buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.IncludeStartSlider.Enable = buttonStatus;
                app.IncludeEndSlider.Enable = buttonStatus;

                if app.ExcludeCheckBox.Value buttonStatus = 'on'; else buttonStatus = 'off'; end
                app.ExcludeStartSlider.Enable = buttonStatus;
                app.ExcludeEndSlider.Enable = buttonStatus;

            else

                app.StartEditField.Enable = 'off';
                app.startsLabel.Enable = 'off';
                app.RangeEditField.Enable = 'off';
                app.RangeEditFieldLabel.Enable = 'off';
                app.StartSlider.Enable = 'off';
                app.RangeSlider.Enable = 'off';
                app.NavStartButton.Enable = 'off';
                app.NavStopButton.Enable = 'off';
                app.NavFastSlowSwitch.Enable = 'off';
                app.IncludeCheckBox.Enable = 'off';
                app.IncludeCheckBoxLabel.Enable = 'off';
                app.IncludeStartSlider.Enable = 'off';
                app.IncludeEndSlider.Enable = 'off';
                app.ExcludeCheckBox.Enable = 'off';
                app.ExcludeCheckBoxLabel.Enable = 'off';
                app.ExcludeStartSlider.Enable = 'off';
                app.ExcludeEndSlider.Enable = 'off';

            end

            drawnow;

        end % Navigator viewing buttons


        % Cine reco buttons
        function CineRecoButtonsFcn(app,on)

            if on

                app.ScoutButton.Enable ='on';
                app.SystolicFunctionButton.Enable = 'on';
                app.DiastolicFunctionButton.Enable = 'on';
                app.RespFunctionButton.Enable = 'on';
                app.FramesEditField.Enable = 'on';
                app.FramesEditFieldLabel.Enable = 'on';
                app.DynamicsEditField.Enable = 'on';
                app.DynamicsEditFieldLabel.Enable = 'on';
                app.RealTimeFunctionButton.Enable = 'on';
                if app.r.multi2DFlag
                    app.DynamicsEditField.Enable = 'off';
                    app.DynamicsEditFieldLabel.Enable = 'off';
                    app.RealTimeFunctionButton.Enable = 'off';
                    app.DynamicsEditField.Value = 1;
                end
                switch app.r.dataType
                    case {'3D','3Dproud','2Dms','3Dute'}
                        app.RealTimeFunctionButton.Enable = 'off';
                end
                switch app.r.dataType
                    case {'2Dradial','2Dradialms','3Dute'}
                        app.SharingEditField.Enable = 'off';
                        app.SharingEditFieldLabel.Enable = 'off';
                    otherwise
                        app.SharingEditField.Enable = 'on';
                        app.SharingEditFieldLabel.Enable = 'on';
                end
                app.VfaFunctionButton.Enable = 'off';
                if app.r.vfaDataFlag
                    app.DynamicsEditField.Enable = 'off';
                    app.DynamicsEditFieldLabel.Enable = 'off';
                    app.VfaFunctionButton.Enable = 'on';
                    app.RealTimeFunctionButton.Enable = 'off';
                end
                app.RecoTypeDropDown.Enable = 'on';
                app.RecoTypeDropDownLabel.Enable = 'on';
                app.AutoGuessRecoCheckBox.Enable = 'on';
                app.AutoGuessRecoCheckBoxLabel.Enable = 'on';
                app.MatlabRecoCheckBox.Enable = 'on';
                app.MatlabRecoCheckBoxLabel.Enable = 'on';
                if app.aiDetectedFlag && (app.nrFrames > 4) && (strcmp(app.r.dataType,'2D') || strcmp(app.r.dataType,'2Dms'))
                    app.DeepLearningButton.Enable = 'on';
                    app.CompressedSensingButton.Enable = 'on';
                else
                    app.DeepLearningButton.Enable = 'off';
                    app.CompressedSensingButton.Enable = 'on';
                    app.CompressedSensingButton.Value = 1;
                end
               
                % K-space data sorted and Bart is available
                if app.validSortFlag && app.bartDetectedFlag

                    app.WVxyzEditField.Enable = 'on';
                    app.WVxyzEditFieldLabel.Enable = 'on';
                    app.TVxyzEditField.Enable = 'on';
                    app.TVxyzEditFieldLabel.Enable = 'on';
                    app.LLRxyzEditField.Enable = 'on';
                    app.LLRxyzEditFieldLabel.Enable = 'on';
                    app.TVcineEditField.Enable = 'on';
                    app.TVcineEditFieldLabel.Enable = 'on';
                    app.TVdynEditField.Enable = 'on';
                    app.TVdynEditFieldLabel.Enable = 'on';
                    app.NLMFCheckBox.Enable = 'on';
                    app.NLMFCheckBoxLabel.Enable = 'on';
                    app.NLMFfilterStrengthEditField.Enable = 'on';
                    app.RingRmCheckBox.Enable = 'on';
                    app.RingRmCheckBoxLabel.Enable = 'on';
                    app.LcurveStartButton.Enable = 'on';
                    app.LcurveStopButton.Enable = 'off';
                    app.Lambda1EditField.Enable = 'on';
                    app.Lambda1EditFieldLabel.Enable = 'on';
                    app.Lambda2EditField.Enable = 'on';
                    app.Lambda2EditFieldLabel.Enable = 'on';
                    app.LambdaStepsEditField.Enable = 'on';
                    app.LambdaStepsEditFieldLabel.Enable = 'on';
                    app.LambdaIterationsEditField.Enable = 'on';
                    app.LambdaIterationsEditFieldLabel.Enable = 'on';
                    app.LcurveWVxyzCheckBox.Enable = 'on';
                    app.LcurveWVxyzCheckBoxLabel.Enable = 'on';
                    app.LcurveTVxyzCheckBox.Enable = 'on';
                    app.LcurveTVxyzCheckBoxLabel.Enable = 'on';
                    app.LcurveLLRxyzCheckBox.Enable = 'on';
                    app.LcurveLLRxyzCheckBoxLabel.Enable = 'on';
                    app.LcurveTVcineCheckBox.Enable = 'on';
                    app.LcurveTVcineCheckBoxLabel.Enable = 'on';
                    app.LcurveTVdynCheckBox.Enable = 'on';
                    app.LcurveTVdynCheckBoxLabel.Enable = 'on';
                    if app.r.multiCoilFlag
                        app.ESPIRiTCheckBox.Enable = 'on';
                        app.ESPIRiTCheckBoxLabel.Enable = 'on';
                    else
                        app.ESPIRiTCheckBox.Enable = 'off';
                        app.ESPIRiTCheckBoxLabel.Enable = 'off';
                        app.ESPIRiTCheckBox.Value = 0;
                    end

                    switch app.r.dataType

                        case '3Dute'

                            app.GradDelayCalibrationCheckBox.Enable = 'on';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.GxDelayEditField.Enable = 'on';
                            app.GxdelayEditFieldLabel.Enable = 'on';
                            app.GyDelayEditField.Enable = 'on';
                            app.GydelayEditFieldLabel.Enable = 'on';
                            app.GzDelayEditField.Enable = 'on';
                            app.GzdelayEditFieldLabel.Enable = 'on';
                            app.DataOffsetRadialEditField.Enable = 'on';
                            app.DataOffsetRadialEditFieldLabel.Enable = 'on';
                            app.HalfCircleRadialButton.Enable = 'off';
                            app.FullCircleRadialButton.Enable = 'off';
                            app.UserDefinedRadialButton.Enable = 'off';
                            app.RadialAngleEditField.Enable = 'off';
                            app.CenterEchoCheckBox.Enable = 'off';
                            app.CenterEchoCheckBoxLabel.Enable = 'off';
                            app.PhaseCorrectCheckBox.Enable = 'on';
                            app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                            app.DensityCorrectCheckBox.Enable = 'on';
                            app.DensityCorrectCheckBoxLabel.Enable = 'on';
                            app.DeepLearningButton.Enable = 'off';
                            app.CompressedSensingButton.Value = 1;
                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(true);

                        case {'2Dradial','2Dradialms'}

                            app.GradDelayCalibrationCheckBox.Enable = 'on';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                            app.RingMethodCheckBox.Enable = 'on';
                            app.RingMethodCheckBoxLabel.Enable = 'on';
                            app.GxDelayEditField.Enable = 'on';
                            app.GxDelayEditField.Enable = 'on';
                            app.GyDelayEditField.Enable = 'on';
                            app.GyDelayEditField.Enable = 'on';
                            app.GzDelayEditField.Enable = 'on';
                            app.GzDelayEditField.Enable = 'on';
                            app.DataOffsetRadialEditField.Enable = 'off';
                            app.DataOffsetRadialEditFieldLabel.Enable = 'off';
                            app.HalfCircleRadialButton.Enable = 'on';
                            app.FullCircleRadialButton.Enable = 'on';
                            app.UserDefinedRadialButton.Enable = 'on';
                            app.RadialAngleEditField.Enable = 'on';
                            app.CenterEchoCheckBox.Enable = 'on';
                            app.CenterEchoCheckBoxLabel.Enable = 'on';
                            app.PhaseCorrectCheckBox.Enable = 'on';
                            app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                            app.DensityCorrectCheckBox.Enable = 'on';
                            app.DensityCorrectCheckBoxLabel.Enable = 'on';
                            app.DeepLearningButton.Enable = 'off';
                            app.CompressedSensingButton.Value = 1;
                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(false);

                        otherwise

                            app.GradDelayCalibrationCheckBox.Enable = 'off';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.GxDelayEditField.Enable = 'off';
                            app.GxdelayEditFieldLabel.Enable = 'off';
                            app.GyDelayEditField.Enable = 'off';
                            app.GydelayEditFieldLabel.Enable = 'off';
                            app.GzDelayEditField.Enable = 'off';
                            app.GzdelayEditFieldLabel.Enable = 'off';
                            app.DataOffsetRadialEditField.Enable = 'off';
                            app.DataOffsetRadialEditFieldLabel.Enable = 'off';
                            app.HalfCircleRadialButton.Enable = 'off';
                            app.FullCircleRadialButton.Enable = 'off';
                            app.UserDefinedRadialButton.Enable = 'off';
                            app.RadialAngleEditField.Enable = 'off';
                            app.CenterEchoCheckBox.Enable = 'off';
                            app.CenterEchoCheckBoxLabel.Enable = 'off';
                            app.PhaseCorrectCheckBox.Enable = 'off';
                            app.PhaseCorrectCheckBox.Enable = 'off';
                            app.DensityCorrectCheckBox.Enable = 'off';
                            app.DensityCorrectCheckBoxLabel.Enable = 'off';
                            app.TrajMovieButtonsFcn(false);
                            app.Traj3DMovieButtonsFcn(false);

                    end

                end

                % K-space data sorted and Bart is not available
                if app.validSortFlag && ~app.bartDetectedFlag

                    app.WVxyzEditField.Enable = 'off';
                    app.WVxyzEditFieldLabel.Enable = 'off';
                    app.TVxyzEditField.Enable = 'off';
                    app.TVxyzEditFieldLabel.Enable = 'off';
                    app.LLRxyzEditField.Enable = 'off';
                    app.LLRxyzEditFieldLabel.Enable = 'off';
                    app.NLMFCheckBox.Enable = 'on';
                    app.NLMFCheckBoxLabel.Enable = 'on';
                    app.NLMFfilterStrengthEditField.Enable = 'on';
                    app.RingRmCheckBox.Enable = 'on';
                    app.RingRmCheckBoxLabel.Enable = 'on';
                    switch app.r.dataType
                        case {'2Dradial','2Dradialms','3Dute'}
                            app.TVcineEditField.Enable = 'off';
                            app.TVcineEditFieldLabel.Enable = 'off';
                        otherwise
                            app.TVcineEditField.Enable = 'on';
                            app.TVcineEditFieldLabel.Enable = 'on';
                    end
                    app.TVdynEditField.Enable = 'off';
                    app.TVdynEditFieldLabel.Enable = 'off';
                    app.LcurveStartButton.Enable = 'off';
                    app.LcurveStopButton.Enable = 'off';
                    app.Lambda1EditField.Enable = 'off';
                    app.Lambda1EditFieldLabel.Enable = 'off';
                    app.Lambda2EditField.Enable = 'off';
                    app.Lambda2EditFieldLabel.Enable = 'off';
                    app.LambdaStepsEditField.Enable = 'off';
                    app.LambdaStepsEditFieldLabel.Enable = 'off';
                    app.LambdaIterationsEditField.Enable = 'off';
                    app.LambdaIterationsEditFieldLabel.Enable = 'off';
                    app.LcurveWVxyzCheckBox.Enable = 'off';
                    app.LcurveWVxyzCheckBoxLabel.Enable = 'off';
                    app.LcurveTVxyzCheckBox.Enable = 'off';
                    app.LcurveTVxyzCheckBoxLabel.Enable = 'off';
                    app.LcurveLLRxyzCheckBox.Enable = 'off';
                    app.LcurveLLRxyzCheckBoxLabel.Enable = 'off';
                    app.LcurveTVcineCheckBox.Enable = 'off';
                    app.LcurveTVcineCheckBoxLabel.Enable = 'off';
                    app.LcurveTVdynCheckBox.Enable = 'off';
                    app.LcurveTVdynCheckBoxLabel.Enable = 'off';

                    switch app.r.dataType

                        case '3Dute'

                            app.GradDelayCalibrationCheckBox.Enable = 'off';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.GxDelayEditField.Enable = 'on';
                            app.GxDelayEditField.Enable = 'on';
                            app.GyDelayEditField.Enable = 'on';
                            app.GydelayEditFieldLabel.Enable = 'on';
                            app.GzDelayEditField.Enable = 'on';
                            app.GzdelayEditFieldLabel.Enable = 'on';
                            app.DataOffsetRadialEditField.Enable = 'on';
                            app.DataOffsetRadialEditFieldLabel.Enable = 'on';
                            app.HalfCircleRadialButton.Enable = 'off';
                            app.FullCircleRadialButton.Enable = 'off';
                            app.UserDefinedRadialButton.Enable = 'off';
                            app.RadialAngleEditField.Enable = 'off';
                            app.CenterEchoCheckBox.Enable = 'off';
                            app.CenterEchoCheckBoxLabel.Enable = 'off';
                            app.PhaseCorrectCheckBox.Enable = 'on';
                            app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                            app.DensityCorrectCheckBox.Enable = 'off';
                            app.DensityCorrectCheckBoxLabel.Enable = 'off';
                            app.DeepLearningButton.Enable = 'off';
                            app.CompressedSensingButton.Value = 1;
                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(true);

                        case {'2Dradial','2Dradialms'}

                            app.GradDelayCalibrationCheckBox.Enable = 'off';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.GxDelayEditField.Enable = 'on';
                            app.GxdelayEditFieldLabel.Enable = 'on';
                            app.GyDelayEditField.Enable = 'on';
                            app.GydelayEditFieldLabel.Enable = 'on';
                            app.GzDelayEditField.Enable = 'on';
                            app.GzdelayEditFieldLabel.Enable = 'on';
                            app.DataOffsetRadialEditField.Enable = 'off';
                            app.DataOffsetRadialEditFieldLabel.Enable = 'off';
                            app.HalfCircleRadialButton.Enable = 'on';
                            app.FullCircleRadialButton.Enable = 'on';
                            app.UserDefinedRadialButton.Enable = 'on';
                            app.RadialAngleEditField.Enable = 'on';
                            app.CenterEchoCheckBox.Enable = 'on';
                            app.CenterEchoCheckBoxLabel.Enable = 'on';
                            app.PhaseCorrectCheckBox.Enable = 'on';
                            app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                            app.DensityCorrectCheckBox.Enable = 'off';
                            app.DensityCorrectCheckBoxLabel.Enable = 'off';
                            app.DeepLearningButton.Enable = 'off';
                            app.CompressedSesnsingButton.Value = 1;
                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(false);

                        otherwise

                            app.GradDelayCalibrationCheckBox.Enable = 'off';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.GxDelayEditField.Enable = 'off';
                            app.GyDelayEditField.Enable = 'off';
                            app.GzDelayEditField.Enable = 'off';
                            app.DataOffsetRadialEditField.Enable = 'off';
                            app.HalfCircleRadialButton.Enable = 'off';
                            app.FullCircleRadialButton.Enable = 'off';
                            app.UserDefinedRadialButton.Enable = 'off';
                            app.RadialAngleEditField.Enable = 'off';
                            app.CenterEchoCheckBox.Enable = 'off';
                            app.PhaseCorrectCheckBox.Enable = 'off';
                            app.DensityCorrectCheckBox.Enable = 'off';
                            app.TrajMovieButtonsFcn(false);
                            app.Traj3DMovieButtonsFcn(false);

                    end

                end

            end

            % K-space data not sorted
            if ~on

                app.RecoTypeDropDown.Enable = 'off';
                app.RecoTypeDropDownLabel.Enable = 'off';
                app.FramesEditField.Enable = 'off';
                app.FramesEditFieldLabel.Enable = 'off';
                app.DynamicsEditField.Enable = 'off';
                app.DynamicsEditFieldLabel.Enable = 'off';
                app.WVxyzEditField.Enable = 'off';
                app.WVxyzEditFieldLabel.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.TVxyzEditFieldLabel.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.LLRxyzEditFieldLabel.Enable = 'off';
                app.TVcineEditField.Enable = 'off';
                app.TVcineEditFieldLabel.Enable = 'off';
                app.ESPIRiTCheckBox.Enable = 'off';
                app.ESPIRiTCheckBoxLabel.Enable = 'off';
                app.TVdynEditField.Enable = 'off';
                app.TVdynEditFieldLabel.Enable = 'off';
                app.DeepLearningButton.Enable = 'off';
                app.CompressedSensingButton.Enable = 'off';
                app.NLMFCheckBox.Enable = 'off';
                app.NLMFCheckBoxLabel.Enable = 'off';
                app.NLMFfilterStrengthEditField.Enable = 'off';
                app.RingRmCheckBox.Enable = 'off';
                app.RingRmCheckBoxLabel.Enable = 'off';
                app.AutoGuessRecoCheckBox.Enable = 'off';
                app.AutoGuessRecoCheckBoxLabel.Enable = 'off';
                app.MatlabRecoCheckBox.Enable = 'off';
                app.MatlabRecoCheckBoxLabel.Enable = 'off';
                app.ScoutButton.Enable ='off';
                app.SystolicFunctionButton.Enable = 'off';
                app.DiastolicFunctionButton.Enable = 'off';
                app.RespFunctionButton.Enable = 'off';
                app.RealTimeFunctionButton.Enable = 'off';
                app.VfaFunctionButton.Enable = 'off';
                app.LcurveStartButton.Enable = 'off';
                app.LcurveStopButton.Enable = 'off';
                app.Lambda1EditField.Enable = 'off';
                app.Lambda1EditFieldLabel.Enable = 'off';
                app.Lambda2EditField.Enable = 'off';
                app.Lambda2EditFieldLabel.Enable = 'off';
                app.LambdaStepsEditField.Enable = 'off';
                app.LambdaStepsEditFieldLabel.Enable = 'off';
                app.LambdaIterationsEditField.Enable = 'off';
                app.LambdaIterationsEditFieldLabel.Enable = 'off';
                app.LcurveWVxyzCheckBox.Enable = 'off';
                app.LcurveWVxyzCheckBoxLabel.Enable = 'off';
                app.LcurveTVxyzCheckBox.Enable = 'off';
                app.LcurveTVxyzCheckBoxLabel.Enable = 'off';
                app.LcurveLLRxyzCheckBox.Enable = 'off';
                app.LcurveLLRxyzCheckBoxLabel.Enable = 'off';
                app.LcurveTVcineCheckBox.Enable = 'off';
                app.LcurveTVcineCheckBoxLabel.Enable = 'off';
                app.LcurveTVdynCheckBox.Enable = 'off';
                app.LcurveTVdynCheckBoxLabel.Enable = 'off';
                app.GradDelayCalibrationCheckBox.Enable = 'off';
                app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                app.RingMethodCheckBox.Enable = 'off';
                app.RingMethodCheckBoxLabel.Enable = 'off';
                app.GxDelayEditField.Enable = 'off';
                app.GxdelayEditFieldLabel.Enable = 'off';
                app.GyDelayEditField.Enable = 'off';
                app.GydelayEditFieldLabel.Enable = 'off';
                app.GzDelayEditField.Enable = 'off';
                app.GzdelayEditFieldLabel.Enable = 'off';
                app.DataOffsetRadialEditField.Enable = 'off';
                app.DataOffsetRadialEditFieldLabel.Enable = 'off';
                app.HalfCircleRadialButton.Enable = 'off';
                app.FullCircleRadialButton.Enable = 'off';
                app.UserDefinedRadialButton.Enable = 'off';
                app.RadialAngleEditField.Enable = 'off';
                app.CenterEchoCheckBox.Enable = 'off';
                app.CenterEchoCheckBoxLabel.Enable = 'off';
                app.PhaseCorrectCheckBox.Enable = 'off';
                app.PhaseCorrectCheckBoxLabel.Enable = 'off';
                app.DensityCorrectCheckBox.Enable = 'off';
                app.DensityCorrectCheckBoxLabel.Enable = 'off';
                app.TrajMovieButtonsFcn(false);
                app.Traj3DMovieButtonsFcn(false);

            end
      
            drawnow;

        end % CineRecoButtonsFcn

        % 3D trajectory movie buttons
        function Traj3DMovieButtonsFcn(app, on)

            if on && app.validSortFlag

                switch app.r.dataType

                    case '3Dute'

                        if app.stop3DMovieFlag
                            app.TrajMovie3DStartButton.Enable = 'on';
                            app.TrajMovie3DStopButton.Enable = 'off';
                            app.SpokeEditField.Enable = 'on';
                            app.SpokeEditFieldLabel.Enable = 'on';
                        else
                            app.TrajMovie3DStartButton.Enable = 'off';
                            app.TrajMovie3DStopButton.Enable = 'on';
                            app.SpokeEditField.Enable = 'on';
                            app.SpokeEditField.Enable = 'on';
                        end

                    otherwise

                        app.TrajMovie3DStartButton.Enable = 'off';
                        app.TrajMovie3DStopButton.Enable = 'off';
                        app.SpokeEditField.Enable = 'off';
                        app.SpokeEditFieldLabel.Enable = 'off';

                end

            else

                app.TrajMovie3DStartButton.Enable = 'off';
                app.TrajMovie3DStopButton.Enable = 'off';
                app.SpokeEditField.Enable = 'off';
                app.SpokeEditFieldLabel.Enable = 'off';

            end

        end % Traj3DMovieButtonsFcn


        % Radial movie buttons
        function TrajMovieButtonsFcn(app, on)

            if on && app.validSortFlag

                switch app.r.dataType

                    case {'2Dradial','2Dradialms'}

                        if app.stopRadialMovieFlag
                            app.TrajMovieStartButton.Enable = 'on';
                            app.TrajMovieStopButton.Enable = 'off';
                        else
                            app.TrajMovieStartButton.Enable = 'off';
                            app.TrajMovieStopButton.Enable = 'on';
                        end
                        app.TrajMovieFrameEditField.Enable = 'on';
                        app.TrajMovieFrameEditFieldLabel.Enable = 'on';
                        app.TrajMovieCheckBox1.Enable = 'on';
                        app.TrajMovieCheckBox2.Value = 0;
                        if app.r.multiSliceFlag
                            app.TrajMovieSliceEditField.Enable = 'on';
                            app.TrajMovieSliceEditFieldLabel.Enable = 'on';
                            app.TrajMovieCheckBox2.Enable = 'on';
                        end
                        app.TrajMovieCheckBox3.Value = 0;
                        if app.r.multiDynamicFlag || app.r.vfaDataFlag
                            app.TrajMovieDynEditField.Enable = 'on';
                            app.TrajMovieDynEditFieldLabel.Enable = 'on';
                            app.TrajMovieCheckBox3.Enable = 'on';
                        end

                    case '3Dute'

                        if app.stopRadialMovieFlag
                            app.TrajMovieStartButton.Enable = 'on';
                            app.TrajMovieStopButton.Enable = 'off';
                        else
                            app.TrajMovieStartButton.Enable = 'off';
                            app.TrajMovieStopButton.Enable = 'on';
                        end
                        app.TrajMovieFrameEditField.Enable = 'on';
                        app.TrajMovieFrameEditFieldLabel.Enable = 'on';
                        app.TrajMovieCheckBox1.Enable = 'on';
                        app.TrajMovieCheckBox2.Value = 0;
                        app.TrajMovieSliceEditField.Value = 1;
                        app.TrajMovieSliceEditField.Enable = 'off';
                        app.TrajMovieSliceEditFieldLabel.Enable = 'off';
                        app.TrajMovieCheckBox2.Enable = 'off';
                        app.TrajMovieCheckBox3.Value = 0;
                        if app.r.multiDynamicFlag || app.r.vfaDataFlag
                            app.TrajMovieDynEditField.Enable = 'on';
                            app.TrajMovieDynEditFieldLabel.Enable = 'on';
                            app.TrajMovieCheckBox3.Enable = 'on';
                        end

                    otherwise

                        app.TrajMovieStartButton.Enable = 'off';
                        app.TrajMovieStopButton.Enable = 'off';
                        app.TrajMovieFrameEditField.Enable = 'off';
                        app.TrajMovieFrameEditFieldLabel.Enable = 'off';
                        app.TrajMovieSliceEditField.Enable = 'off';
                        app.TrajMovieSliceEditFieldLabel.Enable = 'off';
                        app.TrajMovieDynEditField.Enable = 'off';
                        app.TrajMovieDynEditFieldLabel.Enable = 'off';
                        app.TrajMovieCheckBox1.Enable = 'off';
                        app.TrajMovieCheckBox2.Enable = 'off';
                        app.TrajMovieCheckBox3.Enable = 'off';

                end

            else

                app.TrajMovieStartButton.Enable = 'off';
                app.TrajMovieStopButton.Enable = 'off';
                app.TrajMovieFrameEditField.Enable = 'off';
                app.TrajMovieFrameEditFieldLabel.Enable = 'off';
                app.TrajMovieSliceEditField.Enable = 'off';
                app.TrajMovieSliceEditFieldLabel.Enable = 'off';
                app.TrajMovieDynEditField.Enable = 'off';
                app.TrajMovieDynEditFieldLabel.Enable = 'off';
                app.TrajMovieCheckBox1.Enable = 'off';
                app.TrajMovieCheckBox2.Enable = 'off';
                app.TrajMovieCheckBox3.Enable = 'off';
                
            end

        end % MovieRadialButtons


        % Movie buttons
        function MovieButtonsFcn(app, on)

            if on && app.validRecoFlag

                if app.stopMovieFlag
                    app.MovieStartButton.Enable = 'on';
                    app.MovieStopButton.Enable = 'off';
                else
                    app.MovieStartButton.Enable = 'off';
                    app.MovieStopButton.Enable = 'on';
                end
                app.MovieFrameEditField.Enable = 'on';
                app.MovieFrameEditFieldLabel.Enable = 'on';
                app.MovieCheckBox1.Enable = 'on';
                app.WindowScaleSlider.Enable = 'on';
                app.WindowScaleSliderLabel.Enable = 'on';
                app.LevelScaleSlider.Enable = 'on';
                app.LevelScaleSliderLabel.Enable = 'on';
                app.AutoWLButton.Enable = 'on';
                app.MovieRotateLabel.Enable = 'on';
                app.MoviecwButton.Enable = 'on';
                app.MovieccwButton.Enable = 'on';
                app.MovieFrameLabel.Enable = 'on';
                app.MovieFrameIncrButton.Enable = 'on';
                app.MovieFrameDecrButton.Enable = 'on';
                app.MovieButton.Enable = 'on';
                app.AveragesButton.Enable = 'on';
                app.kSpaceButton.Enable = 'on';
                if app.r.multiCoilFlag
                    app.MovieCoilSpinner.Enable = 'on';
                else
                    app.MovieCoilSpinner.Enable = 'off';
                end
                if app.validSenseMapFlag
                    app.SenseMapButton.Enable = 'on';
                    s1 = size(app.r.senseMap,4);
                    s2 = size(app.r.senseMap,5);
                    app.SenseMapSpinner1.Value = 1;
                    if s1>1
                        app.SenseMapSpinner1.Limits = [1,s1];
                        app.SenseMapSpinner1.Enable = 'on';
                    else
                        app.SenseMapSpinner1.Limits = [1,2];
                        app.SenseMapSpinner1.Enable = 'off';
                    end
                    app.SenseMapSpinner2.Value = 1;
                    if s2>1
                        app.SenseMapSpinner2.Limits = [1,s2];
                        app.SenseMapSpinner2.Enable = 'on';
                    else
                        app.SenseMapSpinner2.Limits = [1,2];
                        app.SenseMapSpinner2.Enable = 'off';
                    end
                else
                    app.SenseMapButton.Enable = 'off';
                    app.SenseMapSpinner1.Enable = 'off';
                    app.SenseMapSpinner2.Enable = 'off';
                end
                if app.r.multiSliceFlag
                    app.MovieSliceLabel.Enable = 'on';
                    app.MovieSliceEditField.Enable = 'on';
                    app.MovieSliceEditFieldLabel.Enable = 'on';
                    app.MovieSliceIncrButton.Enable = 'on';
                    app.MovieSliceDecrButton.Enable = 'on';
                    app.MovieCheckBox2.Enable = 'on';
                end
                if app.r.multiDynamicFlag || app.r.vfaDataFlag
                    app.MovieDynLabel.Enable = 'on';
                    app.MovieDynEditField.Enable = 'on';
                    app.MovieDynEditFieldLabel.Enable = 'on';
                    app.MovieDynIncrButton.Enable = 'on';
                    app.MovieDynDecrButton.Enable = 'on';
                    app.MovieCheckBox3.Enable = 'on';
                end

                if app.extFidNavFlag || app.extEchoNavFlag
                    app.ShowNavButton.Enable = 'on';
                end

            else

                app.MovieStartButton.Enable = 'off';
                app.MovieStopButton.Enable = 'off';
                app.MovieFrameEditField.Enable = 'off';
                app.MovieFrameEditFieldLabel.Enable = 'off';
                app.MovieSliceEditField.Enable = 'off';
                app.MovieSliceEditFieldLabel.Enable = 'off';
                app.MovieDynEditField.Enable = 'off';
                app.MovieDynEditFieldLabel.Enable = 'off';
                app.MovieCheckBox1.Enable = 'off';
                app.MovieCheckBox2.Enable = 'off';
                app.MovieCheckBox3.Enable = 'off';
                app.WindowScaleSlider.Enable = 'off';
                app.WindowScaleSliderLabel.Enable = 'off';
                app.LevelScaleSlider.Enable = 'off';
                app.LevelScaleSliderLabel.Enable = 'off';
                app.AutoWLButton.Enable = 'off';
                app.MovieRotateLabel.Enable = 'off';
                app.MoviecwButton.Enable = 'off';
                app.MovieccwButton.Enable = 'off';
                app.MovieFrameLabel.Enable = 'off';
                app.MovieFrameIncrButton.Enable = 'off';
                app.MovieFrameDecrButton.Enable = 'off';
                app.MovieSliceLabel.Enable = 'off';
                app.MovieSliceIncrButton.Enable = 'off';
                app.MovieSliceDecrButton.Enable = 'off';
                app.MovieDynLabel.Enable = 'off';
                app.MovieDynIncrButton.Enable = 'off';
                app.MovieDynDecrButton.Enable = 'off';
                app.MovieButton.Enable = 'off';
                app.AveragesButton.Enable = 'off';
                app.kSpaceButton.Enable = 'off';
                app.MovieCoilSpinner.Enable = 'off';
                app.SenseMapButton.Enable = 'off';
                app.SenseMapSpinner1.Enable = 'off';
                app.SenseMapSpinner2.Enable = 'off';
                app.ShowNavButton.Enable = 'off';

            end

            drawnow;

        end % MovieButtonsFcn


        % Switch of all buttons
        function SwitchOffAllButtonsFcn(app)

            % Stop the movies
            app.stopNavTimeMovieFlag = true;
            app.stopMovieFlag = true;
            app.stopRadialMovieFlag = true;

            % Dashboard buttons
            app.SetDashboardButtonsFcn(false);

            % Navigator selection and filtering
            app.NavSelButtonsFcn(false);

            % Navigator viewing
            app.NavViewingButtonsFcn(false);

            % Cine reconstruction buttons
            app.CineRecoButtonsFcn(false);

            % Movie windows
            app.MovieButtonsFcn(false);
            app.TrajMovieButtonsFcn(false);
            app.Traj3DMovieButtonsFcn(false);

        end % SwitchOffAllButtonsFcn


        % Stop all movies
        function StopAllMoviesFcn(app)

            app.stop3DMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopMovieFlag = true;
            app.stopNavTimeMovieFlag = true;
            app.stopRadialMovieFlag = true;
            app.stopRawNavMovieFlag = true;
            app.MovieButtonsFcn(true);
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);
            drawnow;

        end % StopAllMoviesButtonFcn


        % Load icons
        function SetAppLayoutPropertiesFcn(app)

            app.Credits.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);

            % Icons and title
            app.TulipImage.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';
            app.RetrospectiveTitleImage.ImageSource = 'Retrospective.png';
       
            % Button images
            app.LoadMRDButton.Icon = 'disk.png';
            app.FilterButton.Icon = 'filter.png';
            app.SortKspaceButton.Icon = 'sort.gif';
            app.ReconstructButton.Icon = 'reconstruct.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExportMRDButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.MovieStartButton.Icon = 'play.gif';
            app.NavStartButton.Icon = 'play.gif';
            app.TrajMovieStartButton.Icon = 'play.gif';
            app.TrajMovie3DStartButton.Icon = 'play.gif';
            app.MovieStopButton.Icon = 'stop.gif';
            app.NavStopButton.Icon = 'stop.gif';
            app.TrajMovieStopButton.Icon = 'stop.gif';
            app.TrajMovie3DStopButton.Icon = 'stop.gif';
            app.MoviecwButton.Icon = 'cw.png';
            app.MovieccwButton.Icon = 'ccw.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.StatusBackgroundImage.ImageSource = 'square.gif';
            app.StartMotionButton.Icon = 'play.gif';
            app.StopMotionButton.Icon = 'stop.gif';
            app.AutoWLButton.Icon = 'contrast.png';
            app.ShowNavButton.Icon = 'nav.gif';

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.black = 'Black';
            app.lightblue = '#DEE6EB';
            app.lightgrey = '#F0F0F0';
            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.9,0.9,0.9];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];
            app.traceGreen = [0.4660 0.6740 0.1880];
            app.traceOrange = [0.9290 0.6940 0.1250];

            % Some other elements
            app.StartSlider.MajorTicks = [];
            app.StartSlider.MajorTickLabels = {};
            app.StartSlider.MinorTicks = [];
            app.RangeSlider.MajorTicks = [];
            app.RangeSlider.MajorTickLabels = {};
            app.RangeSlider.MinorTicks = [];
            app.IncludeStartSlider.MajorTicks = [];
            app.IncludeStartSlider.MajorTickLabels = {};
            app.IncludeStartSlider.MinorTicks = [];
            app.IncludeEndSlider.MajorTicks = [];
            app.IncludeEndSlider.MajorTickLabels = {};
            app.IncludeEndSlider.MinorTicks = [];
            app.ExcludeStartSlider.MajorTicks = [];
            app.ExcludeStartSlider.MajorTickLabels = {};
            app.ExcludeStartSlider.MinorTicks = [];
            app.ExcludeEndSlider.MajorTicks = [];
            app.ExcludeEndSlider.MajorTickLabels = {};
            app.ExcludeEndSlider.MinorTicks = [];
            app.LevelScaleSlider.MajorTicks = [];
            app.LevelScaleSlider.MajorTickLabels = {};
            app.LevelScaleSlider.MinorTicks = [];
            app.WindowScaleSlider.MajorTicks = [];
            app.WindowScaleSlider.MajorTickLabels = {};
            app.WindowScaleSlider.MinorTicks = [];

            % Set some plot properties
            app.Nav1Fig.Interactions = [];
            app.Nav1Fig.Position = [47,217,246,185];
            %app.Nav1Fig.XTick = [0,0.2,0.4,0.6,0.8,1];
            %app.Nav1Fig.XTickLabel = [0,0.2,0.4,0.6,0.8,1];
            app.Nav1Fig.YTick = [];
            app.Nav1Fig.YTickLabel = [];
            app.Nav1Fig.Toolbar.Visible = 'off';
            app.Nav1Fig.PositionConstraint = "innerposition";

            app.Nav2Fig.Interactions = [];
            app.Nav2Fig.Position = [47,7,246,185];
            %app.Nav2Fig.XTick = [0,0.2,0.4,0.6,0.8,1];
            %app.Nav2Fig.XTickLabel = [0,0.2,0.4,0.6,0.8,1];
            app.Nav2Fig.YTick = [];
            app.Nav2Fig.YTickLabel = [];
            app.Nav2Fig.Toolbar.Visible = 'off';
            app.Nav2Fig.PositionConstraint = "innerposition";

            app.HeartNavFig.Interactions = [];
            app.HeartNavFig.Position = [37,217,246,185];
            %app.HeartNavFig.XTick = [0,0.2,0.4,0.6,0.8,1];
            %app.HeartNavFig.XTickLabel = [0,0.2,0.4,0.6,0.8,1];
            app.HeartNavFig.YTick = [];
            app.HeartNavFig.YTickLabel = [];
            app.HeartNavFig.Toolbar.Visible = 'off';
            app.HeartNavFig.PositionConstraint = "innerposition";

            app.RespNavFig.Interactions = [];
            app.RespNavFig.Position = [326,217,246,185];
            %app.RespNavFig.XTick = [0,0.2,0.4,0.6,0.8,1];
            %app.RespNavFig.XTickLabel = [0,0.2,0.4,0.6,0.8,1];
            app.RespNavFig.YTick = [];
            app.RespNavFig.YTickLabel = [];
            app.RespNavFig.Toolbar.Visible = 'off';
            app.RespNavFig.PositionConstraint = "innerposition";

            app.HeartRawNavFig.Interactions = [];
            app.HeartRawNavFig.Position = [47,217,246,185];
            %app.HeartRawNavFig.XTick = [0,0.2,0.4,0.6,0.8,1];
            %app.HeartRawNavFig.XTickLabel = [0,0.2,0.4,0.6,0.8,1];
            app.HeartRawNavFig.YTick = [];
            app.HeartRawNavFig.YTickLabel = [];
            app.HeartRawNavFig.Toolbar.Visible = 'off';
            app.HeartRawNavFig.PositionConstraint = "innerposition";

            app.RespRawNavFig.Interactions = [];
            app.RespRawNavFig.Position = [47,217,246,185];
            %app.RespRawNavFig.XTick = [0,0.2,0.4,0.6,0.8,1];
            %app.RespRawNavFig.XTickLabel = [0,0.2,0.4,0.6,0.8,1];
            app.RespRawNavFig.YTick = [];
            app.RespRawNavFig.YTickLabel = [];
            app.RespRawNavFig.Toolbar.Visible = 'off';
            app.RespRawNavFig.PositionConstraint = "innerposition";

            app.HeartSpectrumFig.Interactions = [];
            app.HeartSpectrumFig.Position = [27,7,266,185];
            %app.HeartSpectrumFig.XTick = [0,0.2,0.4,0.6,0.8,1];
            %app.HeartSpectrumFig.XTickLabel = [0,0.2,0.4,0.6,0.8,1];
            %app.HeartSpectrumFig.YTick = [0,0.2,0.4,0.6,0.8,1];
            %app.HeartSpectrumFig.YTickLabel = [0,0.2,0.4,0.6,0.8,1];
            app.HeartSpectrumFig.Toolbar.Visible = 'off';
            app.HeartSpectrumFig.PositionConstraint = "innerposition";

            app.RespSpectrumFig.Interactions = [];
            app.RespSpectrumFig.Position = [27,7,266,185];
            %app.RespSpectrumFig.XTick = [0,0.2,0.4,0.6,0.8,1];
            %app.RespSpectrumFig.XTickLabel = [0,0.2,0.4,0.6,0.8,1];
            %app.RespSpectrumFig.YTick = [0,0.2,0.4,0.6,0.8,1];
            %app.RespSpectrumFig.YTickLabel = [0,0.2,0.4,0.6,0.8,1];
            app.RespSpectrumFig.Toolbar.Visible = 'off';
            app.RespSpectrumFig.PositionConstraint = "innerposition";
            
            app.HeartRateTimeFig.Interactions = [];
            app.HeartRateTimeFig.Position = [18,6,265,185];
            %app.HeartRateTimeFig.XTick = [0,0.2,0.4,0.6,0.8,1];
            %app.HeartRateTimeFig.XTickLabel = [0,0.2,0.4,0.6,0.8,1];
            %app.HeartRateTimeFig.YTick = [0,0.2,0.4,0.6,0.8,1];
            %app.HeartRateTimeFig.YTickLabel = [0,100,200,300,400,500];
            app.HeartRateTimeFig.Toolbar.Visible = 'off';
            app.HeartRateTimeFig.PositionConstraint = "innerposition";
         
            app.RespRateTimeFig.Interactions = [];
            app.RespRateTimeFig.Position = [307,6,264,185];
            %app.RespRateTimeFig.XTick = [0,0.2,0.4,0.6,0.8,1];
            %app.RespRateTimeFig.XTickLabel = [0,0.2,0.4,0.6,0.8,1];
            %app.RespRateTimeFig.YTick = [0,0.2,0.4,0.6,0.8,1];
            %app.RespRateTimeFig.YTickLabel = [0,10,20,30,40,50];
            app.RespRateTimeFig.Toolbar.Visible = 'off';
            app.RespRateTimeFig.PositionConstraint = "innerposition";

            app.MovieFig.Interactions = [zoomInteraction regionZoomInteraction];
            app.MovieFig.Toolbar.Visible = 'off';
            app.MovieFig.PositionConstraint = "innerposition";

            app.TrajectoryFig.Toolbar.Visible = 'off';
            app.TrajectoryFig.PositionConstraint = "innerposition";
            app.TrajectoryFig.Interactions = [];

            app.TrajectoryLargeFig.Toolbar.Visible = 'off';
            app.TrajectoryLargeFig.Interactions = [];

            app.LcurveFig.PositionConstraint = "innerposition";
            app.LcurveFig.Toolbar.Visible = 'off';
            app.LcurveFig.Interactions = [];

            app.LcurveModelErrorFig.PositionConstraint = "innerposition";
            app.LcurveModelErrorFig.Toolbar.Visible = 'off';
            app.LcurveModelErrorFig.Interactions = [];

            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Set initial colors
            app.ColorsButtonGroupSelectionChanged;

        end % SetAppLayoutPropertiesFcn


        % Species presets
        function SetSpeciesFcn(app)

            % Mouse preset settings
            if app.MouseButton.Value

                app.r.bandwidthHR = 60;
                app.HeartWidthEditField.Value = 60;
                app.r.bandwidthRR = 20;
                app.RespWidthEditField.Value = 40;
                app.r.respPercentage = 30;
                app.RespWindowEditField.Value = 30;
                app.MinHeartRateEditField.Value = 300;
                app.HeartMinEditField.Value = 300;
                app.MaxHeartRateEditField.Value = 700;
                app.HeartMaxEditField.Value = 700;
                app.MinRespRateEditField.Value = 30;
                app.RespMinEditField.Value = 30;
                app.MaxRespRateEditField.Value = 120;
                app.RespMaxEditField.Value = 120;
                app.FilterOrderEditField.Value = 4;
                app.RespShiftEditField.Value = 0;

            end

            % Rat preset settings
            if app.RatButton.Value

                app.r.bandwidthHR = 50;
                app.HeartWidthEditField.Value = 50;
                app.r.bandwidthRR = 20;
                app.RespWidthEditField.Value = 40;
                app.r.respPercentage = 30;
                app.RespWindowEditField.Value = 30;
                app.MinHeartRateEditField.Value = 200;
                app.HeartMinEditField.Value = 200;
                app.MaxHeartRateEditField.Value = 500;
                app.HeartMaxEditField.Value = 500;
                app.MinRespRateEditField.Value = 30;
                app.RespMinEditField.Value = 30;
                app.MaxRespRateEditField.Value = 120;
                app.RespMaxEditField.Value = 120;
                app.FilterOrderEditField.Value = 4;
                app.RespShiftEditField.Value = 0;
           
            end

            % Zebrafish preset settings
            if app.ZebraFishButton.Value

                app.r.bandwidthHR = 60;
                app.HeartWidthEditField.Value = 60;
                app.r.bandwidthRR = 20;
                app.RespWidthEditField.Value = 40;
                app.r.respPercentage = 30;
                app.RespWindowEditField.Value = 30;
                app.MinHeartRateEditField.Value = 60;
                app.HeartMinEditField.Value = 60;
                app.MaxHeartRateEditField.Value = 300;
                app.HeartMaxEditField.Value = 300;
                app.MinRespRateEditField.Value = 10;
                app.RespMinEditField.Value = 10;
                app.MaxRespRateEditField.Value = 90;
                app.RespMaxEditField.Value = 90;
                app.FilterOrderEditField.Value = 4;
                app.RespShiftEditField.Value = 0;
           
            end

        end % SetSpeciesFcn


        function SetRecoParametersFcn(app)

            % Settings for scout acquisition (e.g. 3 orthogonal slices)
            if app.ScoutButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 1;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.005;
                app.LLRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.000;
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end
                app.RingRmCheckBox.Value = 0;
                app.NLMFCheckBox.Value = 0;
           
            end

            % Settings for systolic function, typical number of CINE frames = 15
            if app.SystolicFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 15;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.000;
                if contains(app.r.dataType,["3D","3Dp2roud","3Dute"])
                    app.TVcineEditField.Value = 0.010;
                    if app.GPUpresentFlag || app.pcType == "AppleSilicon"
                        app.LLRxyzEditField.Value = 0.001;
                    end
                else
                    app.TVcineEditField.Value = 0.100;
                    if app.GPUpresentFlag || app.pcType == "AppleSilicon"
                        app.LLRxyzEditField.Value = 0.01;
                    end
                end
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end
                app.RingRmCheckBox.Value = 0;
                app.NLMFCheckBox.Value = 0;

            end

            % Settings for diastolic function, typical number of CINE frames = 32
            if app.DiastolicFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 32;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.000;
                if contains(app.r.dataType,["3D","3Dp2roud","3Dute"])
                    app.TVcineEditField.Value = 0.010;
                    if app.GPUpresentFlag || app.pcType == "AppleSilicon"
                        app.LLRxyzEditField.Value = 0.001;
                    end
                else
                    app.TVcineEditField.Value = 0.100;
                    if app.GPUpresentFlag || app.pcType == "AppleSilicon"
                        app.LLRxyzEditField.Value = 0.01;
                    end
                end
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end
                app.RingRmCheckBox.Value = 0;
                app.NLMFCheckBox.Value = 0;

            end

            % For reconstruction of respiratory motion
            if app.RespFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'respiratory';
                app.FramesEditField.Value = 32;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.000;
                if contains(app.r.dataType,["3D","3Dp2roud","3Dute"])
                    app.TVcineEditField.Value = 0.010;
                    if app.GPUpresentFlag || app.pcType == "AppleSilicon"
                        app.LLRxyzEditField.Value = 0.001;
                    end
                else
                    app.TVcineEditField.Value = 0.100;
                    if app.GPUpresentFlag || app.pcType == "AppleSilicon"
                        app.LLRxyzEditField.Value = 0.01;
                    end
                end
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end
                app.RingRmCheckBox.Value = 0;
                app.NLMFCheckBox.Value = 0;

            end

            % For 'realtime' reconstruction of X frames / heartbeat
            if app.RealTimeFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'realtime';
                app.FramesEditField.Value = 2;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 3;
                app.WVxyzEditField.Value = 0.000;
                app.TVxyzEditField.Value = 0.01;
                app.LLRxyzEditField.Value = 0.05;
                app.TVcineEditField.Value = 0.001;
                app.TVdynEditField.Value = 0.200;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end
                app.FilterButtonPushed;
                app.RingRmCheckBox.Value = 0;
                app.NLMFCheckBox.Value = 1;

            end

            % For variable flip-angle reconstruction for T1 mapping
            if app.VfaFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 6;
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.002;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.000;
                if app.GPUpresentFlag
                    app.LLRxyzEditField.Value = 0.001;
                end
                app.TVcineEditField.Value = 0.010;
                app.TVdynEditField.Value = 0.001;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end
                app.RingRmCheckBox.Value = 0;
                app.NLMFCheckBox.Value = 1;

            end

        end % SetRecoParametersFcn


        function SetNavigatorSelectionFcn(app)

            % Set navigators to lowest default possibility

            if app.fidNavFlag                                                       
                app.SliceFID1Button.Value = 1;
                app.SliceFID2Button.Value = 1;
            elseif app.extFidNavFlag
                app.ExtFID1Button.Value = 1;
                app.ExtFID2Button.Value = 1;
            elseif app.extEchoNavFlag
                app.ExtECHO1Button.Value = 1;
                app.ExtECHO2Button.Value = 1;
            end

        end % SetNavigatorSelectionFcn


        % ------------------------------------------
        % Image acquisition data parameters
        % ------------------------------------------

        % Set MRD paths
        function SetMRDimportPathFcn(app)

            % Try to find text file with mrd import path in app directory
            app.mrdImportPath = '';
            mrdPathFile = 'mrdpath.txt';
            if isfile(mrdPathFile)
                try
                    folderName = fileread(mrdPathFile);
                    if exist(folderName, 'dir')
                        app.mrdImportPath = folderName;
                    end
                catch
                    app.mrdImportPath = '';
                end
            end

            % On PC search for the path in the Windows registry
            if ispc
                try
                    app.mrdImportPath = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    app.mrdImportPath = [app.mrdImportPath,filesep];
                    app.TextMessage('MRD directory found in Windows registry ...');
                    app.TextMessage(strcat("MRD directory = ",app.mrdImportPath," ..."));
                    app.mrdAutoImportPath = app.mrdImportPath;
                catch
                    app.mrdImportPath = '';
                end
            end

            % If still nothing found, than empty the path names
            if isempty(app.mrdImportPath)
                app.mrdAutoImportPath = '';
            else
                app.mrdAutoImportPath = app.mrdImportPath;
            end

        end % SetMRDimportPathFcn


        % Define data parameters
        function DataParametersFcn(app)

            % Heart and respiration parameters
            app.r.bandwidthHR = app.HeartWidthEditField.Value;                      % cardiac bandpass filter bandwidth
            app.r.bandwidthRR = app.RespWidthEditField.Value;                       % respiratory bandpass filter bandwidth
            app.r.meanHeartRate = app.HeartEditField.Value;                         % Heart rate
            app.r.meanRespRate = app.RespEditField.Value;                           % Respiration rate
            app.r.respPercentage = app.RespWindowEditField.Value;                   % percentage of data discarded during respiration, default = 30%
            app.nrFrames = app.FramesEditField.Value;                               % default number of cardiac frames
            app.nrDynamics = app.DynamicsEditField.Value;                           % default number of dynamics

            % Reset the analysis flags
            app.validFilterFlag = false;                                            
            app.validSenseMapFlag = false;
            app.validRecoFlag = false;
            app.validSortFlag = false;
            app.r.multiSliceFlag = false;
            app.r.multiDynamicFlag = false;
     
            % Navigator GUI parameters
            app.extFidNavFlag = app.r.ext_nav_fid_on;
            app.extEchoNavFlag = app.r.ext_nav_echo_on;
            app.fidNavFlag = app.r.slice_nav;
            app.NavEnd1EditField.Value = 1;
            app.NavEnd2EditField.Value = 1;
            app.NavNRPoints1EditField.Value = 1;
            app.NavNRPoints2EditField.Value = 1;
            NoNavSamples1 = max([app.ExtFID1Button.Value*app.r.ext_nav_fid_no_samples app.ExtECHO1Button.Value*app.r.ext_nav_echo_no_samples app.SliceFID1Button.Value*app.r.no_samples_nav]);
            NoNavSamples2 = max([app.ExtFID2Button.Value*app.r.ext_nav_fid_no_samples app.ExtECHO2Button.Value*app.r.ext_nav_echo_no_samples app.SliceFID2Button.Value*app.r.no_samples_nav]);
            app.NavEnd1EditField.Limits = [1 NoNavSamples1];
            app.NavEnd2EditField.Limits = [1 NoNavSamples2];
            app.NavNRPoints1EditField.Limits = [1 NoNavSamples1];
            app.NavNRPoints2EditField.Limits = [1 NoNavSamples2];
            app.NavEnd1EditField.Value = app.r.primaryNavigatorPoint1;              % primary navigator point
            app.NavEnd2EditField.Value = app.r.primaryNavigatorPoint2;              % primary navigator point
            app.DiscardEditField.Value = app.r.nrNavPointsDiscarded;                % number of nav points discarded between navigator and echo
            app.NavNRPoints1EditField.Value = app.r.nrNavPointsUsed1;               % number of nav points used
            app.NavNRPoints2EditField.Value = app.r.nrNavPointsUsed2;               % number of nav points used
            app.StartEditField.Limits = [0 inf];
            app.RangeEditField.Limits = [0 inf];
            app.StartEditField.Value = 120;                                         % reset the nav viewing start value
            app.RangeEditField.Value = 6;                                           % reset the nav viewing range
            app.StartSlider.Limits = [0 6000];
            app.RangeSlider.Limits = [0 2000];
            app.StartSlider.Value = 3000;                                           % reset the nav viewing start value slider
            app.RangeSlider.Value = 1000;                                           % reset the nav viewing range slider
            app.IncludeCheckBox.Value = 0;                                          % reset the include and exclude sliders
            app.ExcludeCheckBox.Value = 0;

            % Flip angles
            app.NrFlipAnglesViewField.Value = app.r.nrFlipAngles;
           
            % Data type dependent parameters
            switch app.r.dataType

                case {'2D','2Dms','3D','3Dp2roud'}

                    app.r = app.r.determineReadoutLength(app);      % #points in readout direction dimx                
                    app.r.dimz = size(app.r.data{1},2);             % #points in 3rd dimension (2D multi-slice or 3D)
            
                    switch app.r.pe1_order

                        case 0

                            % Linear
                            app.TrajectoryViewField.Value = 'linear';
                            app.r.dimy = app.r.NO_VIEWS;                                                                % nr phase encoding steps (no_views)
                            app.r.nrKsteps = app.r.dimy;                                                                % nr k-space points per repetition = nr phase encoding steps
                            app.r.nrKlines = app.r.nr_repetitions*app.r.dimy*app.r.dimz;                                % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory
                            
                            % Plot the trajectory
                            cla(app.TrajectoryFig); 
                            plot(app.TrajectoryFig, app.r.trajectory);                                                            
                            xlim(app.TrajectoryFig,[1 length(app.r.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.r.trajectory)-1 max(app.r.trajectory)+1]);
                       
                        case 2

                            % Zigzag
                            app.TrajectoryViewField.Value = 'zigzag';
                            app.r.dimy = app.r.NO_VIEWS;                                                                % nr phase encoding steps (no_views)
                            app.r.nrKsteps = app.r.dimy;                                                                % nr k-space points per repetition = nr phase encoding steps
                            app.r.nrKlines = app.r.nr_repetitions*app.r.dimy*app.r.dimz;                                % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory
                            
                            % Plot the trajectory
                            cla(app.TrajectoryFig); 
                            plot(app.TrajectoryFig, app.r.trajectory);                                                            
                            xlim(app.TrajectoryFig,[1 length(app.r.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.r.trajectory)-1 max(app.r.trajectory)+1]);

                        case 3

                            % User defined
                            app.TrajectoryViewField.Value = 'user defined';
                            app.r.dimy = max(app.r.gp_var_mul)-min(app.r.gp_var_mul)+1;                                 % effective nr phase encoding steps (no_effective_views)
                            app.r.nrKsteps = app.r.NO_VIEWS;                                                            % nr k-space trajectory points (no_views) in table
                            app.r.nrKlines = app.r.nr_repetitions*app.r.nrKsteps*app.r.dimz;                            % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory

                            % Plot the trajectory
                            cla(app.TrajectoryFig); 
                            plot(app.TrajectoryFig, app.r.trajectory);                                                            
                            xlim(app.TrajectoryFig,[1 length(app.r.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.r.trajectory)-1 max(app.r.trajectory)+1]);

                        case {4,5}

                            % P2ROUD acquisition, based on LUT file
                            app.TrajectoryViewField.Value = 'P2ROUD';
                            app.r.dimy = app.r.NO_VIEWS;                                                                % nr phase encoding steps (no_views)
                            app.r.nrKsteps = app.r.dimy;                                                                % nr k-space points per repetition = nr phase encoding steps
                            app.r.nrKlines = app.r.nr_repetitions*app.r.nrKsteps*app.r.dimz;                            % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory
                     
                            % Plot the k-space filling trajectory in the app if available
                            cla(app.TrajectoryFig); 
                            if strcmp(app.r.dataType,'3Dp2roud')
                                scatter(app.TrajectoryFig, app.r.trajectory(1:2:end),app.r.trajectory(2:2:end),'s');            % plot the 3D trajectory
                                xlim(app.TrajectoryFig,[min(app.r.trajectory(1:2:end))-1,max(app.r.trajectory(1:2:end))+1]);
                                ylim(app.TrajectoryFig,[min(app.r.trajectory(2:2:end))-1,max(app.r.trajectory(2:2:end))+1]);
                            else
                                text(app.TrajectoryFig,-0.7,1,'MISSING');
                                text(app.TrajectoryFig,-2.4,-0.5,'TRAJECTORY');
                                xlim(app.TrajectoryFig,[-3 9]);
                                ylim(app.TrajectoryFig,[-3 3]);
                            end
                            
                            % Final check if the trajectory has the correct size matching the data
                            if length(app.r.trajectory) ~= 2*app.r.nrKsteps*app.r.dimz
                                app.TextMessage('ERROR: Missing or invalid trajectory file ...');
                                app.SetStatus(2);
                            end

                    end

                case {'2Dradial','2Dradialms'}

                    % Radial
                    app.TrajectoryViewField.Value = '2D radial';
                    app.r.pe1_order = 8;                                                % pe1_order = 8 for radial scanning
                    app.DiscardEditField.Value = 0;                                     % no discarded points
                    app.r.nrNavPointsDiscarded = 0;             
                    app.SharingEditField.Value = 0;                                     % view sharing not implemented for radial sequences
                    app.r.dimx = app.r.NO_SAMPLES;                                      % number of samples
                    app.r.dimy = app.r.dimx;                                            % y-dimension equal to number of samples
                    app.r.dimz = app.r.NO_SLICES;                                       % slices
                    app.r.nrKsteps = app.r.NO_VIEWS;                                    % number of radial spokes per repetition
                    app.r.nrKlines = app.r.nr_repetitions*app.r.nrKsteps*app.r.dimz;    % total number of spokes measured
                    app.r = app.r.getKspaceTrajectory(app);                             % get the k-space trajectory
                    app.RadialAngleEditField.Value = app.r.spoke_increment;             % radial angle
                    app.HalfCircleRadialButton.Value = 1;
                    if app.RadialAngleEditField.Value > 1
                        app.UserDefinedRadialButton.Value = 1;
                    end

                    % Set the parameters for the gradient calibration
                    app.GradDelayCalibrationCheckBox.Value = 1;
                    app.RingMethodCheckBox.Value = 1;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.CenterEchoCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;

                    % Report on the trajectory in the app
                    text(app.TrajectoryFig,-0.7,1,'2D radial');
                    text(app.TrajectoryFig,-0.7,-0.5,'trajectory');
                    xlim(app.TrajectoryFig,[-3 9]);
                    ylim(app.TrajectoryFig,[-3 3]);

                case '3Dute'

                    % 3D UTE
                    app.TrajectoryViewField.Value = '3D UTE';
                    app.r.pe1_order = 9;                                % pe1_order = 9 for 3D UTE
                    app.DiscardEditField.Value = 0;                     % no discarded points
                    app.r.nrNavPointsDiscarded = 0; 
                    app.r.dimx = app.r.NO_SAMPLES;                      % number of samples
                    app.r.dimy = app.r.dimx;                            % y-dimension equal to number of samples
                    app.r.dimz = app.r.dimx;                            % z-dimension equal to number of samples
                    app.r.nrKsteps = app.r.NO_SAMPLES;                  % number of points in a radial spoke
                    app.r.nrKlines = app.r.nr_repetitions;              % number of total spokes
                    app.r = app.r.getKspaceTrajectory(app);             % get trajectory
                    
                    % Set the parameters for the gradient calibration
                    app.GradDelayCalibrationCheckBox.Value = 0;
                    app.RingMethodCheckBox.Value = 0;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.CenterEchoCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;

                    % Report on the trajectory in the app
                    text(app.TrajectoryFig,-0.2,1,'3D UTE');
                    text(app.TrajectoryFig,-0.7,-0.5,'trajectory');
                    xlim(app.TrajectoryFig,[-3 9]);
                    ylim(app.TrajectoryFig,[-3 3]);

            end

            % Reset include / exclude window
            app.r.includeWindow = ones(app.r.nrKlines,1);
            app.r.excludeWindow = ones(app.r.nrKlines,1);

            % Total acquisition time in seconds
            app.acqDur = app.r.nrKlines*app.r.TR/1000;

            % Display the acquisition parameters in the app
            app.NxViewField.Value = app.r.dimx;
            app.NyViewField.Value = app.r.dimy;
            app.NzViewField.Value = app.r.dimz;
            app.NcViewField.Value = app.r.nr_coils;
            app.RepetitionsViewField.Value = app.r.nr_repetitions;
            app.FieldOfViewViewField.Value = app.r.FOV;
            app.NrKlinesViewField.Value = app.r.nrKlines;
            app.TRViewField.Value = app.r.TR;
            app.TEViewField.Value = app.r.TE;
            app.ScanTimeViewField.Value = string(duration(seconds(app.acqDur),'Format','hh:mm:ss.SSS')); 
            app.TimeDynViewField.Value = string(duration(seconds(app.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));
            if app.r.VFA_size == 0
                app.FlipAnglesViewField.Value = num2str(app.r.alpha);
            else
                app.FlipAnglesViewField.Value = num2str(app.r.VFA_angles);
            end
            switch app.r.dataType
                case {'3D','3Dp2roud'}
                    app.TrajectorySizeViewField.Value = app.r.nrKsteps*app.r.dimz;
                otherwise
                    app.TrajectorySizeViewField.Value = app.r.nrKsteps;
            end

            % Geometry parameters
            app.FieldOfViewViewField2.Value = app.r.FOV;
            app.AspectRatioViewField.Value = app.r.aspectratio;
            app.SliceThicknessViewField.Value = app.r.SLICE_THICKNESS;
            app.SliceGapViewField.Value = app.r.SQLsliceGap;
            app.AspectRatioViewField.Value = app.r.aspectratio;
            app.PhaseOrientationViewField.Value = app.r.PHASE_ORIENTATION;
            app.OffsetXViewField.Value = app.r.SQLoffsetX;
            app.OffsetYViewField.Value = app.r.SQLoffsetY;
            app.OffsetZViewField.Value = app.r.SQLoffsetZ;
            app.AngleXViewField.Value = app.r.SQLangleX;
            app.AngleYViewField.Value = app.r.SQLangleY;
            app.AngleZViewField.Value = app.r.SQLangleZ;
      
            % Define the ranges of the display range sliders
            app.StartEditField.Limits = [0, app.r.nrKlines-1]*app.r.TR/1000;
            app.StartSlider.Limits = [0, app.r.nrKlines-1]*app.r.TR/1000;
            app.StartEditField.Value = app.r.nrKlines*app.r.TR/2000;
            app.StartSlider.Value = app.StartEditField.Value;
            maxRange = 2000; % max range (samples)
            maxRange(maxRange > (app.r.nrKlines-1)) = app.r.nrKlines - 1;
            app.RangeEditField.Limits = [0, maxRange]*app.r.TR/1000;
            app.RangeSlider.Limits = [0, maxRange]*app.r.TR/1000;
            tmpRange = 600; % preferred range (samples), but smaller if less data available
            tmpRange(tmpRange > round(app.r.nrKlines/2 -1)) = round(app.r.nrKlines/4);
            app.RangeEditField.Value = tmpRange*app.r.TR/1000;
            app.RangeSlider.Value = app.RangeEditField.Value;   

            % Data limits
            app.IncludeStartSlider.Limits = [0, app.acqDur];
            app.IncludeEndSlider.Limits = [0, app.acqDur];
            app.IncludeStartSlider.Value = 0;
            app.IncludeEndSlider.Value = app.acqDur;
            app.ExcludeStartSlider.Limits = [0, app.acqDur];
            app.ExcludeEndSlider.Limits = [0, app.acqDur];
            app.ExcludeStartSlider.Value = 0;
            app.ExcludeEndSlider.Value = app.acqDur;
            app.SenseMapSpinner1.Value = 1;
            app.SenseMapSpinner2.Value = 1;
            app.ESPIRiTCheckBox.Value = 0;
            app.WindowScaleSlider.Limits = [0, app.r.maxImageValue];
            app.LevelScaleSlider.Limits = [0, app.r.maxImageValue];
         
            % Case of multicoil data
            if app.r.multiCoilFlag
                if app.HeartCoilSpinner1.Value > app.r.nr_coils
                    app.HeartCoilSpinner1.Value = 1;
                    app.MovieCoilSpinner.Value = 1;
                end
                if app.HeartCoilSpinner2.Value > app.r.nr_coils
                    app.HeartCoilSpinner2.Value = 1;
                    app.MovieCoilSpinner.Value = 1;
                end
                if app.RespCoilSpinner1.Value > app.r.nr_coils
                    app.RespCoilSpinner1.Value = 1;
                    app.MovieCoilSpinner.Value = 1;
                end
                if app.RespCoilSpinner2.Value > app.r.nr_coils
                    app.RespCoilSpinner2.Value = 1;
                    app.MovieCoilSpinner.Value = 1;
                end
                app.HeartCoilSpinner1.Limits = [1, app.r.nr_coils];
                app.RespCoilSpinner1.Limits = [1, app.r.nr_coils]; 
                app.HeartCoilSpinner2.Limits = [1, app.r.nr_coils];
                app.RespCoilSpinner2.Limits = [1, app.r.nr_coils]; 
                app.MovieCoilSpinner.Limits = [1, app.r.nr_coils];
            else
                app.HeartCoilSpinner1.Value = 1;
                app.HeartCoilSpinner1.Limits = [1, 2];
                app.RespCoilSpinner1.Value = 1;
                app.RespCoilSpinner1.Limits = [1, 2];
                app.HeartCoilSpinner2.Value = 1;
                app.HeartCoilSpinner2.Limits = [1, 2];
                app.RespCoilSpinner2.Value = 1;
                app.RespCoilSpinner2.Limits = [1, 2];
                app.MovieCoilSpinner.Value = 1;
                app.MovieCoilSpinner.Limits = [1, 2];
            end

            % Case of variable flip-angle data
            if app.r.vfaDataFlag
                app.DynamicsEditFieldLabel.Text = '# flip-angles';
                app.MovieDynLabel.Text = 'flip-angle';
                app.MovieDynEditFieldLabel.Text = 'flip-angle';
                app.DynamicsEditField.Editable = 'off';
                app.DynamicsEditField.Value = app.r.VFA_size;
                app.nrDynamics = app.r.VFA_size;
            else
                app.DynamicsEditFieldLabel.Text = '# dynamics';
                app.MovieDynLabel.Text = 'dynamic';
                app.MovieDynEditFieldLabel.Text = 'dynamic';
                app.DynamicsEditField.Editable = 'on';
                app.DynamicsEditField.Value = 1;
                app.nrDynamics = 1;
            end

            % Set initial reconstruction parameters, based on the data
            if app.AutoGuessRecoCheckBox.Value
                app.r = app.r.guessRecoType(app);
                switch app.r.recoGuess
                    case 'scout'
                        app.ScoutButton.Value = 1;
                    case 'systolic function'
                        app.SystolicFunctionButton.Value = 1;
                    case 'diastolic function'
                        app.DiastolicFunctionButton.Value = 1;
                    case 'variable flip-angle'
                        app.VfaFunctionButton.Value = 1;
                end
                app.SetRecoParametersFcn;
            end

        end % DataParametersFcn


        % ------------------------------------------
        % Other functions
        % ------------------------------------------


        % Calculates the power spectrum of the raw navigator signal within the boundaries of the data-window
        function CalculateNavigatorSpectrumFcn(app)

            % Determine the power spectrum of the navigator
            app.r.physioFilterSettings = [app.MinHeartRateEditField.Value app.MaxHeartRateEditField.Value app.MinRespRateEditField.Value app.MaxRespRateEditField.Value app.FilterOrderEditField.Value app.RespShiftEditField.Value];
            app.r = app.r.determinePowerSpectrumPCA(app);

            % Update the detected heart rate and respiration rate fields
            app.HeartEditField.Value = app.r.detectedHR;
            app.RespEditField.Value = app.r.detectedRR;

            % Heart rate window cannot be larger than the heart rate
            app.HeartWidthEditField.Value(app.HeartWidthEditField.Value > app.HeartEditField.Value) = max([app.HeartWidthEditField.Limits(1) app.HeartEditField.Value]);

            % Repiration rate window cannot be larger than the respiration rate
            app.RespWidthEditField.Value(app.RespWidthEditField.Value > app.RespEditField.Value) = max([app.RespWidthEditField.Limits(1) app.RespEditField.Value]);
      
        end % CalculateNavigatorSpectrumFcn


        % ------------------------------------------
        % Plotting figures and images
        % ------------------------------------------


        % Plot the raw navigator signals
        function PlotRawNavigatorFcn(app)

            % Display x-range
            startPoint = round(app.StartEditField.Value*1000/app.r.TR);
            endPoint = round((app.StartEditField.Value + app.RangeEditField.Value)*1000/app.r.TR);
            startPoint(startPoint<1) = 1;
            endPoint(endPoint>app.r.nrKlines) = app.r.nrKlines;

            % Extract part of the navigator that will be shown
            trace1 = app.r.navAmplitude1{app.HeartCoilSpinner2.Value}(startPoint:endPoint);
            trace1 = trace1 - mean(trace1(:));
            trace2 = app.r.navAmplitude2{app.RespCoilSpinner2.Value}(startPoint:endPoint);
            trace2 = trace2 - mean(trace2(:));
            timePoints = (startPoint:endPoint)*app.r.TR/1000;

            % Display y-range
            maxPlot1 = 1.25*max(trace1);
            minPlot1 = min(trace1)-0.25*maxPlot1;
            maxPlot1(maxPlot1<minPlot1) = minPlot1 + 1;
            maxPlot2 = 1.25*max(trace2);
            minPlot2 = min(trace2)-0.25*maxPlot2;
            maxPlot2(maxPlot2<minPlot2) = minPlot2 + 1;

            % Plot the navigator 1
            plot(app.HeartRawNavFig, timePoints, trace1, 'LineWidth',1, 'Color',app.blue);
            xlim(app.HeartRawNavFig, [min(timePoints)-app.r.TR/1000 max(timePoints)+app.r.TR/1000]);
            ylim(app.HeartRawNavFig, [minPlot1 maxPlot1+0.001]);
            drawnow;

            % Plot the navigator 2
            plot(app.RespRawNavFig, timePoints, trace2, 'LineWidth',1, 'Color',app.blue);
            xlim(app.RespRawNavFig, [min(timePoints)-app.r.TR/1000 max(timePoints)+app.r.TR/1000]);
            ylim(app.RespRawNavFig, [minPlot2 maxPlot2+0.001]);
            drawnow;

            % Extract the part of the raw navigator FID or ECHO to be shown
            mid1 = round(size(app.r.rawNav1{app.HeartCoilSpinner1.Value},1)/2);
            trace3 = squeeze(abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(mid1,:)));
            mid2 = round(size(app.r.rawNav2{app.RespCoilSpinner1.Value},1)/2);
            trace4 = squeeze(abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(mid2,:)));
            
            % Display y-range
            maxPlot3 = max(abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(mid1,:)));
            maxPlot4 = max(abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(mid2,:)));
         
            % Used navigator points
            x3 = app.NavEnd1EditField.Value - app.NavNRPoints1EditField.Value + 1:app.NavEnd1EditField.Value;
            x4 = app.NavEnd2EditField.Value - app.NavNRPoints2EditField.Value + 1:app.NavEnd2EditField.Value;
          
            % Plot the raw navigator 1 FID or ECHO
            cla(app.Nav1Fig);
            hold(app.Nav1Fig,"on");
            plot(app.Nav1Fig, trace3, 'o-' ,'LineWidth' ,1 , 'Color', app.blue);
            plot(app.Nav1Fig, x3, trace3(x3), 'o', 'Color', app.red, 'MarkerFaceColor', app.green);
            plot(app.Nav1Fig, x3(end), trace3(x3(end)), 'o', 'Color', app.red, 'MarkerFaceColor', app.traceOrange);
            xlim(app.Nav1Fig, [0 length(trace3)+1]);
            ylim(app.Nav1Fig, [0 1.1*maxPlot3+1]);
            hold(app.Nav1Fig,"off");
            drawnow;

            % Plot the raw navigator 2 FID or ECHO
            cla(app.Nav2Fig);
            hold(app.Nav2Fig,"on");
            plot(app.Nav2Fig, trace4, 'o-', 'LineWidth' ,1 , 'Color', app.blue);
            plot(app.Nav2Fig, x4, trace4(x4), 'o', 'Color', app.red, 'MarkerFaceColor', app.green);
            plot(app.Nav2Fig, x4(end), trace4(x4(end)), 'o', 'Color', app.red, 'MarkerFaceColor', app.traceOrange);
            xlim(app.Nav2Fig, [0 length(trace4)+1]);
            ylim(app.Nav2Fig, [0 1.1*maxPlot4+1]);
            hold(app.Nav2Fig,"off");
            drawnow;

        end % PlotRawNavigatorFcn

        
        % Plot the navigator with cardiac filtering
        function PlotHeartNavigatorFcn(app)

            % Clear the image
            cla(app.HeartNavFig);
            hold(app.HeartNavFig,'on');

            if app.validFilterFlag

                % Display x-range
                startPoint = round(app.StartEditField.Value*1000/app.r.TR);
                endPoint = round((app.StartEditField.Value + app.RangeEditField.Value)*1000/app.r.TR);
                startPoint(startPoint<1) = 1;
                endPoint(endPoint>app.r.nrKlines) = app.r.nrKlines;

                % Extract part of the navigator that will be shown and scale for viewing
                trace1 = app.r.PCANav1(startPoint:endPoint);
                trace1 = trace1 - mean(trace1(:));
                trace2 = app.r.heartNav(startPoint:endPoint);
                trace2 = 0.25*max(trace1)*(trace2 - mean(trace2(:))) + median(trace1(:));
                timePoints = (startPoint:endPoint)*app.r.TR/1000;
               
                % Display y-range
                maxPlot = 1.25*max(trace1);
                minPlot = min(trace1)-0.25*maxPlot;
                maxPlot(maxPlot<minPlot) = minPlot + 1;

                % Show the detected trigger points (only when movie is not running)
                if app.stopNavTimeMovieFlag
                    triggerPoints = app.r.heartTrigPoints((app.r.heartTrigPoints>=startPoint) & (app.r.heartTrigPoints<=endPoint))*app.r.TR/1000;
                    if length(triggerPoints) < 50
                        for cnt = 1:length(triggerPoints)
                            win=plot(app.HeartNavFig,[triggerPoints(cnt),triggerPoints(cnt)],[minPlot,maxPlot+0.001],'black','LineWidth',1);
                            win.Color(4) = 0.2; % transparancy
                        end
                    end
                end

                % Plot the raw navigator
                plot(app.HeartNavFig, timePoints, trace1, 'LineWidth',1, 'Color',app.blue);

                % Plot the navigator filtered for cardiac motion
                plot(app.HeartNavFig, timePoints, trace2, 'LineWidth',0.75, 'Color',app.red);

                % Set plot axes
                xlim(app.HeartNavFig, [min(timePoints)-app.r.TR/1000 max(timePoints)+app.r.TR/1000]);
                ylim(app.HeartNavFig, [minPlot maxPlot+0.001]);

            end

            hold(app.HeartNavFig,'off');
            drawnow limitrate;

        end % PlotHeartNavigatorFcn


        % Plot the navigator with respiratory filtering
        function PlotRespirationNavigatorFcn(app)

            % Clear the image
            cla(app.RespNavFig);
            hold(app.RespNavFig,'on');

            if app.validFilterFlag

                % Display x-range
                startPoint = round(app.StartEditField.Value*1000/app.r.TR);
                endPoint = round((app.StartEditField.Value+app.RangeEditField.Value)*1000/app.r.TR);
                startPoint(startPoint<1) = 1;
                endPoint(endPoint>app.r.nrKlines) = app.r.nrKlines;

                % Extract part of the navigator that will be shown and scale for viewing
                trace1 = app.r.PCANav2(startPoint:endPoint);
                trace1 = trace1 - mean(trace1(:));
                trace2 = app.r.respNav(startPoint:endPoint);
                trace2 = trace2 - mean(trace2(:));
                trace2 = 0.3*max(trace1) * trace2 / max(rmoutliers(abs(trace2(:)),"percentiles",[0 80]));
                timePoints = (startPoint:endPoint)*app.r.TR/1000;
                
                % Display range
                maxPlot = 1.25*max(trace1(:));
                minPlot = min(trace1)-0.25*maxPlot;
                maxPlot(maxPlot<minPlot) = minPlot + 1;
                
                % Plot respiratory window masks in transparent red
                steps = 4;
                if app.stopNavTimeMovieFlag
                    steps = 1;
                end
                for cnt = startPoint:steps:endPoint
                    if app.r.respWindow(cnt)==1
                        win=line(app.RespNavFig,[cnt;cnt]*app.r.TR/1000,[app.r.respWindow(cnt)*minPlot;app.r.respWindow(cnt)*maxPlot],'Color',app.red,'LineWidth',1);
                        win.Color(4) = 0.1*steps; % transparancy
                    end
                end

                % Show the detected trigger points (only when movie is not running)
                if app.stopNavTimeMovieFlag
                    triggerpoints = app.r.respTrigPoints((app.r.respTrigPoints>=startPoint) & (app.r.respTrigPoints<=endPoint))*app.r.TR/1000;
                    if length(triggerpoints) < 10
                        for cnt = 1:length(triggerpoints)
                            win=plot(app.RespNavFig,[triggerpoints(cnt),triggerpoints(cnt)],[minPlot,maxPlot+0.001],'black','LineWidth',1);
                            win.Color(4) = 0.2; % transparancy
                        end
                    end
                end

                % Plot raw navigator
                plot(app.RespNavFig, timePoints, trace1, 'LineWidth',1, 'Color',app.blue);

                % Plot navigator signal filtered for respiratory motion
                plot(app.RespNavFig, timePoints, trace2, 'LineWidth',0.75, 'Color',app.red);

                % Set plot axes
                xlim(app.RespNavFig, [min(timePoints)-app.r.TR/1000 max(timePoints)+app.r.TR/1000]);
                ylim(app.RespNavFig, [minPlot maxPlot+0.001]);

            end

            hold(app.RespNavFig,'off');
            drawnow limitrate;

        end % PlotRespirationNavigatorFcn


        % Plot the time-heartrate and time-respirationrate plots
        function PlotRatesFcn(app)

            % Clear the images
            cla(app.HeartRateTimeFig);
            cla(app.RespRateTimeFig);

            if app.validFilterFlag

                % Y-scale
                maxHeart = round(2*app.r.meanHeartRate);
                maxResp = round(2*app.r.meanRespRate);
                maxHeart(maxHeart<100) = 100;
                maxResp(maxResp<50) = 50;

                % Show the time-heartrate plot
                hold(app.HeartRateTimeFig,'on');
                plot(app.HeartRateTimeFig, app.r.TR*app.r.heartTrigPoints(1:end-1)'/1000, app.r.heartRateTime, '.', 'Color',app.blue);                    % heart rate
                plot(app.HeartRateTimeFig, app.r.TR*app.r.heartTrigPoints(1:end-1)'/1000, app.r.heartRateTimeFiltered, 'LineWidth',2, 'Color',app.red);   % trend line
                axis(app.HeartRateTimeFig,[0 app.r.TR*app.r.heartTrigPoints(end)/1000 0 maxHeart]);
                % app.HeartRateTimeFig.Position = [32,6,260,185];
                hold(app.HeartRateTimeFig,'off');
               
                % Show the time-respirationrate plot
                hold(app.RespRateTimeFig,'on');
                plot(app.RespRateTimeFig, app.r.TR*app.r.respTrigPoints(1:end-1)'/1000, app.r.respRateTime, '.', 'Color',app.blue);                        % respiration rate
                plot(app.RespRateTimeFig, app.r.TR*app.r.respTrigPoints(1:end-1)'/1000, app.r.respRateTimeFiltered, 'LineWidth',2, 'Color',app.red);       % trend line
                axis(app.RespRateTimeFig,[0 app.r.TR*app.r.respTrigPoints(end)/1000 0 maxResp]);
                % app.RespRateTimeFig.Position = [322,6,260,185];
                hold(app.RespRateTimeFig,'off');
             
                % Plot the dynamic or multi-slice windows
                app.PlotDynamicsWindowsFcn;
                app.PlotMultiSliceWindowsFcn;

            end

        end % PlotRatesFcn


        % Plot the separation into dynamics in the time-heart-rate and time-respiration-rate plots
        function PlotDynamicsWindowsFcn(app)

            % Delete previous windows
            delete(app.dPlot1);
            delete(app.dPlot2);
            delete(app.dPlot1Txt);
            delete(app.dPlot2Txt);

            if app.DynamicsEditField.Value > 1

                % Y-scale
                maxHeart = round(2*app.r.meanHeartRate);
                maxResp = round(2*app.r.meanRespRate);
                maxHeart(maxHeart<100) = 100;
                maxResp(maxResp<50) = 50;

                dynamicLabel = 'd';
                if app.r.vfaDataFlag
                    dynamicLabel = 'fa';
                end

                hold(app.HeartRateTimeFig,'on');

                % Show shaded areas in the time-heartrate plot
                heartRange = app.r.TR*app.r.heartTrigPoints(end-1)/1000;
                step = heartRange / app.DynamicsEditField.Value;
                pgon = polyshape();
                for cnt = 1:2:app.DynamicsEditField.Value
                    xStart = (cnt-1)*step;
                    xEnd = cnt*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]));
                end
                app.dPlot1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.DynamicsEditField.Value < 15
                    cnt = 1:app.DynamicsEditField.Value;
                    txtPosX = (cnt - 0.5 - app.DynamicsEditField.Value / 25)*step;
                    txtPosY = 0.95*maxHeart*ones(app.DynamicsEditField.Value,1)';
                    txtStr = strcat(dynamicLabel,num2str(cnt(:)));
                    app.dPlot1Txt = text(app.HeartRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.HeartRateTimeFig,'off');

                hold(app.RespRateTimeFig,'on');

                % Show in the time-respirationrate plot
                heartRange = app.r.TR*app.r.respTrigPoints(end-1)/1000;
                step = heartRange / app.DynamicsEditField.Value;
                pgon = polyshape();
                for cnt = 1:2:app.DynamicsEditField.Value
                    xStart = (cnt-1)*step;
                    xEnd = cnt*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]));
                end
                app.dPlot2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.DynamicsEditField.Value < 15
                    cnt = 1:app.DynamicsEditField.Value;
                    txtPosX = (cnt - 0.5 - app.DynamicsEditField.Value / 25)*step;
                    txtPosY = 0.95*maxResp*ones(app.DynamicsEditField.Value,1)';
                    txtStr = strcat(dynamicLabel,num2str(cnt(:)));
                    app.dPlot2Txt = text(app.RespRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.RespRateTimeFig,'off');

            end

        end % PlotDynamicsWindowsFcn


        % Plot the separation into slices for the time-heartrate and time-respiratiorate plots
        function PlotMultiSliceWindowsFcn(app)

            % Delete previous windows
            delete(app.msPlot1);
            delete(app.msPlot2);
            delete(app.msPlot1Txt);
            delete(app.msPlot2Txt);

            if strcmp(app.r.dataType,'2Dms') || strcmp(app.r.dataType,'2Dradialms')

                % Y-scale
                maxHeart = round(2*app.r.meanHeartRate);
                maxResp = round(2*app.r.meanRespRate);
                maxHeart(maxHeart<100) = 100;
                maxResp(maxResp<50) = 50;

                hold(app.HeartRateTimeFig,'on');

                % Show shaded areas in the time-heartrate plot
                heartRange = app.r.TR*app.r.heartTrigPoints(end-1)/1000;
                step = heartRange / app.NzViewField.Value;
                pgon = polyshape();
                for cnt = 1:2:app.NzViewField.Value
                    xStart = (cnt-1)*step;
                    xEnd = cnt*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]));
                end
                app.msPlot1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.NzViewField.Value < 15
                    cnt = 1:app.NzViewField.Value;
                    txtPosX = (cnt - 0.5 - app.NzViewField.Value / 50)*step;
                    txtPosY = 0.95*maxHeart*ones(app.NzViewField.Value,1)';
                    txtStr = strcat('s',num2str(cnt(:)));
                    app.msPlot1Txt = text(app.HeartRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.HeartRateTimeFig,'off');

                hold(app.RespRateTimeFig,'on');

                % Show in the time-respirationrate plot
                heartRange = app.r.TR*app.r.respTrigPoints(end-1)/1000;
                step = heartRange / app.NzViewField.Value;
                pgon = polyshape();
                for cnt = 1:2:app.NzViewField.Value
                    xStart = (cnt-1)*step;
                    xEnd = cnt*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]));
                end
                app.msPlot2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.NzViewField.Value < 15
                    cnt = 1:app.NzViewField.Value;
                    txtPosX = (cnt - 0.5 - app.NzViewField.Value / 50)*step;
                    txtPosY = 0.95*maxResp*ones(app.NzViewField.Value,1)';
                    txtStr = strcat('s',num2str(cnt(:)));
                    app.msPlot2Txt = text(app.RespRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.RespRateTimeFig,'off');

            end

        end % PlotMultiSliceWindowsFcn


        % Plot windows in time-heartrate and time-respirationrate plots which indicate the range of data shown in the navigator plots
        function PlotRateLimitsFcn(app)

            % Delete previous windows
            delete(app.cardRateWindow);
            delete(app.respRateWindow);

            % Start and ending of the window
            xStart = app.StartEditField.Value;
            xEnd = xStart + app.RangeEditField.Value;

            % Y-scale
            maxHeart = round(2*app.r.meanHeartRate);
            maxResp = round(2*app.r.meanRespRate);
            maxHeart(maxHeart<100) = 100;
            maxResp(maxResp<50) = 50;

            % Time-heartrate plot navigator inspection window
            hold(app.HeartRateTimeFig,'on');
            pgon = polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]);
            app.cardRateWindow = plot(app.HeartRateTimeFig, pgon,'FaceColor','black','FaceAlpha',0.25,'LineStyle','none');
            hold(app.HeartRateTimeFig,'off');

            % Time-respirationrate plot navigator inspection window
            hold(app.RespRateTimeFig,'on');
            pgon = polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
            app.respRateWindow = plot(app.RespRateTimeFig, pgon,'FaceColor','black','FaceAlpha',0.25,'LineStyle','none');
            hold(app.RespRateTimeFig,'off');

        end % PlotRateLimitsFcn


        % Plot the data window in time-heartrate and time-respirationrate plots
        function PlotDataWindowFcn(app)

            % Delete previous windows
            delete(app.dWin1);
            delete(app.dWin2);
            delete(app.dWin3);
            delete(app.dWin4);

            % Y-scale
            maxHeart = round(2*app.r.meanHeartRate);
            maxResp = round(2*app.r.meanRespRate);
            maxHeart(maxHeart<100) = 100;
            maxResp(maxResp<50) = 50;

            if app.IncludeCheckBox.Value

                % Time-heartrate plot window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.IncludeStartSlider.Value app.IncludeStartSlider.Value app.IncludeEndSlider.Value app.IncludeEndSlider.Value],[5 maxHeart-5 maxHeart-5 5]);
                app.dWin1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.brightgreen,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.r.TR*app.r.heartTrigPoints(end)/1000 0 maxHeart]);
                hold(app.HeartRateTimeFig,'off');
             
                % Time-respirationrate plot window
                hold(app.RespRateTimeFig,'on');
                pgon = polyshape([app.IncludeStartSlider.Value app.IncludeStartSlider.Value app.IncludeEndSlider.Value app.IncludeEndSlider.Value],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
                app.dWin2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.brightgreen,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespRateTimeFig,[0 app.r.TR*app.r.respTrigPoints(end)/1000 0 maxResp]);
                hold(app.RespRateTimeFig,'off');
                drawnow;

            end

            if app.ExcludeCheckBox.Value

                % Time-heartrate plot window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.ExcludeStartSlider.Value app.ExcludeStartSlider.Value app.ExcludeEndSlider.Value app.ExcludeEndSlider.Value],[5 maxHeart-5 maxHeart-5 5]);
                app.dWin3 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.red,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.r.TR*app.r.heartTrigPoints(end)/1000 0 maxHeart]);
                hold(app.HeartRateTimeFig,'off');
              
                % Time-respirationrate plot window
                hold(app.RespRateTimeFig,'on');
                pgon = polyshape([app.ExcludeStartSlider.Value app.ExcludeStartSlider.Value app.ExcludeEndSlider.Value app.ExcludeEndSlider.Value],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
                app.dWin4 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.red,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespRateTimeFig,[0 app.r.TR*app.r.respTrigPoints(end)/1000 0 maxResp]);
                hold(app.RespRateTimeFig,'off');
                drawnow;

            end

            % Set the acquisition parameters
            app.NrKlinesViewField.Value = nnz(app.r.includeWindow.*app.r.excludeWindow);
            app.RepetitionsViewField.Value = round(app.NrKlinesViewField.Value/app.TrajectorySizeViewField.Value);
            app.ScanTimeViewField.Value = string(duration(seconds(app.NrKlinesViewField.Value*app.r.TR/1000),'Format','hh:mm:ss.SSS'));
            app.TimeDynViewField.Value = string(duration(seconds(app.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));

        end % PlotDataWindowFcn


        % Plot the power spectrum of the raw navigator signal
        function PlotNavigatorSpectrumFcn(app)

            % Linear or log plot spectrum 1
            if strcmp(app.HeartLogLinButton.Text,'lin')
                intensity1 = app.r.powerSpectrum1;
            else
                intensity1 = log(app.r.powerSpectrum1);
            end

            % Linear or log plot spectrum 2
            if strcmp(app.RespLogLinButton.Text,'lin')
                intensity2 = app.r.powerSpectrum2;
            else
                intensity2 = log(app.r.powerSpectrum2);
            end

            % Limits
            startFreq = find(app.r.frequency > app.HeartMinEditField.Value,1);
            endFreq   = find(app.r.frequency > app.HeartMaxEditField.Value,1);
        
            % Set the x and y limits of spectrum 1 
            yMax1 = 1.3*max(intensity1(startFreq:endFreq));
            yMin1 = -0.05*yMax1;
            if isinf(yMax1) || isinf(yMin1)
                yMin1 =0;
                yMax1 = 1;
            end
            yMax1(yMax1<yMin1) = yMin1+1;
            cla(app.HeartSpectrumFig);
            hold(app.HeartSpectrumFig,'on');
            xlim(app.HeartSpectrumFig, [app.HeartMinEditField.Value app.HeartMaxEditField.Value]);
            ylim(app.HeartSpectrumFig, [yMin1, yMax1+1]);

            % Limits
            startFreq = find(app.r.frequency > app.RespMinEditField.Value,1);
            endFreq   = find(app.r.frequency > app.RespMaxEditField.Value,1);

             % Set the x and y limits of spectrum 2 
            yMax2 = 1.3*max(intensity2(startFreq:endFreq));
            yMin2 = -0.05*yMax2;
            if isinf(yMax2) || isinf(yMin2)
                yMin2 =0;
                yMax2 = 1;
            end
            yMax2(yMax2<yMin2) = yMin2+1;
            cla(app.RespSpectrumFig);
            hold(app.RespSpectrumFig,'on');
            xlim(app.RespSpectrumFig, [app.RespMinEditField.Value app.RespMaxEditField.Value]);
            ylim(app.RespSpectrumFig, [yMin2, yMax2+1]);

            % Plot the spectra 1 and 2
            plot(app.HeartSpectrumFig,app.r.frequency,intensity1,'Color',app.blue);
            plot(app.RespSpectrumFig,app.r.frequency,intensity2,'Color',app.blue);

            % Heart and respiration bandwidth values
            app.r.bandwidthHR = app.HeartWidthEditField.Value;
            app.r.bandwidthRR = app.RespWidthEditField.Value;

            % Plot the butter filter for cardiac motion filtering
            flt = app.r.filterShape(app, 'heart');
            area(app.HeartSpectrumFig,flt(:,1),0.95*yMax1*flt(:,2),'FaceColor',app.brightgreen,'FaceAlpha',0.1,'EdgeColor',app.brightgreen,'LineStyle', 'none');
            plot(app.HeartSpectrumFig,[app.r.detectedHR,app.r.detectedHR],[0,yMax1],'Color',app.brightgreen,'LineWidth',1,'LineStyle','--');
            text(app.HeartSpectrumFig,app.r.detectedHR+10,0.9*yMax1,[num2str(app.r.detectedHR),' bpm']);

            % Plot the butter filter for the repiration motion filtering
            try
                flt = app.r.filterShape(app, 'resp');
                area(app.RespSpectrumFig,flt(:,1),0.95*yMax2*flt(:,2),'FaceColor',app.brightgreen,'FaceAlpha',0.1,'EdgeColor',app.brightgreen,'LineStyle', 'none');
                plot(app.RespSpectrumFig,[app.r.detectedRR,app.r.detectedRR],[0,yMax2],'Color',app.brightgreen,'LineWidth',1,'LineStyle','--');
                text(app.RespSpectrumFig,app.r.detectedRR+10,0.9*yMax2,[num2str(app.r.detectedRR),' bpm']);
            catch ME
                app.TextMessage(ME.message);
            end

            hold(app.HeartSpectrumFig,'off');
            hold(app.RespSpectrumFig,'off');
            drawnow;

        end % PlotNavigatorSpectrumFcn


        % Plot the current movie frame
        function PlotMovieFrameFcn(app)

            try

                % Delete color bar
                delete(app.cbar);

                % Clean up previous lines and areas
                if isfield(app, 'navLines') && ~isempty(app.navLines)
                    cellfun(@(h) delete(h), app.navLines(~cellfun(@isempty, app.navLines)))
                end

                if isfield(app, 'navArea') && ~isempty(app.navArea)
                    cellfun(@(h) delete(h), app.navArea(~cellfun(@isempty, app.navArea)))
                end

                if app.MovieButton.Value == 1

                    % MRI movie frame
                    im = squeeze(app.r.movieApp(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                    % Rotate the image (cc and ccw rotation button)
                    for k=1:app.imageRot
                        im = rot90(im);
                    end

                    % Size of the image
                    [xl,yl] = size(im);
                    recoAspectRatio = (1/app.r.aspectratio)*yl/xl;
                    if app.r.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                        recoAspectRatio = app.r.aspectratio*yl/xl;
                    end

                    % Window and Level
                    if app.AutoWLButton.Value
                        im1 = squeeze(app.r.movieApp(:,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                        % [app.window, app.level] = app.r.autoWindowLevel(app, im1);
                        app.level = 0.4*max(im1(:));
                        app.window = 0.8*max(im1(:));
                        app.LevelScaleSlider.Value = double(app.level);
                        app.WindowScaleSlider.Value = double(app.window);
                    end

                    % Scale image to range [0 1] using level and window
                    im = (1/app.window)*(im - app.level + app.window/2);
                    im(im<0) = 0;
                    im(im>1) = 1;

                    % Plot the movie frame
                    app.moviePlot = imshow(rot90(im),[0 1],'Colormap',gray,'Interpolation','bilinear','InitialMagnification','fit','Parent',app.MovieFig);
                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                    % Plot external navigator
                    if app.showExtNavFlag

                        % Calculate the intersection of the navigator plane with the image
                        [lines, area] = app.r.calculatePlaneIntersection(app, xl, yl);
                        centers = app.r.calculatePlaneMidlineProjection(app, xl, yl);

                        if ~isempty(lines)

                            hold(app.MovieFig, "on");

                            % Plot the navigator outlines
                            app.navLines = {};
                            for k = 1:2
                                clipped_segments = lines{k};
                                for v = 1:numel(clipped_segments)
                                    xy = clipped_segments{v};
                                    app.navLines{end+1} = plot(app.MovieFig, xy(:,1), xy(:,2), '-', 'Color', [1, 0.5, 0.0, 0.5], 'LineWidth', 1);
                                end
                            end

                            % Fill the area
                            app.navArea = fill(app.MovieFig, area{1}, area{2}, [1, 0.5, 0], 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                            uistack(app.navArea, 'top');

                            if app.extEchoNavFlag

                                % Keep points within bounds
                                in_bounds = centers(:,1) >= 0 & centers(:,1) <= xl+1 & centers(:,2) >= 0 & centers(:,2) <= yl+1;

                                % Plot visible points
                                scatter(app.MovieFig, centers(in_bounds,1), centers(in_bounds,2), 25, [1, 0.5, 0]);

                                % Plot filled circles at selected nav points only if also in bounds
                                idx1 = app.NavEnd1EditField.Value;
                                if app.ExtECHO1Button.Value && in_bounds(idx1)
                                    scatter(app.MovieFig, centers(idx1,1), centers(idx1,2), 25, [1, 0.5, 0], 'filled');
                                end

                                idx2 = app.NavEnd2EditField.Value;
                                if app.ExtECHO2Button.Value && in_bounds(idx2)
                                    scatter(app.MovieFig, centers(idx2,1), centers(idx2,2), 25, [1, 0.5, 0], 'filled');
                                end

                            end

                            hold(app.MovieFig, "off");

                        end

                    end

                    % Plot orientation labels
                    if app.orientationsFlag
                        app.OrientationLabel1.Text = app.r.orientationLabels(1);
                        app.OrientationLabel2.Text = app.r.orientationLabels(2);
                        app.OrientationLabel3.Text = app.r.orientationLabels(3);
                        app.OrientationLabel4.Text = app.r.orientationLabels(4);
                    else
                        app.OrientationLabel1.Text = '';
                        app.OrientationLabel2.Text = '';
                        app.OrientationLabel3.Text = '';
                        app.OrientationLabel4.Text = '';
                    end

                end

                % Averages
                if app.AveragesButton.Value == 1

                    % Averages scale and color
                    maxScale = round(max(app.r.kSpaceAvg(:)));
                    c = [0 0 0 ; summer(maxScale)];

                    % Averages movie frame
                    im = squeeze(app.r.kSpaceAvg(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                    % Size of the image
                    [xl,yl] = size(im);
                    if app.r.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                    end
                    recoAspectRatio = yl/xl;

                    % Plot the averages movie frame
                    app.moviePlot = imshow(rot90(im),[0,maxScale],'ColorMap',c,'Parent',app.MovieFig);
                    app.cbar = colorbar(app.MovieFig,'location','eastoutside','TickLabelsMode','auto','FontSize',12);
                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                end

                % Raw k-space
                if app.kSpaceButton.Value == 1

                    % K-space movie frame
                    im = squeeze(abs(app.r.kSpace{app.MovieCoilSpinner.Value}(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value)));

                    % Size and orientation of the image
                    [xl,yl] = size(im);
                    if app.r.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                    end
                    recoAspectRatio = yl/xl;

                    % Window and Level
                    im = im/max(im(:));
                    if app.AutoWLButton.Value
                        lvl = 0.4;
                        wdw = 0.8;
                        app.WindowScaleSlider.Value = double(app.r.maxKspaceValue*wdw);
                        app.LevelScaleSlider.Value = double(app.r.maxKspaceValue*lvl);
                    else
                        lvl = app.LevelScaleSlider.Value/app.r.maxKspaceValue;
                        wdw = app.WindowScaleSlider.Value/app.r.maxKspaceValue;
                    end
                    im = (1/lvl)*(im - lvl + wdw/2);
                    im(im<0) = 0;
                    im(im>1) = 1;

                    % Plot the k-space movie frame
                    app.moviePlot = imshow(rot90(im),[0 1],'Colormap',gray,'Parent',app.MovieFig);
                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                end

                % Sense map
                if (app.SenseMapButton.Value == 1) && (app.validSenseMapFlag)

                    % Sense map movie frame
                    im = squeeze(app.r.senseMap(:,:,app.MovieSliceEditField.Value,app.SenseMapSpinner1.Value,app.SenseMapSpinner2.Value));

                    % Rotate the image (cc and ccw rotation button)
                    for k=1:app.imageRot
                        im = rot90(im);
                    end

                    % Size and orientation of the image
                    [xl,yl] = size(im);
                    recoAspectRatio = (1/app.r.aspectratio)*yl/xl;
                    if app.r.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                        recoAspectRatio = app.r.aspectratio*yl/xl;
                    end

                    % Window and Level
                    im = im/max(im(:));
                    if app.AutoWLButton.Value
                        lvl = 0.9;
                        wdw = 0.9;
                        app.WindowScaleSlider.Value = double(app.r.maxImageValue*wdw);
                        app.LevelScaleSlider.Value = double(app.r.maxImageValue*lvl);
                    else
                        lvl = app.LevelScaleSlider.Value/app.r.maxImageValue;
                        wdw = app.WindowScaleSlider.Value/app.r.maxImageValue;
                    end
                    im = (1/lvl)*(im - lvl + wdw/2);
                    im(im<0) = 0;
                    im(im>1) = 1;

                    % Plot the new sense map movie frame
                    app.moviePlot = imshow(rot90(im),[0 1],'Colormap',gray,'Parent',app.MovieFig);
                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                end

                xlim(app.MovieFig, [0 xl+1]);
                ylim(app.MovieFig, [0 yl+1]);

                drawnow;

            catch ME
                app.TextMessage(ME.message,2);
            end

        end % PlotCurrentMovieFrameFcn


        % Fast update the current movie frame
        function UpdateMovieFrameFcn(app)

            % MRI movie
            if app.MovieButton.Value == 1
               
                % MRI movie frame
                im = squeeze(app.r.movieApp(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                % Rotate the image (cc and ccw rotation button)
                for i=1:app.imageRot
                    im = rot90(im);
                end

                % Phase orienation
                if app.r.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end

                % Window and Level
                if app.AutoWLButton.Value
                    im1 = squeeze(app.r.movieApp(:,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                    app.level = 0.4*max(im1(:));
                    app.window = 0.8*max(im1(:));
                    app.LevelScaleSlider.Value = double(app.level);
                    app.WindowScaleSlider.Value = double(app.window);
                end
                im = (1/app.window)*(im - app.level + app.window/2);
                im(im<0) = 0;
                im(im>1) = 1;

                % Plot the new frame
                set(app.moviePlot, 'CData', rot90(im));

            end

            % Averages
            if app.AveragesButton.Value == 1

                % Averages movie frame
                im = squeeze(app.r.kSpaceAvg(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                % Orientation
                if app.r.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end

                % Plot the new movie frame
                set(app.moviePlot, 'CData', rot90(im));

            end

            % Raw k-space
            if app.kSpaceButton.Value == 1

                % K-space movie frame
                im = squeeze(abs(app.r.kSpace{app.MovieCoilSpinner.Value}(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value)));
                
                % Orientation
                if app.r.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end

                % Window and Level
                im = im/max(im(:));
                if app.AutoWLButton.Value
                    lvl = 0.4;
                    wdw = 0.8;
                    app.WindowScaleSlider.Value = double(app.r.maxImageValue*wdw);
                    app.LevelScaleSlider.Value = double(app.r.maxImageValue*lvl);
                else
                    lvl = app.LevelScaleSlider.Value/app.r.maxImageValue;
                    wdw = app.WindowScaleSlider.Value/app.r.maxImageValue;
                end
                im = (1/lvl)*(im - lvl + wdw/2);
                im(im<0) = 0;
                im(im>1) = 1;

                % Plot the new movie frame
                set(app.moviePlot, 'CData', rot90(im));

            end

            % Sense map
            if (app.SenseMapButton.Value == 1) && (app.validSenseMapFlag)

                % Sense map movie frame
                im = squeeze(abs(app.r.senseMap(:,:,app.MovieSliceEditField.Value,app.SenseMapSpinner1.Value,app.SenseMapSpinner2.Value)));
                
                % Rotate the image (cc and ccw rotation button)
                for i=1:app.imageRot
                    im = rot90(im);
                end
                
                % Phase orienation
                if app.r.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end

                % Window and Level
                im = im/max(im(:));
                if app.AutoWLButton.Value
                    lvl = 0.9;
                    wdw = 0.9;
                    app.WindowScaleSlider.Value = double(app.r.maxImageValue*wdw);
                    app.LevelScaleSlider.Value = double(app.r.maxImageValue*lvl);
                else
                    lvl = app.LevelScaleSlider.Value/app.r.maxImageValue;
                    wdw = app.WindowScaleSlider.Value/app.r.maxImageValue;
                end
                im = (1/lvl)*(im - lvl + wdw/2);
                im(im<0) = 0;
                im(im>1) = 1;

                % Plot the new movie frame
                set(app.moviePlot, 'CData', rot90(im));

            end

            drawnow;

        end % UpdateMovieFrameFcn


    end % Methods (Access = private)


    % ----------------------------------------------
    % Public functions, available outside the app
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message, varargin)

            % Write all message to log
            try
                fprintf(app.logFile,"%s \n", message);
            catch
            end

            % Write message to message box
            try

                if ~app.blockMessagesFlag

                    % If no message level input, message level = 1
                    debugLevel = 1;
                    if nargin > 2
                        debugLevel = varargin{1};
                    end

                    % Add new text
                    newText = app.MessageBox.Value;
                    if debugLevel <= app.MessageLevelSpinner.Value
                        newText = [message ; app.MessageBox.Value];
                    end

                    % Limit the history of the messages in the message box to 100 lines
                    if size(newText,1)>100
                        newText = newText(1:100,1);
                    end

                    % Write the message in the app message window
                    app.MessageBox.Value = newText;
                    drawnow;

                end

            catch 
            end

        end % TextMessage

        % Status icon
        function SetStatus(app, status)

            switch status

                case 0 % Status OK
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.Text = 'OK';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';
                    app.TextMessage("Status: OK ...",2);

                case 1 % Status Warning
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.Text = 'Warning';
                    app.StatusLabel.FontColor = app.orange;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.TextMessage("Status: WARNING ...",2);

                case 2 % Status Error
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.Text = 'ERROR';
                    app.StatusLabel.FontColor = app.brightred;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.TextMessage("Status: ERROR ...",2);

                case 3 % Status Busy
                    app.StatusImage.Position = [607,63,33,33];
                    app.StatusImage.ImageSource = 'busy.gif';
                    app.StatusLabel.Text = 'BUSY';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.TextMessage("Status: BUSY ...",2);

                case 4 % Status auto reconstruction
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = 'auto.png';
                    app.StatusLabel.Text = 'AUTO';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.TextMessage("Status: AUTO ...",2);

            end

            drawnow;

        end % SetStatus


        % Plot L curve
        function PlotLcurveFigFcn(app,lambda,x,y)

            % Plot the L-curve and model-error curve
            if length(x)>1

                % Set the plot limits
                try
                    app.LcurveFig.XLim = [min(x) max(x)];
                    app.LcurveFig.YLim = [min(y) max(y)];
                    app.LcurveModelErrorFig.XLim = [app.Lambda1EditField.Value app.Lambda2EditField.Value];
                    app.LcurveModelErrorFig.YLim = [min(x) max(x)];
                catch ME
                    app.TextMessage(ME.message);
                end

                % Log-log plots of L-curve and model-error 
                loglog(app.LcurveFig,x,y,'o','MarkerEdgeColor',[0 .5 .5],'MarkerFaceColor',app.blue,'LineWidth',1.5);
                loglog(app.LcurveModelErrorFig,lambda(1:length(x)),x,'o','MarkerEdgeColor',[0 .5 .5],'MarkerFaceColor',app.blue,'LineWidth',1.5);

            end

        end % PlotLcurveFig


        % Plot trajectory for radial scanning
        function PlotTrajectoryMovieFrameFcn(app,xLines,yLines,zLines,dimx,firstTime)

            try

                switch app.r.dataType

                    case {'2Dradial','2Dradialms'}

                        if firstTime

                            % First time plot, set the plot limits and properties
                            reset(app.TrajectoryLargeFig);
                            app.trajPlot = plot(app.TrajectoryLargeFig,xLines,yLines,'LineWidth',0.001);
                            hold(app.TrajectoryLargeFig,'on');
                            xlim(app.TrajectoryLargeFig,[-0.55*dimx 0.55*dimx]);
                            ylim(app.TrajectoryLargeFig,[-0.55*dimx 0.55*dimx]);
                            set(app.TrajectoryLargeFig,'XTickLabel',[]);
                            set(app.TrajectoryLargeFig,'YTickLabel',[]);
                            set(app.TrajectoryLargeFig,'XTick',[]);
                            set(app.TrajectoryLargeFig,'YTick',[]);
                            app.TrajectoryLargeFig.Interactions = [];
                            app.TrajectoryLargeFig.Toolbar.Visible = 'off';
                            hold(app.TrajectoryLargeFig,'off');

                        else

                            % For all other times, only update the lines
                            for cnt = 1:app.maxNrSpokesPlot
                                app.trajPlot(cnt,1).XData = xLines(:,cnt);
                                app.trajPlot(cnt,1).YData = yLines(:,cnt);
                            end

                        end

                        % With limitrate the plotting is faster
                        drawnow limitrate;

                    case '3Dute'

                        if firstTime

                            % First time plot, set the plot limits and properties
                            app.trajPlot = plot3(app.TrajectoryLargeFig,xLines,yLines,zLines,'LineWidth',0.001);
                            xlim(app.TrajectoryLargeFig,[-0.55*dimx 0.55*dimx]);
                            ylim(app.TrajectoryLargeFig,[-0.55*dimx 0.55*dimx]);
                            zlim(app.TrajectoryLargeFig,[-0.55*dimx 0.55*dimx]);
                            set(app.TrajectoryLargeFig,'XTickLabel',[]);
                            set(app.TrajectoryLargeFig,'YTickLabel',[]);
                            set(app.TrajectoryLargeFig,'ZTickLabel',[]);
                            box(app.TrajectoryLargeFig,'on');
                            app.TrajectoryLargeFig.Interactions = rotateInteraction;
                            app.TrajectoryLargeFig.Toolbar.Visible = 'off';

                        else

                            % For all other times, only update the lines
                            for cnt = 1:app.maxNrSpokesPlot
                                app.trajPlot(cnt,1).XData = xLines(:,cnt);
                                app.trajPlot(cnt,1).YData = yLines(:,cnt);
                                app.trajPlot(cnt,1).ZData = zLines(:,cnt);
                            end

                        end

                        % With limitrate the plotting is faster
                        drawnow limitrate;

                end

            catch ME

                app.TextMessage(ME.message,1);

            end

        end % PlotTrajectoryFcn

    end % Methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)

            % Disable unnecessary warnings
            warning off;
            clc;

            % Open new log file
            try
                logFileName = string(datetime);
                logFileName = strrep(logFileName," ","-");
                logFileName = strrep(logFileName,":","-");
                logFileName = strcat(tempdir,filesep,"retrospective-log-",logFileName,".txt");
                app.logFile = fopen(logFileName,'w');
                app.TextMessage(strcat("log file = ",logFileName," ..."),3);
            catch ME
                app.TextMessage(ME.message);
            end

            % Create empty objects of classes
            app.r = retro(app);
        
            % Empty file import and export paths
            app.mrdImportPath = '';
            app.mrdAutoImportPath = '';
            app.mrdAutoFile = '';
            app.dicomExportPath = '';
            app.gifExportPath = '';

            % Initalize flags
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.validSenseMapFlag = false;
            app.showExtNavFlag = false;

            % Stop all movies
            app.StopAllMoviesFcn;

            % Add subfolders to search path, remove some unwanted folders
            if (~isdeployed)
                app.oldPath = pwd;
                info = dir(which('retrospective10.mlapp'));
                app.appPath = info.folder;
                cd(app.appPath);
                addpath(genpath(strcat(pwd,filesep,'functions')));
                addpath(genpath(strcat(pwd,filesep,'gui')));
            end

            % Load all icons & colors
            app.SetAppLayoutPropertiesFcn;

            % Report on the computer platform
            try
                if ismac()
                    [~,result] = system('uname -v');
                    isAppleSiliconMac = any(strfind(result,'ARM64'));
                    if isAppleSiliconMac
                        app.pcType = "AppleSilicon";
                        app.TextMessage('Apple arm64 platform ...');
                    else
                        app.pcType = "AppleIntel";
                        app.TextMessage('Apple Intel platform ...');
                    end
                elseif ispc()
                    app.pcType = "Windows";
                    app.TextMessage('PC Intel platform ...');
                elseif isunix()
                    app.pcType = "Unix";
                    app.TextMessage('Unix platform ...');
                    app.AImodule = false;
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Determine if BART is available by requesting its version number
            app.bartDetectedFlag = false;
            try
                app.bartVersion = "none";
                bart(app,'version');
                if contains(app.bartVersion,'v')
                    app.bartDetectedFlag = true;
                    app.TextMessage('BART toolbox detected ...');
                end
            catch ME
                app.bartDetectedFlag = false;
                app.TextMessage(ME.message);
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end

            % Check if Bart was compiled with GPU support
            app.GPUpresentFlag = false;
            if app.bartDetectedFlag
                try
                    app.blockMessagesFlag = true;
                    rec = bart(app,'pics -l2 -g ',[0 1 0 ; 1 1 1 ; 0 1 0],[1 1 1 ; 1 1 1 ; 1 1 1]);
                    app.blockMessagesFlag = false;
                    app.TextMessage('BART compiled with GPU support ...');
                    app.GPUpresentFlag = true;
                catch
                    app.blockMessagesFlag = false;
                    app.TextMessage('BART compiled without GPU support ...');
                end
            end
            
            % Check whether python, pip and/or conda modules are available for deep learning reconstruction
            if app.AImodule
                app.r.checkForAIsoftware(app);
            end

            % Determine the current directory in case of deployed app
            % One could put additional input files here
            if isdeployed
                try
                    [~,result] = system('path');
                    app.currentPath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    app.TextMessage(strcat("Path: ",app.currentPath));
                catch
                    app.currentPath = '';
                end
            end

            % Read the graddelays.txt file
            app.r.readGradDelays(app);
            
            % Set the import directories
            app.SetMRDimportPathFcn;

            % Check app version
            app.CheckVersionFnc;

            % Start the parallel pool if it is not running already
            try
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p)
                    app.TextMessage("Starting the parallel pool ...",1);
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Set buttons
            app.LoadMRDButton.Enable = 'on';
            app.SetStatus(0);
            app.TextMessage("Initialization completed ...");
            app.TextMessage("Ready ...");

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                case "classic"
                    guiColors = { ...
                        app.guiGrey, ...                % backgroundColor
                        app.guiBlack, ...               % fontColor
                        app.guiDarkGrey, ...            % boxColor    
                        app.blue, ...                   % gaugeColor
                        app.guiWhite, ...               % gaugeBackgroundColor
                        app.guiWhite, ...               % figureColor    
                        app.guiMiddleGrey, ...          % movieFigureColor
                        app.guiDarkGrey, ...            % gridColor
                        app.lightblue, ...              % autoRecoColor
                        app.guiGrey ...                 % manualRecoColor
                        };
                   
                case "black/white"
                    guiColors = { ...
                        app.guiBlack, ...               % backgroundColor
                        app.guiWhite, ...               % fontColor
                        app.guiDarkGrey, ...            % boxColor
                        app.blue, ...                   % gaugeColor
                        app.guiDarkGrey, ...            % gaugeBackgroundColor
                        app.guiGrey, ...                % figureColor 
                        app.guiDarkGrey, ...            % movieFigureColor
                        app.guiDarkGrey, ...            % gridColor
                        app.guiBlackBlue, ...           % autoRecoColor
                        app.guiBlack ...                % manualRecoColor
                        };

            end

            app.SetAppColorsFcn(guiColors);
            
        end

        % Callback function: DataFile, LoadMRDButton
        function LoadMRDfileButtonPushed(app, event)
     
            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SetLightsFcn(0);
            app.SwitchOffAllButtonsFcn;
            app.Lamp1.Color = app.yellow;
            app.SetStatus(3);
        
            % Reset object of class retro
            clear app.r;
            app.r = retro(app);
            tempPath = app.mrdImportPath;

            % Reset flags & some parameters
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.validSenseMapFlag = false;
            app.fidNavFlag = false;
            app.extFidNavFlag = false;
            app.extEchoNavFlag = false;
            app.orientationsFlag = true;
            app.showExtNavFlag = false;
            app.HeartNavFlipSwitch.Value = 'Up';
            app.RespNavFlipSwitch.Value = 'Up';
            app.ProgressGauge.Value = 0;
            cla(app.TrajectoryLargeFig);
            app.ReconstructionTabGroup.SelectedTab = app.RecoTab;
            app.ParametersTabGroup.SelectedTab = app.SequenceTab;
            app.NavTabGroup.SelectedTab = app.NavTab;
            drawnow;
            
            try

                % Select a DATA file automatically or manually
                if app.AutoRecoCheckBox.Value

                    [filePath,name,ext] = fileparts(app.mrdAutoFile);
                    app.mrdImportPath = filePath;
                    app.mrdFileName = strcat(name,ext);

                else
 
                    f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                    [app.mrdFileName,app.mrdImportPath] = uigetfile(strcat(app.mrdImportPath,filesep,{'*.mrd;*.orig;*.job0'}));
                    delete(f);
                    figure(app.UIFigure);  % Put the app back in the foreground

                end

                % Import the data
                if ischar(app.mrdFileName)

                    % File selected
                    app.mrdFile = [app.mrdImportPath, app.mrdFileName];

                    if strcmp(app.mrdFileName,"fid.orig") || strcmp(app.mrdFileName,"rawdata.job0")

                        % ------------------------------------------------------------------------
                        % B-type scanner data import
                        % ------------------------------------------------------------------------

                        [app.r, data, parameters] = app.r.importB(app);
                        app.DataFile.Value = app.mrdFileName;
                        app.r.rprFlag = false;

                        % Construct the retro parameter class
                        app.r = retro(app, parameters, data);
                      
                        % Check if the data is a valid file with a motion navigator acquisition
                        app.r = app.r.acquisitionType(app);

                    else

                        % ------------------------------------------------------------------------
                        % MR Solutions data import
                        % ------------------------------------------------------------------------

                        mrdStart = extractBefore(app.mrdFileName,'_');
                        fList = dir(fullfile(app.mrdImportPath,[mrdStart,'*.MRD']));

                        % Check wether the filelist contains already reconstructed MRD file(s), remove these from the list
                        if ~isempty(fList)
                            for coil = length(fList):-1:1
                                if contains(fList(coil).name,app.r.ignoreFiles)
                                    fList(coil) = [];
                                end
                            end
                        end

                        if ~isempty(fList)

                            % Filename tag
                            app.DataFile.Value = fList(1).name;
                            [~,app.tag,~] = fileparts([fList(1).folder,filesep,fList(1).name]);

                            % Load the data
                            app.TextMessage('Loading k-space data ...');
                            nrCoils = length(fList);
                            data = cell(nrCoils);
                            for coil = 1:nrCoils
                                app.TextMessage(strcat("Loading coil ",num2str(coil)," ..."));
                                [data{coil},~,parameters,~] = app.r.importMRD(fullfile(fList(coil).folder, fList(coil).name), 'seq', 'seq');
                            end
                            parameters.coil_scaling = ones(nrCoils,1);      % Assume coil scaling is 1 for all coils
                            parameters.nr_coils = nrCoils;                  % Number of RF receiver coil

                            % Construct the retro parameter class
                            app.r = retro(app, parameters, data);

                            % Read the full MRD file footer
                            mrdFooterFileName = fullfile(fList(1).folder, fList(1).name);
                            app.r = app.r.readMrdFooter(app, mrdFooterFileName);

                            % Read the RPR file
                            rprFolder = fList(1).folder;
                            app.r = app.r.readRPRfile(app, rprFolder);

                            % Read the SQL file
                            sqlFilename = [];
                            sqlFilenameList = dir(fullfile([fList(1).folder, filesep, '*.SQL']));
                            if ~isempty(sqlFilenameList)
                                sqlFilename = [sqlFilenameList(1).folder, filesep, sqlFilenameList(1).name];
                            end
                            app.r = app.r.readSQLfile(app, sqlFilename);

                            % Check if the data is a valid file with a motion navigator acquisition
                            app.r = app.r.acquisitionType(app);
                
                        end

                    end

                    % ------------------------------------------------------------------------
                    % Some additional checks on the data
                    % ------------------------------------------------------------------------

                    % Check if there are sufficient number of experiments
                    app.r = app.r.checkNumberOfExperiments(app);
                   
                    % Check for averaging
                    app.r = app.r.checkNumberOfAverages(app);
                   
                    % 3D multi-slab data is not supported
                    app.r = app.r.checkForMultiSlab(app);
                   
                    % Determine whether 3D data has multiple flip-angles
                    app.r = app.r.checkForVFA(app);
                    
                    % Check that TR is not equal to zero
                    app.r = app.r.checkTR(app);

                    % Try to get some parameters from the SQL file if present
                    app.r = app.r.sqlParameters(app);
                    app.r = app.r.imageOrientLabels(app);
                  
                end

            catch ME

                % If something unknown goes wrong during reading of the data,
                % report the error to the app
                app.TextMessage(ME.message);
                app.r.validDataFlag = false;
         
            end

            if app.r.validDataFlag
              
                % Initialize   
                app.DataParametersFcn;                                                      % Set intial data parameters
                app.SetSpeciesFcn;                                                          % Set initial filtering values for mouse/rat
                app.RespToggleCheckBox.Value = 0;                                           % Set reconstruction to expiration
                app.IgnoreRespToggleCheckBox.Value = 0;                                     % Switch ignore respiration off
                app.r = app.r.determineFlashVersion(app);                                   % Determine flash sequence version
                app.SetNavigatorSelectionFcn;
                app.NavButtonGroupSelectionChanged;
                app.r = app.r.autoDiscardNav(app);                                          % Automatically set the number of discarded points
                
                % Reset buttons
                app.SetLightsFcn(1);
                app.NavViewingButtonsFcn(false);
                app.CineRecoButtonsFcn(false);
                app.MovieButtonsFcn(false);
                app.NavSelButtonsFcn(true);
                app.SetStatus(0);
                app.SetDashboardButtonsFcn(131);
             
            else

                % Reset buttons
                app.LoadMRDButton.Enable = 'on';
                app.ExitButton.Enable = 'on';
                app.Lamp1.Color = app.brightred;
                app.NavSelButtonsFcn(false);
                app.NavViewingButtonsFcn(false);
                app.CineRecoButtonsFcn(false);
                app.MovieButtonsFcn(false);
                app.SetStatus(2);

                % Reset some parameters
                app.mrdImportPath = tempPath;
                app.mrdAutoImportPath = tempPath;
                app.SetMRDimportPathFcn;

                % Error message
                app.TextMessage('ERROR: Not a valid data file ...');

            end

        end

        % Value changed function: DiscardEditField, HeartNavFlipSwitch, 
        % ...and 5 other components
        function NavPointEditFieldValueChanged(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Update the used navigator point and the number of navigator points used
            app.NavNRPoints1EditField.Value(app.NavNRPoints1EditField.Value > app.NavEnd1EditField.Value) = app.NavEnd1EditField.Value;
            app.NavNRPoints2EditField.Value(app.NavNRPoints2EditField.Value > app.NavEnd2EditField.Value) = app.NavEnd2EditField.Value;
            
            app.r.primaryNavigatorPoint1 = app.NavEnd1EditField.Value;
            app.r.primaryNavigatorPoint2 = app.NavEnd2EditField.Value;
            app.r.nrNavPointsUsed1 = app.NavNRPoints1EditField.Value;
            app.r.nrNavPointsUsed2 = app.NavNRPoints2EditField.Value;
            app.r.nrNavPointsDiscarded = app.DiscardEditField.Value;

            % Update the data parameters
            app.DataParametersFcn;

            % Switch the data-windows off
            app.IncludeCheckBox.Value = false;
            app.ExcludeCheckBox.Value = false;
            app.PlotDataWindowFcn;

            % Determine the value of the switch to flip the navigator
            app.r = app.r.readFlipSwitch(app);

            % Extract the navigator signals
            app.r = app.r.extractNavigator(app);
           
            % Plot the raw navigator signal
            app.PlotRawNavigatorFcn;

            % Determine and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;

            % Plot or clear the other navigator graphs
            app.PlotHeartNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            cla(app.HeartRateTimeFig);
            cla(app.RespRateTimeFig);

            % Reset buttons
            app.SetLightsFcn(1);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(131);
            app.NavSelButtonsFcn(true);
            app.ProgressGauge.Value = 0;
 
        end

        % Selection changed function: Nav1ButtonGroup, Nav2ButtonGroup
        function NavButtonGroupSelectionChanged(app, event)
         
            % Stop all movies
            app.StopAllMoviesFcn;

            % Reset the navigator points
            switch app.r.dataType

                case {'2D','2Dms'}
               
                    NoNavSamples1 = max([app.ExtFID1Button.Value*app.r.ext_nav_fid_no_samples app.ExtECHO1Button.Value*app.r.ext_nav_echo_no_samples app.SliceFID1Button.Value*app.r.no_samples_nav]);
                    NoNavSamples2 = max([app.ExtFID2Button.Value*app.r.ext_nav_fid_no_samples app.ExtECHO2Button.Value*app.r.ext_nav_echo_no_samples app.SliceFID2Button.Value*app.r.no_samples_nav]);

                    if app.ExtECHO1Button.Value
                        app.r.primaryNavigatorPoint1 = round(NoNavSamples1/2)+1;
                        app.r.nrNavPointsUsed1 = 1;
                    else
                        app.r.primaryNavigatorPoint1 = NoNavSamples1;
                        app.r.nrNavPointsUsed1 = 5;
                    end

                    if app.ExtECHO2Button.Value
                        app.r.primaryNavigatorPoint2 = round(NoNavSamples2/2)+1;
                        app.r.nrNavPointsUsed2 = 1;
                    else
                        app.r.primaryNavigatorPoint2 = NoNavSamples2;
                        app.r.nrNavPointsUsed2 = 5;
                    end

                case {'3D','3Dp2roud','3Dute'}

                    NoNavSamples1 = max([app.ExtFID1Button.Value*app.r.ext_nav_fid_no_samples app.ExtECHO1Button.Value*app.r.ext_nav_echo_no_samples app.SliceFID1Button.Value*app.r.no_samples_nav]);
                    NoNavSamples2 = max([app.ExtFID2Button.Value*app.r.ext_nav_fid_no_samples app.ExtECHO2Button.Value*app.r.ext_nav_echo_no_samples app.SliceFID2Button.Value*app.r.no_samples_nav]);

                    if app.ExtECHO1Button.Value
                        app.r.primaryNavigatorPoint1 = round(NoNavSamples1/2)+1;
                        app.r.nrNavPointsUsed1 = 1;
                    else
                        app.r.primaryNavigatorPoint1 = NoNavSamples1;
                        app.r.nrNavPointsUsed1 = 5;
                    end

                    if app.ExtECHO2Button.Value
                        app.r.primaryNavigatorPoint2 = round(NoNavSamples2/2)+1;
                        app.r.nrNavPointsUsed2 = 1;
                    else
                        app.r.primaryNavigatorPoint2 = NoNavSamples2;
                        app.r.nrNavPointsUsed2 = 5;
                    end

                case {'2Dradial','2Dradialms'}
                 
                    app.r.primaryNavigatorPoint1 = round(app.r.NO_SAMPLES/2)+1;
                    app.r.nrNavPointsUsed1 = 1;
                    app.r.primaryNavigatorPoint2 = round(app.r.NO_SAMPLES/2)+1;
                    app.r.nrNavPointsUsed2 = 1;

            end

            % Show the external navigator outline if one of the ext navs is selected
            if app.ExtECHO1Button.Value || app.ExtECHO2Button.Value || app.ExtFID1Button.Value || app.ExtFID2Button.Value
                app.showExtNavFlag = true;
            end

            % Check that nr of navpoints is not bigger than the available navigator points
            app.r.nrNavPointsUsed1(app.r.nrNavPointsUsed1 > app.r.primaryNavigatorPoint1) = app.r.primaryNavigatorPoint1;
            app.r.nrNavPointsUsed2(app.r.nrNavPointsUsed2 > app.r.primaryNavigatorPoint2) = app.r.primaryNavigatorPoint2;
        
            % Update the data parameters
            app.DataParametersFcn;

            % Switch the data-windows off
            app.IncludeCheckBox.Value = false;
            app.ExcludeCheckBox.Value = false;
            app.PlotDataWindowFcn;

            % Determine the value of the switch to flip the navigator
            app.r = app.r.readFlipSwitch(app);

            % Extract the navigator signals
            app.r = app.r.extractNavigator(app);
           
            % Plot the raw navigator signal
            app.PlotRawNavigatorFcn;

            % Determine and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;

            % Plot or clear the other navigator graphs
            app.PlotHeartNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            cla(app.HeartRateTimeFig);
            cla(app.RespRateTimeFig);

            % Reset buttons
            app.SetLightsFcn(1);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(131);
            app.NavSelButtonsFcn(true);
            app.ProgressGauge.Value = 0;
            
        end

        % Button pushed function: StartMotionButton
        function StartRawNavButtonPushed(app, event)
  
            % Stop all movies
            app.StopAllMoviesFcn;

            app.TextMessage("Starting the raw navigator movie ...");

            % Navigator movie is running
            app.stopRawNavMovieFlag = false;

            try % To catch error during exit, while movie is still running

                % Inital raw navigator values
                frame1 = round(size(app.r.rawNav1{app.HeartCoilSpinner1.Value},1)/2);
                frame2 = round(size(app.r.rawNav2{app.RespCoilSpinner1.Value},1)/2);
                maxFrame1 = size(app.r.rawNav1{app.HeartCoilSpinner1.Value},1);
                maxFrame2 = size(app.r.rawNav2{app.RespCoilSpinner1.Value},1);
                maxPlot3 = max(abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(frame1,:)));
                maxPlot4 = max(abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(frame2,:)));

                tsl = app.traceLength;                                                              % Trace length
                for cnt = 1:tsl
                    alphaValues(cnt) = 1 - (cnt-1)/tsl/1.2;                                         % Trace line transparancy
                    lw(cnt) = 2 - 2*(cnt-1)/tsl;                                                    % Trace line thickness
                end
           
                % Trace starting values and x-coordinates
                app.navTrace3 = repelem(abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(frame1, app.NavEnd1EditField.Value)),tsl);
                app.navTrace4 = repelem(abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(frame2, app.NavEnd2EditField.Value)),tsl);
                navl1 = length(app.r.rawNav1{app.HeartCoilSpinner1.Value}(frame1,:));
                navl2 = length(app.r.rawNav2{app.RespCoilSpinner1.Value}(frame2,:));
                step1 = 0.5*navl1/tsl;
                step2 = 0.5*navl2/tsl;
                x3x = app.NavEnd1EditField.Value:-step1:app.NavEnd1EditField.Value-tsl*step1+step1;
                x4x = app.NavEnd2EditField.Value:-step2:app.NavEnd2EditField.Value-tsl*step2+step1;
                step = 3;
                minTimePerFrame = 0.04;

                % Repeat until navigator movie is stopped
                cnt2 = 0;
                while ~app.stopRawNavMovieFlag

                    tic;

                    % Update the trace counter
                    cnt2 = cnt2 + 1;
                    cnt2(cnt2>tsl) = tsl;

                    % Extract the part of the raw navigator FID or ECHO to be shown
                    trace3 = squeeze(abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(frame1,:)));
                    trace4 = squeeze(abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(frame2,:)));

                    % Used navigator points
                    x3 = app.NavEnd1EditField.Value - app.NavNRPoints1EditField.Value + 1:app.NavEnd1EditField.Value;
                    x4 = app.NavEnd2EditField.Value - app.NavNRPoints2EditField.Value + 1:app.NavEnd2EditField.Value;
              
                    % Update max plots if needed
                    maxPlot3 = 0.999*maxPlot3;
                    maxPlot4 = 0.999*maxPlot4;
                    maxPlot3(maxPlot3 < max(trace3(:))) = max(trace3(:));
                    maxPlot4(maxPlot4 < max(trace4(:))) = max(trace4(:));
                 
                    % Plot the raw navigator 1 FID or ECHO
                    if cnt2 < tsl
                        cla(app.Nav1Fig);
                        hold(app.Nav1Fig,"on");
                        cnt3 = cnt2;
                        cnt3(cnt3>(tsl-step)) = tsl-step;
                        for cnt = 1:step:cnt3
                            pt1(cnt) = plot(app.Nav1Fig, x3x(cnt:cnt+step), app.navTrace3(cnt:cnt+step), 'Color', [app.traceOrange alphaValues(cnt)], 'LineWidth', lw(cnt));
                        end
                        p1a = plot(app.Nav1Fig, trace3, 'o-','LineWidth' ,1 , 'Color', app.blue);
                        p1b = plot(app.Nav1Fig, x3, trace3(x3), 'o', 'Color', app.red, 'MarkerFaceColor', app.green);
                        p1c = plot(app.Nav1Fig, x3(end), trace3(x3(end)), 'o', 'Color', app.red, 'MarkerFaceColor', app.traceOrange);
                        xlim(app.Nav1Fig, [0 length(trace3)+1]);
                        ylim(app.Nav1Fig, [0 1.1*maxPlot3+1]);
                        hold(app.Nav1Fig,"off");
                    else
                        % Once the full trace has been drawn, use faster method
                        set(p1a,'YData',trace3);
                        set(p1b,'XData',x3);
                        set(p1b,'YData',trace3(x3));
                        set(p1c,'XData',x3(end));
                        set(p1c,'YData',trace3(x3(end)));
                        for cnt = 1:step:tsl-step
                            set(pt1(cnt),'XData',x3x(cnt:cnt+step));
                            set(pt1(cnt),'YData',app.navTrace3(cnt:cnt+step));
                        end
                        xlim(app.Nav1Fig, [0 length(trace3)+1]);
                        ylim(app.Nav1Fig, [0 1.1*maxPlot3+1]);
                    end
      
                    % Plot the raw navigator 2 FID or ECHO
                    if cnt2 < tsl
                        cla(app.Nav2Fig);
                        hold(app.Nav2Fig,"on");
                        cnt3 = cnt2;
                        cnt3(cnt3>tsl-step) = tsl-step;
                        for cnt = 1:step:cnt3
                            pt2(cnt) = plot(app.Nav2Fig, x4x(cnt:cnt+step), app.navTrace4(cnt:cnt+step), 'Color', [app.traceOrange alphaValues(cnt)], 'LineWidth', lw(cnt));
                        end
                        p2a = plot(app.Nav2Fig, trace4, 'o-','LineWidth' ,1 , 'Color', app.blue);
                        p2b = plot(app.Nav2Fig, x4, trace4(x4), 'o', 'Color', app.red, 'MarkerFaceColor', app.green);
                        p2c = plot(app.Nav2Fig, x4(end), trace4(x4(end)), 'o', 'Color', app.red, 'MarkerFaceColor', app.traceOrange);
                        xlim(app.Nav2Fig, [0 length(trace4)+1]);
                        ylim(app.Nav2Fig, [0 1.1*maxPlot4+1]);
                        hold(app.Nav2Fig,"off");
                    else
                        % Once the full trace has been drawn, use faster method
                        set(p2a,'YData',trace4);
                        set(p2b,'XData',x4);
                        set(p2b,'YData',trace4(x4));
                        set(p2c,'XData',x4(end));
                        set(p2c,'YData',trace4(x4(end)));
                        for cnt = 1:step:tsl-step
                            set(pt2(cnt),'XData',x4x(cnt:cnt+step));
                            set(pt2(cnt),'YData',app.navTrace4(cnt:cnt+step));
                        end
                        xlim(app.Nav2Fig, [0 length(trace4)+1]);
                        ylim(app.Nav2Fig, [0 1.1*maxPlot4+1]);
                    end
                    drawnow;

                    % Increase the frames
                    frame1 = frame1 + 1;
                    frame1(frame1>maxFrame1) = 1;
                    frame2 = frame2 + 1;
                    frame2(frame2>maxFrame2) = 1;

                    % Update nav trace
                    app.navTrace3 = circshift(app.navTrace3,1);
                    app.navTrace3(1) = abs(app.r.rawNav1{app.HeartCoilSpinner1.Value}(frame1, app.NavEnd1EditField.Value));
                    app.navTrace4 = circshift(app.navTrace4,1);
                    app.navTrace4(1) = abs(app.r.rawNav2{app.RespCoilSpinner1.Value}(frame2, app.NavEnd2EditField.Value));

                    % Slow movie a bit down with small number of frames
                    mvTime = toc;

                    if mvTime < minTimePerFrame
                        pause(minTimePerFrame-mvTime);
                    end

                end

            catch ME

                app.TextMessage(ME.message);

            end

        end

        % Button pushed function: StopMotionButton
        function StopRawNavButtonPushed(app, event)
            
            app.TextMessage("Stopping the raw navigator movie ...");
            app.stopRawNavMovieFlag = true;
            drawnow;

        end

        % Value changed function: HeartCoilSpinner1, HeartCoilSpinner2, 
        % ...and 2 other components
        function NavCoilSpinnerValueChanged(app, event)
            
            % Plot the raw navigator signal
            app.PlotRawNavigatorFcn;
            
        end

        % Value changed function: HeartEditField, HeartWidthEditField, 
        % ...and 3 other components
        function FilterEditFieldValueChanged(app, event)

            % Reset the valid filter flag to false
            app.validFilterFlag = false;

            % Limit the filter width values
            app.HeartWidthEditField.Value(app.HeartWidthEditField.Value > app.HeartEditField.Value) = app.HeartEditField.Value; 
            app.RespWidthEditField.Value(app.RespWidthEditField.Value > app.RespEditField.Value) = app.RespEditField.Value;

            % Get the filter values from the app
            app.r.detectedHR = app.HeartEditField.Value;
            app.r.bandwidthHR = app.HeartWidthEditField.Value;
            app.r.detectedRR = app.RespEditField.Value;
            app.r.bandwidthRR = app.RespWidthEditField.Value;
            app.r.respPercentage = app.RespWindowEditField.Value;

            % Filter the navigator data
            app.FilterButtonPushed;

            % Plot the navigator spectrum
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: ResetFilterValuesButton
        function ResetFilterValuesButtonPushed(app, event)

            % Reset filter values
            app.validFilterFlag = false;
            app.SetSpeciesFcn;
            app.CalculateNavigatorSpectrumFcn;
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;

        end

        % Callback function: HeartRateTimeFig, SpeciesButton
        function SpeciesButtonSelectionChanged(app, event)

            % Execute the reset filter values function
            app.ResetFilterValuesButtonPushed;

        end

        % Button down function: SpeciesButton
        function SpeciesButtonButtonDown(app, event)

            % Execute the reset filter values function
            app.ResetFilterValuesButtonPushed;

        end

        % Value changed function: IgnoreRespToggleCheckBox, 
        % ...and 1 other component
        function RespirationToggleCheckBoxValueChanged(app, event)
        
            % Execute the reset filter values function
            app.ResetFilterValuesButtonPushed;

        end

        % Selection changed function: RecoPresetsButtonGroup
        function RecoPresetsButtonGroupSelectionChanged(app, event)

            % Update the reonstruction parameters
            app.SetRecoParametersFcn;
            app.CinePanelValueChanged;

        end

        % Button down function: RecoPresetsButtonGroup
        function RecoPresetsButtonGroupButtonDown(app, event)

            % Execute the reco preset change function
            app.RecoPresetsButtonGroupSelectionChanged;

        end

        % Value changed function: AutoRecoCheckBox
        function AutoRecoCheckBoxValueChanged(app, event)

            % Color the canvas for visual indication of auto-reconstruction
            if app.AutoRecoCheckBox.Value
                app.StopMovieButtonPushed;
                app.AutoSoundsCheckBox.Value = 0;
                app.SetMRDimportPathFcn;
            else
                app.AutoSoundsCheckBox.Value = 1;
                app.UIFigure.Color = app.manualRecoColor;
                app.SetStatus(0);
            end

            try % Catch error during while loop

                % Wait for new data
                while app.AutoRecoCheckBox.Value

                    % Show autostatus
                    app.TextMessage('Auto reco mode started ...');
                    app.TextMessage(strcat("Watching: ",app.mrdAutoImportPath," ..."));
                    app.UIFigure.Color = app.autoRecoColor;
                    app.SetStatus(4);

                    % Initial mrd files in directory + sub-directories
                    searchFiles = [app.mrdAutoImportPath,filesep,'**',filesep,'*.MRD'];
                    fut = parfeval(@dir, 1, searchFiles);
                    didFinish = wait(fut, 'finished', 5);  % Search directories and sub-directories for max 5 seconds
                    if didFinish
                        fns = fetchOutputs(fut);
                        fOld = {fns(1:end).name};
                    else
                        cancel(fut);
                        fOld = {''};
                    end
                    fIndx = 0;
                    clear('fut');
                    fDiff = [];

                    % Loop and wait for new mrd file
                    while app.AutoRecoCheckBox.Value && isempty(fDiff)

                        fut = parfeval(@dir, 1, searchFiles);
                        didFinish = wait(fut, 'finished', 5);  % Search directories and sub-directories for max 5 seconds
                        if didFinish
                            fns = fetchOutputs(fut);
                            fNew = {fns(1:end).name};
                        else
                            app.TextMessage('WARNING: Searching path takes too long, too many subdirectories ...');
                            cancel(fut);
                            fNew = {''};
                            app.AutoRecoCheckBox.Value = 0;
                            app.AutoSoundsCheckBox.Value = 1;
                            app.UIFigure.Color = app.manualRecoColor;
                            app.SetStatus(1);
                        end
                        clear('fut');

                        [fDiff,fIndx] = setdiff(fNew,fOld);

                        drawnow;

                    end

                    % Continue when new mrd file is detected or autorecocheckbox == false
                    if fIndx>0

                        app.TextMessage('New MRD file found ...');

                        app.mrdAutoFile = fullfile(fns(fIndx).folder,fns(fIndx).name);

                        pause(1);
                        app.LoadMRDfileButtonPushed;                    % Load data

                        if app.r.validDataFlag
                            app.FilterButtonPushed;                     % Filter the navigator data

                            if app.validFilterFlag
                                app.SortKspaceButtonPushed;             % Sort the data into k-space

                                if app.validSortFlag
                                    app.RecoMoviesButtonPushed;         % Reconstruct the images/movie

                                    if app.validRecoFlag
                                        app.ExportMRDButtonPushed;      % Export new MRD file

                                    end
                                end
                            end
                        end

                    end

                end

            catch ME

                app.TextMessage("Error during auto reconstruction ...",2);
                app.TextMessage(ME.message,2);

            end

        end

        % Value changed function: FilterOrderEditField, 
        % ...and 5 other components
        function FilterSettingsChanged(app, event)

            % Limit the values, min cannot be larger than max value
            app.MinRespRateEditField.Value(app.MinRespRateEditField.Value > app.MaxRespRateEditField.Value) = app.MaxRespRateEditField.Value;
            app.MinHeartRateEditField.Value(app.MinHeartRateEditField.Value > app.MaxHeartRateEditField.Value) = app.MaxHeartRateEditField.Value;
       
            % Calculate the new spectrum, filter the navigator, plot the spectrum
            app.CalculateNavigatorSpectrumFcn;
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: HeartLogLinButton
        function HeartLogLinButtonPushed(app, event)

            % Switch between linear or logaritmic scale
            if strcmp(app.HeartLogLinButton.Text,'log')
                app.HeartLogLinButton.Text = 'lin';
            else
                app.HeartLogLinButton.Text = 'log';
            end

            % Replot the spectrum
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: RespLogLinButton
        function RespLogLinButtonPushed(app, event)
            
            % Switch between linear or logaritmic scale
            if strcmp(app.RespLogLinButton.Text,'log')
                app.RespLogLinButton.Text = 'lin';
            else
                app.RespLogLinButton.Text = 'log';
            end

            % Replot the spectrum
            app.PlotNavigatorSpectrumFcn;

        end

        % Value changed function: HeartMaxEditField, HeartMinEditField, 
        % ...and 2 other components
        function SpectrumEditFieldValueChanged(app, event)
            
            % Keep values between limits

            min1 = app.HeartMinEditField.Value;
            max1 = app.HeartMaxEditField.Value;

            min1(min1 > max1-10) = max1 - 10;
            if min1 < 0
                min1 = 0;
                max1 = 10;
            end

            app.HeartMinEditField.Value = min1;
            app.HeartMaxEditField.Value = max1;

            min2 = app.RespMinEditField.Value;
            max2 = app.RespMaxEditField.Value;

            min2(min2 > max2-10) = max2 - 10;
            if min2 < 0
                min2 = 0;
                max2 = 10;
            end

            app.RespMinEditField.Value = min2;
            app.RespMaxEditField.Value = max2;

            % Plot the spectrum with the new axes
            app.PlotNavigatorSpectrumFcn;
            
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SetLightsFcn(1);
            app.Lamp2.Color = app.yellow;
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;

            % Message the app
            app.TextMessage('Filtering the navigator ...');

            % Reset some parameters
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.ProgressGauge.Value = 0;

            try

                % Filter twice
                for cnt = 1:2

                    % Filter settings from app
                    app.r.physioFilterSettings = [app.MinHeartRateEditField.Value ...
                        app.MaxHeartRateEditField.Value app.MinRespRateEditField.Value ...
                        app.MaxRespRateEditField.Value app.FilterOrderEditField.Value app.RespShiftEditField.Value];

                    % Filter for cardiac and respiratory motion
                    app.r = app.r.filterNavPCA(app);
                    app.RespEditField.Value = app.r.detectedRR;
                    app.RespWidthEditField.Value = app.r.bandwidthRR;

                    % Extract cardiac trigger points
                    app.r = app.r.trigPointsHeart(app);

                    % Calculate heart-time curves, trend-lines, and mean heart-rate
                    app.r = app.r.calcHeartRate(app);
                    app.FinalHeartViewField.Value = app.r.meanHeartRate;
                    app.HeartEditField.Value = app.r.meanHeartRate;

                    % Extract respiratory trigger points
                    app.r = app.r.trigPointsResp(app);

                    % Calculate resp-time curves, trend-lines, and mean resp-rate
                    app.r = app.r.calcRespRate(app);
                    app.FinalRespViewField.Value = app.r.meanRespRate;

                end

                % Make the respiration exclusion windows
                app.r = app.r.makeRespWindow(app);
              
                % Let the app know the nav data is filtered
                app.validFilterFlag = true;

                % Plot the datawindow in the time-heartrate and time-respirationrate plots and datawindow
                app.PlotRatesFcn;
                app.PlotRateLimitsFcn;
                app.PlotDataWindowFcn;
          
                % Show the samples-navigator plots
                app.PlotHeartNavigatorFcn;
                app.PlotRespirationNavigatorFcn;

                % Reset buttons
                app.SetLightsFcn(3);
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
                app.SetStatus(0);

            catch 

                % Error message
                app.TextMessage('ERROR: Something went wrong during filtering of the navigator ...')
             
                % Let the app know the nav data is not filtered
                app.validFilterFlag = false;

                % Reset buttons
                app.SetLightsFcn(1);
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
                app.SetStatus(2);
         
            end

        end

        % Value changed function: RangeEditField, StartEditField
        function DisplayEditFieldValuesChanged(app, event)

            % Get the new range values from the app
            value1 = round(app.StartEditField.Value*1000/app.r.TR);
            value2 = round(app.RangeEditField.Value*1000/app.r.TR);

            % Range should be at least 20*TR
            value2(value2<20) = 20;

            % Range should not exceed 2000, else plot gets really slow and cluttered
            value2(value2>2000) = 2000;

            % Start + range should not exceed the number of available k-lines
            if value1 + value2 > app.r.nrKlines
                value1 = app.r.nrKlines - value2;
            end

            % Report the values back to the user-interface
            app.StartEditField.Value = value1*app.r.TR/1000;
            app.RangeEditField.Value = value2*app.r.TR/1000;

            % Adjust the sliders also
            app.StartSlider.Value = app.StartEditField.Value;
            app.RangeSlider.Value = app.RangeEditField.Value;

            % Plot the raw navigator, cardiac filtered, and respiration filtered
            app.PlotRawNavigatorFcn;
            app.PlotHeartNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            app.PlotRateLimitsFcn;

        end

        % Value changed function: RangeSlider, StartSlider
        function DisplaySlidersValueChanged(app, event)

            % Set the field values to match the sliders
            app.StartEditField.Value = app.StartSlider.Value;
            app.RangeEditField.Value = app.RangeSlider.Value;

            % Proceed as if the field values were changed
            app.DisplayEditFieldValuesChanged;

        end

        % Button pushed function: NavStartButton
        function StartNavTimeButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            app.TextMessage('Starting the navigator - time movie ...');

            % Navigator movie is running
            app.stopNavTimeMovieFlag = false;

            try % To catch error during exit, while movie is still running

                % Repeat until navigator movie is stopped
                while ~app.stopNavTimeMovieFlag

                    value1 = round(app.StartEditField.Value*1000/app.r.TR);
                    value2 = round(app.RangeEditField.Value*1000/app.r.TR);

                    % Add app.navMovieSpeed value to display the next frame
                    value1 = value1 + app.navMovieSpeed;

                    % At the end of the data, reset to beginning
                    if value1 + value2 > app.r.nrKlines
                        value1 = 1;
                    end

                    % Show the values in the app
                    app.StartEditField.Value = value1*app.r.TR/1000;
                    app.StartSlider.Value = value1*app.r.TR/1000;

                    % Plot the range of navigators in the 4 plots
                    app.PlotRawNavigatorFcn;
                    app.PlotHeartNavigatorFcn;
                    app.PlotRespirationNavigatorFcn;
                    app.PlotRateLimitsFcn;

                end

            catch
            end

        end

        % Button pushed function: NavStopButton
        function StopNavTimeButtonPushed(app, event)

            app.TextMessage('Stopping the navigator - time movie ...');

            % Set the stop navigator movie flag to true
            app.stopNavTimeMovieFlag = true;

            % Plot the raw navigators, cardiac filtered, and respiration filtered
            app.PlotRawNavigatorFcn;
            app.PlotHeartNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            app.PlotRateLimitsFcn;

        end

        % Value changed function: NavFastSlowSwitch
        function NavFastSlowSwitchValueChanged(app, event)

            % Navigator movie speed is slow or fast
            if app.NavFastSlowSwitch.Value == "slow"
                app.navMovieSpeed = 25;
            else
                app.navMovieSpeed = 100;
            end

        end

        % Value changed function: IncludeCheckBox
        function IncludeCheckBoxValueChanged(app, event)

            % Include window check-box on or off
            if app.IncludeCheckBox.Value == 1

                % Include window is on
                % Execute IncludeSliderValueChanged function
                app.IncludeSliderValueChanged;

            else

                % Reset the window to zeros
                app.r.includeWindow = ones(app.r.nrKlines,1);
                app.PlotDataWindowFcn;
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;

                % Reset buttons
                app.SetLightsFcn(1);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.stopMovieFlag = true;
                app.SetStatus(0);
           
            end

        end

        % Value changed function: IncludeEndSlider, IncludeStartSlider
        function IncludeSliderValueChanged(app, event)

            % Read the sliders
            value1 = app.IncludeStartSlider.Value;      % Start of the include-window in units of seconds
            value2 = app.IncludeEndSlider.Value;        % End of the include-window in units of seconds

            % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
            if (value2 - value1) < round(10 * app.r.nrKsteps * app.r.TR / 1000)
                value2 = value1 + round(10 * app.r.nrKsteps * app.r.TR / 1000);
            end

            % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
            if value2 > app.acqDur
                value2 = app.acqDur;
                value1 = value2 - (10 * app.r.nrKsteps * app.r.TR / 1000);
            end

            % Set the values and sliders
            app.IncludeStartSlider.Value = value1;
            app.IncludeEndSlider.Value = value2;

            % Set the value of the include window
            app.r.includeWindow = zeros(app.r.nrKlines,1);
            value1k = round(1000*value1/app.r.TR)+1;    % Start of the window in nr k-lines
            value2k = round(1000*value2/app.r.TR);      % End of the window in nr k-lines
            value2k(value2k > app.r.nrKlines) = app.r.nrKlines;
            app.r.includeWindow(value1k:value2k) = 1;

            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            app.PlotDataWindowFcn;

            % Calculate and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;

            % Reset buttons
            app.SetLightsFcn(1);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(131);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.stopMovieFlag = true;
         
            % Check whether there is sufficient data left to do a reconstruction
            numberOfKlines = nnz(app.r.includeWindow.*app.r.excludeWindow);
            if numberOfKlines < 512
                app.TextMessage('ERROR: Not enough k-lines left for reconstruction, adjust data windows ...');
                app.FilterButton.Enable = 'off';
                app.SetStatus(2);
            else
                app.SetStatus(0);
            end

        end

        % Value changed function: ExcludeCheckBox
        function ExcludeCheckBoxValueChanged(app, event)

            % Exclude window check-box on or off
            if app.ExcludeCheckBox.Value == 1

                % Exclude window is on
                % Execute ExcludeSliderValueChanged function 
                app.ExcludeSliderValueChanged;

            else

                % Reset the window to zeros
                app.r.excludeWindow = ones(app.r.nrKlines,1);

                % Plot the new windows
                app.PlotDataWindowFcn;
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;

                % Reset buttons
                app.StopAllMoviesFcn;
                app.SetLightsFcn(1);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.SetStatus(0);
            
            end

        end

        % Value changed function: ExcludeEndSlider, ExcludeStartSlider
        function ExcludeSliderValueChanged(app, event)

            % Read the sliders
            value1 = app.ExcludeStartSlider.Value;          % Start of the exclude-window in units of seconds
            value2 = app.ExcludeEndSlider.Value;            % End of the exclude-window in units of seconds

            % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
            if (value2 - value1) < round(10 * app.r.nrKsteps * app.r.TR / 1000)
                value2 = value1 + round(10 * app.r.nrKsteps * app.r.TR / 1000);
            end

            % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
            if value2 > app.acqDur
                value2 = app.acqDur;
                value1 = value2 - (10 * app.r.nrKsteps * app.r.TR / 1000);
            end

            % Set the values and sliders
            app.ExcludeStartSlider.Value = value1;
            app.ExcludeEndSlider.Value = value2;

            % Set the value of the exclude window
            app.r.excludeWindow = ones(app.r.nrKlines,1);
            value1k = round(1000*value1/app.r.TR)+1;        % Start of the window in nr k-lines
            value2k = round(1000*value2/app.r.TR);          % End of the window in nr k-lines
            value2k(value2k > app.r.nrKlines) = app.r.nrKlines;
            app.r.excludeWindow(value1k:value2k) = 0;

            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            app.PlotDataWindowFcn;

            % Calculate and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;

            % Reset buttons
            app.StopAllMoviesFcn;
            app.SetLightsFcn(1);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(131);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
        
            % Check whether there is sufficient data left to do a reconstruction
            numberOfKlines = nnz(app.r.includeWindow.*app.r.excludeWindow);
            if numberOfKlines < 512
                app.TextMessage('ERROR: Not enough k-lines left for reconstruction, adjust data windows ...');
                app.FilterButton.Enable = 'off';
                app.SetStatus(2);
            else
                app.SetStatus(0);
            end

        end

        % Value changed function: DynamicsEditField, FramesEditField, 
        % ...and 2 other components
        function CinePanelValueChanged(app, event)

            % If running, stop any movies
            app.StopMovieButtonPushed;
            app.StopTrajMovieButtonPushed;
            app.StopTrajMovie3DButtonPushed;
            app.validRecoFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.SetDashboardButtonsFcn(135);
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;

            % New number of frames
            app.nrFrames = app.FramesEditField.Value;
            app.nrDynamics = app.DynamicsEditField.Value;

            % No include and exclude windows for multi-dynamic reconstructions
            if app.nrDynamics > 1
                app.TimeDynViewField.Value = string(duration(seconds(app.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));
                app.IncludeCheckBox.Value = 0;
                app.IncludeCheckBoxValueChanged;
                app.ExcludeCheckBox.Value = 0;
                app.ExcludeCheckBoxValueChanged;
                app.FilterButtonPushed;
            else
                app.TimeDynViewField.Value = app.ScanTimeViewField.Value;
            end

            % Plot the dynamic windows in the rate plots
            app.PlotDynamicsWindowsFcn;
        
        end

        % Button pushed function: SortKspaceButton
        function SortKspaceButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SetLightsFcn(3);
            app.Lamp3.Color = app.yellow;
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;

            % Report sorting status to app
            app.TextMessage('Sorting ...');

            % Reset some parameters
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.ProgressGauge.Value = 0;
            app.GradDelayCalibrationCheckBox.Value = 1;

            try
      
                % Extract all the raw k-space imaging data
                app.r = app.r.extractData(app);

                % Sort by cardiac phase/relative binning, cardiac absolute binning, respiratory phase binning, or realtime
                switch app.RecoTypeDropDown.Value

                    case 'cardiac'
                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.r = app.r.assignBinTimes(app);
                        app.r = app.r.assignBinFrames(app);
                     
                    case 'cardiac abs'
                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.r = app.r.assignBinTimesAbs(app);
                        app.r = app.r.assignBinFramesAbs(app);

                    case 'respiratory'
                        app.nrCardFrames = 1;
                        app.nrRespFrames = app.nrFrames;
                        app.r = app.r.assignBinTimes(app);
                        app.r = app.r.assignBinFrames(app);

                    case 'realtime'
                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.nrDynamics = 1;    % Will be calculated later depending on the number of heartbeats
                        app.r = app.r.assignBinTimes(app);
                        app.r = app.r.assignBinFrames(app);

                end

                % Sort k-space
                switch app.r.dataType

                    case {'2D','2Dms'}

                        if strcmp(app.RecoTypeDropDown.Value,'realtime')
                            % Realtime sorting
                            app.r = app.r.fillKspace2Drealtime(app);
                            app.DynamicsEditField.Value = app.nrDynamics;
                            app.TimeDynViewField.Value = string(duration(seconds(app.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));
                        else
                            % 2D and 2D multi-slice sorting
                            app.r = app.r.fillKspace2D(app);
                        end

                    case '3D'

                        % Sorting 3D data
                        app.r = app.r.fillKspace3D(app);

                    case '3Dp2roud'

                        % Sorting 3D proud data
                        app.r = app.r.fillKspace3Dp2roud(app);

                    case {'2Dradial','2Dradialms'}

                        % Sorting 2D radial data
                        if app.UserDefinedRadialButton.Value == 1
                            app.r = app.r.fillKspace2Dradial(app);
                        else
                            app.r = app.r.fillKspace2DradialReg(app);
                        end
                        
                        app.ReconstructionTabGroup.SelectedTab = app.RadialRecoTab;
                     
                    case '3Dute'

                        % Sorting 3D UTE data
                        app.r = app.r.fillKspace3Dute(app);
                        app.ReconstructionTabGroup.SelectedTab = app.RadialRecoTab;
                      
                end

                % Reshape to either cardiac or respiratory CINE
                app.r = app.r.reshapeKspace(app);

                % Normalize k-space
                app.r = app.r.normKspace(app);

                % Let the app know the data is sorted
                app.validSortFlag = true;
       
                % K-space statistics
                app.r = app.r.kSpaceStats(app);

                % Reset buttons
                app.SetLightsFcn(7);
                app.SetDashboardButtonsFcn(143);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
                app.TrajMovieButtonsFcn(true);
                app.SetStatus(0);

            catch ME

                % Let the app know the data is not sorted
                app.validSortFlag = false;

                % Reset buttons
                app.SetLightsFcn(3);
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(false);
                app.MovieButtonsFcn(false);
                app.SetStatus(2);

                % Error message
                app.TextMessage('ERROR: Something went wrong during sorting of the data into k-space ...')
                app.TextMessage(ME.message);
            
            end

        end

        % Value changed function: ESPIRiTCheckBox, LLRxyzEditField, 
        % ...and 4 other components
        function RecoParEditFieldValueChanged(app, event)

            % Stop movie & reset progress bar
            app.StopAllMoviesFcn;
            app.ProgressGauge.Value = 0;

            % Reset buttons
            app.SetLightsFcn(7);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(143);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(false);
         
        end

        % Value changed function: CenterEchoCheckBox, 
        % ...and 2 other components
        function TrajectorySettingsEditFieldValueChanged(app, event)
            
            % Resort the data
            if app.validFilterFlag
                app.SortKspaceButtonPushed;
            end
      
        end

        % Selection changed function: TrajectoryButtonGroup
        function TrajectoryButtonGroupSelectionChanged(app, event)
            
            % Resort the data
            if app.validFilterFlag
                app.SortKspaceButtonPushed;
            end

        end

        % Button pushed function: LcurveStartButton
        function StartLcurveButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.TextMessage('L-curve calculation in progress ...');
            app.SetStatus(3);
            app.stopLcurveFlag = false;
            app.SwitchOffAllButtonsFcn;
            app.LcurveStartButton.Enable = 'off';
            app.LcurveStopButton.Enable = 'on';
            app.MatlabRecoCheckBox.Value = 0;
           
            % L-curve analysis only for simple 2D data
            if app.bartDetectedFlag && ~app.r.multiDynamicFlag && (strcmp(app.r.dataType,'2D')==true || strcmp(app.r.dataType,'2Dms')==true)

                try
                    % Do the L-curve reconstruction
                    app.r = app.r.recoLcurve(app);
                catch ME
                    app.TextMessage('ERROR: Something went wrong during L-curve calculation ...')
                    app.TextMessage(ME.message);
                end

            else

                app.TextMessage('ERROR: L-curve calculations require Bart toolbox and single-dynamic Cartesian 2D data...');
                app.SetStatus(2);

            end

            % Stopped before completion
            if app.stopLcurveFlag
                app.TextMessage('L-curve calculation stopped by user ...');
            end

            % Let the app know there is no valid reco
            app.validRecoFlag = false;

            % Reset buttons
            app.SetLightsFcn(7);
            app.SetDashboardButtonsFcn(143);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(false);
            app.SetStatus(0);

        end

        % Button pushed function: LcurveStopButton
        function StopLcurveButtonPushed(app, event)

            % Stop the L-curve calculations
            app.stopLcurveFlag = true;
            drawnow;

        end

        % Button pushed function: ReconstructButton
        function RecoMoviesButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SetLightsFcn(7);
            app.Lamp4.Color = app.yellow;
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;
            app.ReconstructionTabGroup.SelectedTab = app.RecoTab;

            % Reset some parameters
            app.validRecoFlag = false;
            app.r.multiSliceFlag = false;
            app.r.multiDynamicFlag = false;
            app.validSenseMapFlag = false;
            app.ProgressGauge.Value = 0;
           
            app.sliceNr = 1;
            app.dynamicNr = 1;
            app.MovieSliceEditField.Value = 1;
            app.MovieFrameEditField.Value = 1;
            app.MovieDynEditField.Value = 1;
            app.MovieCheckBox1.Value = 1;
            app.MovieCheckBox2.Value = 0;
            app.MovieCheckBox3.Value = 0;
            app.imageRot = 0;

            % Reconstruction
            try

                switch app.r.dataType

                    case {'2D','2Dms'}

                        % Reconstruction of 2D and 2D multi-slice data
                        if app.CompressedSensingButton.Value
                            app.r = app.r.reco2DCS(app);
                        else
                            app.r = app.r.reco2DAI(app);
                        end

                    case {'3D','3Dp2roud'}

                        % Reconstruction of 3D Cartesian data
                        app.r = app.r.reco3D(app);

                    case {'2Dradial','2Dradialms'}

                        % Reconstruction of 2D radial data
                        app.r = app.r.reco2Dradial(app);

                    case '3Dute'

                        % Reconstruction of 3D UTE data
                        app.r = app.r.reco3Dute(app);

                end

            catch ME

                app.TextMessage(ME.message);
                app.validRecoFlag = false;
                app.validSenseMapFlag = false;

            end

            % Successful reco or not
            if app.validRecoFlag

                % Gibss ringing suppression
                if app.RingRmCheckBox.Value
                    app.r = app.r.unRing(app);
                end

                % Denoising filter
                if app.NLMFCheckBox.Value
                    app.r = app.r.NLMFdenoise(app);
                end

                % Shift image on sub-pixel level for certain data
                try
                    switch app.r.dataType
                        case {'2Dradial','2Dradialms'}
                            app.r = app.r.shiftImages2D(app);
                        case {'3D','3Dp2roud','3Dute'}
                            app.r = app.r.shiftImages3D(app);
                    end
                catch ME
                    app.TextMessage(ME.message);
                end

                % Normalize image intensity somewhat
                app.r = app.r.normImages(app);
                
                % Set multislice or multidynamic flags
                app.r = app.r.determineMultiDimensions(app);

                % Number of slices and starting movie slice
                app.nrSlices = size(app.r.movieApp,4);
                app.MovieSliceEditField.Limits = [1 app.nrSlices];
                app.MovieSliceEditField.Value = round(app.nrSlices/2);
                app.sliceNr = app.MovieSliceEditField.Value;

                % Info on phase orientation 
                app.PhaseOrientationViewField.Value = app.r.PHASE_ORIENTATION;
                if app.r.PHASE_ORIENTATION
                    app.TextMessage('INFO: phase orientation = 1 / horizontal ... ')
                else
                    app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                end
                app.r = app.r.imageOrientLabels(app);
              
                % Reset buttons
                app.SetLightsFcn(15);
                app.SetDashboardButtonsFcn(255);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(true);
                app.SetStatus(0);

                % Start the movie
                app.AutoWLButton.Value = 1;
                app.MovieButton.Value = 1;
                app.MovieButtonGroupSelectionChanged;
                if ~app.AutoRecoCheckBox.Value
                    if app.nrFrames > 1
                        app.StartMovieButtonPushed;
                    end
                else
                    app.PlotMovieFrameFcn;
                end
             
            else

                % Inform the app that the reco was not successful
                app.validRecoFlag = false;
                app.validSenseMapFlag = false;

                % Reset buttons
                app.SetLightsFcn(7);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
            
                % Error message
                app.TextMessage('ERROR: There was a problem with reconstruction ...');
                app.SetStatus(2);
          
            end

        end

        % Button pushed function: MovieStartButton
        function StartMovieButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Buttons
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);
            
            % Movie is running
            app.stopMovieFlag = false;
            app.MovieButtonsFcn(true);
            app.TextMessage('Movie playing ...');

            % Show the movie frame
            app.MovieFig.Position = [154,15,280,280];
            app.MovieButtonGroupSelectionChanged;

            % Set the counters
            counter1 = app.MovieFrameEditField.Value;
            counter2 = app.sliceNr;
            counter3 = app.dynamicNr;

            try % To catch error during exit while movie is still running

                % Play the movie continuously until app.stopMovieFlag==true
                while app.stopMovieFlag == false

                    % Time
                    tic

                    % Calculate movie speed
                    if strcmp(app.RecoTypeDropDown.Value,'realtime')
                        minTimePerFrame = 1/(app.MovieCheckBox1.Value * app.nrFrames + app.MovieCheckBox2.Value*app.nrSlices + app.MovieCheckBox3.Value * app.nrDynamics);
                    else
                        minTimePerFrame = 1/(app.MovieCheckBox1.Value * app.nrFrames);
                        if app.MovieCheckBox1.Value == 0
                            minTimePerFrame = 1/(app.MovieCheckBox2.Value * app.nrSlices + app.MovieCheckBox3.Value * app.nrDynamics);
                        end
                    end

                    % Show the movie frame
                    app.UpdateMovieFrameFcn;

                    chk = false;

                    if app.MovieCheckBox1.Value == 1
                        counter1 = counter1 + 1;
                        chk = true;
                        if counter1 > app.nrFrames
                            counter1 = 1;
                            chk = false;
                        end
                        app.MovieFrameEditField.Value = counter1;
                    end

                    if (app.MovieCheckBox2.Value == 1) && (chk == false)
                        counter2 = counter2 + 1;
                        chk = true;
                        if counter2 > app.r.dimz
                            counter2 = 1;
                            chk = false;
                        end
                        app.MovieSliceEditField.Value = counter2;
                        app.sliceNr = counter2;
                    end

                    if (app.MovieCheckBox3.Value == 1) && (chk == false)
                        counter3 = counter3 + 1;
                        chk = true; %#ok<*NASGU>
                        if counter3 > app.nrDynamics
                            counter3 = 1;
                            chk = false;
                        end
                        app.MovieDynEditField.Value = counter3;
                        app.dynamicNr = counter3;
                    end

                    % Slow movie a bit down with small number of frames
                    mvTime = toc;

                    if mvTime < minTimePerFrame
                        pause(minTimePerFrame-mvTime);
                    end

                end

                app.PlotMovieFrameFcn;

            catch
            end
         
        end

        % Button pushed function: MovieStopButton
        function StopMovieButtonPushed(app, event)

            % Stop the movie
            app.stopMovieFlag = true;
            app.TextMessage('Stopping the movie ...');

            % Reset buttons
            app.MovieButtonsFcn(true);
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: ShowNavButton
        function ShowNavButtonPushed(app, event)

            app.StopMovieButtonPushed;

            if app.extEchoNavFlag || app.extFidNavFlag
                app.showExtNavFlag = ~app.showExtNavFlag;
            else
                app.showExtNavFlag = false;
            end

            if app.stopMovieFlag
                app.PlotMovieFrameFcn;
            end

        end

        % Value changed function: MovieFrameEditField
        function MovieFrameEditFieldValueChanged(app, event)
   
            % Check if input value <= number of cardiac frames
            app.MovieFrameEditField.Value(app.MovieFrameEditField.Value > app.nrFrames) = app.nrFrames;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieSliceEditField
        function MovieSliceEditFieldValueChanged(app, event)

            % Check if input value <= number of slices
            app.MovieSliceEditField.Value(app.MovieSliceEditField.Value > app.nrSlices) = app.nrSlices;

            app.sliceNr = app.MovieSliceEditField.Value;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieDynEditField
        function MovieDynEditFieldValueChanged(app, event)

            % Check if input value <= number of dynamics
            app.MovieDynEditField.Value(app.MovieDynEditField.Value > app.nrDynamics) = app.nrDynamics;
      
            app.dynamicNr = app.MovieDynEditField.Value;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieCheckBox1, MovieCheckBox2, 
        % ...and 1 other component
        function MovieCheckBoxValueChanged(app, event)

            % At least frame movie checkbox values should be active
            if (app.MovieCheckBox1.Value == 0) && (app.MovieCheckBox2.Value == 0) && (app.MovieCheckBox3.Value == 0)
                app.MovieCheckBox1.Value = 1;
            end

        end

        % Value changed function: WindowScaleSlider
        function MovieWindowScaleSliderValueChanged(app, event)

            app.AutoWLButton.Value = 0;
            value = app.WindowScaleSlider.Value;
            app.window = value;
            app.PlotMovieFrameFcn;

        end

        % Value changed function: LevelScaleSlider
        function MovieLevelScaleSliderValueChanged(app, event)

            app.AutoWLButton.Value = 0;
            value = app.LevelScaleSlider.Value;
            app.level = value;
            app.PlotMovieFrameFcn;

        end

        % Value changed function: AutoWLButton
        function AutoWLButtonValueChanged(app, event)
            
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieCoilSpinner, SenseMapSpinner1, 
        % ...and 1 other component
        function MovieCoilSpinnerValueChanged(app, event)

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: MoviecwButton
        function MovieRotateMoviecwButtonPushed(app, event)

            % Rotate movie clock-wise
            app.r.aspectratio = 1/app.r.aspectratio;
            app.imageRot = app.imageRot - 1;
            app.imageRot(app.imageRot==-1) = 3;

            % Adjust orientation labels
            app.r.orientationLabels = circshift(app.r.orientationLabels,-1);

            % Show the movie frame
            app.PlotMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieccwButton
        function MovieRotateMovieccwButtonPushed(app, event)

            % Rotate movie counter-clock-wise
            app.r.aspectratio = 1/app.r.aspectratio;
            app.imageRot = app.imageRot + 1;
            app.imageRot(app.imageRot==4) = 0;

            % Adjust orientation labels
            app.r.orientationLabels = circshift(app.r.orientationLabels,1);

            % Show the movie frame
            app.PlotMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieFrameIncrButton
        function MovieFrameIncrButtonPushed(app, event)

            % Shift movie +1 frame along the time/frame axis

            switch app.r.dataType

                case {'2D','3D','2Dradial','3Dp2roud','3Dute'}

                    app.r.movieExp = circshift(app.r.movieExp,-1,1);
                    app.r.movieApp = circshift(app.r.movieApp,-1,1);
                    for coil = 1:app.r.nr_coils
                        app.r.kSpace{coil} = circshift(app.r.kSpace{coil},-1,1);
                    end
                    app.r.kSpaceAvg = circshift(app.r.kSpaceAvg,-1,1);

                case {'2Dms','2Dradialms'}

                    navigatorPerSlice = (app.SliceFID1Button.Value && contains(app.RecoTypeDropDown.Value,'cardiac')) || ...
                        (app.SliceFID2Button.Value && contains(app.RecoTypeDropDown.Value,'respiratory'));

                    if navigatorPerSlice

                        % Separate navigator per slice

                        % Shift for the reconstructed movie slice
                        shiftedMovieSlice = circshift(app.r.movieExp(:,:,:,app.sliceNr),-1,1);
                        app.r.movieExp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                        % Shift for the movie slice
                        shiftedMovieSlice = circshift(app.r.movieApp(:,:,:,app.sliceNr),-1,1);
                        app.r.movieApp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                        % Shift k-space movie for all coils
                        for coil = 1:app.r.nr_coils
                            shiftedMovieSlice = circshift(app.r.kSpace{coil}(:,:,:,app.sliceNr),-1,1);
                            app.r.kSpace{coil}(:,:,:,app.sliceNr) = shiftedMovieSlice;
                        end

                        % Shift the averages movie
                        shiftedMovieSlice = circshift(app.r.kSpaceAvg(:,:,:,app.sliceNr),-1,1);
                        app.r.kSpaceAvg(:,:,:,app.sliceNr) = shiftedMovieSlice;

                    else

                        % One cardiac navigator for all slices

                        app.r.movieExp = circshift(app.r.movieExp,-1,1);
                        app.r.movieApp = circshift(app.r.movieApp,-1,1);
                        for coil = 1:app.r.nr_coils
                            app.r.kSpace{coil} = circshift(app.r.kSpace{coil},-1,1);
                        end
                        app.r.kSpaceAvg = circshift(app.r.kSpaceAvg,-1,1);

                    end

            end

            % Show the movie frame
            app.PlotMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieFrameDecrButton
        function MovieFrameDecrButtonPushed(app, event)

            % Shift movie -1 frame along the time/frame axis

            switch app.r.dataType

                case {'2D','3D','2Dradial','3Dp2roud','3Dute'}

                    app.r.movieExp = circshift(app.r.movieExp,1,1);
                    app.r.movieApp = circshift(app.r.movieApp,1,1);
                    for coil = 1:app.r.nr_coils
                        app.r.kSpace{coil} = circshift(app.r.kSpace{coil},1,1);
                    end
                    app.r.kSpaceAvg = circshift(app.r.kSpaceAvg,1,1);

                case {'2Dms','2Dradialms'}

                    navigatorPerSlice = (app.SliceFID1Button.Value && contains(app.RecoTypeDropDown.Value,'cardiac')) || ...
                        (app.SliceFID2Button.Value && contains(app.RecoTypeDropDown.Value,'respiratory'));

                    if navigatorPerSlice

                        % Separate navigator per slice

                        % Shift for the reconstructed movie slice
                        shiftedMovieSlice = circshift(app.r.movieExp(:,:,:,app.sliceNr),1,1);
                        app.r.movieExp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                        % Shift for the movie slice
                        shiftedMovieSlice = circshift(app.r.movieApp(:,:,:,app.sliceNr),1,1);
                        app.r.movieApp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                        % Shift k-space movie for all coils
                        for coil = 1:app.r.nr_coils
                            shiftedMovieSlice = circshift(app.r.kSpace{coil}(:,:,:,app.sliceNr),1,1);
                            app.r.kSpace{coil}(:,:,:,app.sliceNr) = shiftedMovieSlice;
                        end

                        % Shift the averages movie
                        shiftedMovieSlice = circshift(app.r.kSpaceAvg(:,:,:,app.sliceNr),1,1);
                        app.r.kSpaceAvg(:,:,:,app.sliceNr) = shiftedMovieSlice;

                    else

                        % One cardiac navigator for all slices

                        app.r.movieExp = circshift(app.r.movieExp,1,1);
                        app.r.movieApp = circshift(app.r.movieApp,1,1);
                        for coil = 1:app.r.nr_coils
                            app.r.kSpace{coil} = circshift(app.r.kSpace{coil},1,1);
                        end
                        app.r.kSpaceAvg = circshift(app.r.kSpaceAvg,1,1);

                    end

            end

            % Show the movie frame
            app.PlotMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieSliceIncrButton
        function MovieSliceIncrButtonPushed(app, event)

            % Increment the slice number by 1
            app.sliceNr = app.sliceNr + 1;

            if app.kSpaceButton.Value == 1 || app.AveragesButton.Value == 1
                app.sliceNr(app.sliceNr > size(app.r.data{1},2)) = 1;
            else
                app.sliceNr(app.sliceNr > app.nrSlices) = 1;
            end

            app.MovieSliceEditField.Value = app.sliceNr;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: MovieSliceDecrButton
        function MovieSliceDecrButtonPushed(app, event)

            % Decrease the slice number by 1
            app.sliceNr = app.sliceNr - 1;

            if app.kSpaceButton.Value == 1 || app.AveragesButton.Value == 1
                app.sliceNr(app.sliceNr < 1) = size(app.r.data{1},2);
            else
                app.sliceNr(app.sliceNr < 1) = app.nrSlices;
            end

            app.MovieSliceEditField.Value = app.sliceNr;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: MovieDynIncrButton
        function MovieDynIncrButtonPushed(app, event)
   
            % Increment the dynamic number by 1
            app.dynamicNr = app.dynamicNr + 1;
            
            app.dynamicNr(app.dynamicNr > app.nrDynamics) = 1;

            app.MovieDynEditField.Value = app.dynamicNr;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: MovieDynDecrButton
        function MovieDynDecrButtonPushed(app, event)

            % Decrease the dynamic number by 1
            app.dynamicNr = app.dynamicNr - 1;

            app.dynamicNr(app.dynamicNr < 1) = app.nrDynamics;

            app.MovieDynEditField.Value = app.dynamicNr;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Selection changed function: ButtonGroup
        function MovieButtonGroupSelectionChanged(app, event)

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: TrajMovieStartButton
        function StartTrajMovieButtonPushed(app, event)
            
            % Stop all movies
            app.StopAllMoviesFcn;

            % Movie is running
            app.stopRadialMovieFlag = false;
            app.StopMovieButtonPushed;
            app.StopTrajMovie3DButtonPushed;
            app.TextMessage('Radial trajectory movie playing ...');

            % Buttons
            app.MovieButtonsFcn(true);
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);
           
            % Determine limits of the dimensions
            dimF = size(app.r.kSpaceTraj,1);
            dimZ = size(app.r.kSpaceTraj,4);
            dimD = size(app.r.kSpaceTraj,5);

            % Prefill the trajectory array to increase display speed
            app.trajPlotData = {};
            totalNrSpokes = 0;
            shownNrSpokes = 0;

            for cnt1 = 1:dimF
                for cnt2 = 1:dimZ
                    for cnt3 = 1:dimD

                        % The spokes from each dimension
                        xCrds = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,1))';
                        yCrds = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,2))';
                        zCrds = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,3))';
                       
                        % Remove all zero entries
                        zeroElements = ~((xCrds(:,end)==0) & (yCrds(:,end)==0) & (zCrds(:,end)==0));
                        xCrds = xCrds(zeroElements,:);
                        yCrds = yCrds(zeroElements,:);
                        zCrds = zCrds(zeroElements,:);
                        totalNrSpokes = totalNrSpokes + size(xCrds,1);

                        % Restrict number of visible spokes to maxNrSpokesPlot per frame
                        trajSize = size(xCrds,1);
                        skip = ceil(trajSize/app.maxNrSpokesPlot);
                        xCrds = xCrds(1:skip:end,[1 end])';
                        yCrds = yCrds(1:skip:end,[1 end])';
                        zCrds = zCrds(1:skip:end,[1 end])';
                        shownNrSpokes = shownNrSpokes + size(xCrds,2);

                        % Make sure that all dimensions are equal
                        xCrdsSl = zeros(2,app.maxNrSpokesPlot);
                        yCrdsSl = zeros(2,app.maxNrSpokesPlot);
                        zCrdsSl = zeros(2,app.maxNrSpokesPlot);
                        xCrdsSl(:,1:size(xCrds,2)) = xCrds(:,:);
                        yCrdsSl(:,1:size(yCrds,2)) = yCrds(:,:);
                        zCrdsSl(:,1:size(zCrds,2)) = zCrds(:,:);

                        % Fill the trajectory plot array
                        app.trajPlotData{cnt1,cnt2,cnt3,1} = xCrdsSl;
                        app.trajPlotData{cnt1,cnt2,cnt3,2} = yCrdsSl;
                        app.trajPlotData{cnt1,cnt2,cnt3,3} = zCrdsSl;

                    end
                end
            end

            % Report number of spokes visualized in the movie
            app.TextMessage(strcat("Total number of spokes = ",num2str(totalNrSpokes)," ..."));
            app.TextMessage(strcat("Numnber of spokes visualized = ",num2str(shownNrSpokes), " ..."));

            % Set the counter
            cnt1 = app.TrajMovieFrameEditField.Value;
            cnt2 = app.TrajMovieSliceEditField.Value;
            cnt3 = app.TrajMovieDynEditField.Value;

            % Reset to 1 if counters exceed max values
            cnt1(cnt1>dimF) = 1;
            cnt2(cnt2>dimZ) = 1;
            cnt3(cnt3>dimD) = 1;

            % Set limits in app
            nFramesLimits = dimF;     
            nFramesLimits(nFramesLimits == 1) = 2;
            nSlicesLimits = dimZ;     
            nSlicesLimits(nSlicesLimits == 1) = 2;
            nDynamicsLimits = dimD; 
            nDynamicsLimits(nDynamicsLimits == 1) = 2;
            app.TrajMovieFrameEditField.Limits = [1 nFramesLimits];
            app.TrajMovieSliceEditField.Limits = [1 nSlicesLimits];
            app.TrajMovieDynEditField.Limits = [1 nDynamicsLimits];

            % Image dimension
            dimX = 2*max(app.r.kSpaceTraj(:));

            % Plot the first movie frame
            app.PlotTrajectoryMovieFrameFcn(app.trajPlotData{cnt1,cnt2,cnt3,1},app.trajPlotData{cnt1,cnt2,cnt3,2},app.trajPlotData{cnt1,cnt2,cnt3,3},dimX,true);
       
            try % To catch error during exit while movie is running

                % Play the movie continuously until app.stopMovieRadialFlag==true
                while app.stopRadialMovieFlag == false

                    % Time
                    tic

                    % Calculate movie speed
                    if strcmp(app.RecoTypeDropDown.Value,'realtime')
                        minTimePerFrame = 1/(app.TrajMovieCheckBox1.Value * dimF + app.TrajMovieCheckBox2.Value*dimZ + app.TrajMovieCheckBox3.Value * dimD);
                    else
                        minTimePerFrame = 1/(app.TrajMovieCheckBox1.Value * dimF);
                        if app.TrajMovieCheckBox1.Value == 0
                            minTimePerFrame = 1/(app.TrajMovieCheckBox2.Value * dimZ + app.TrajMovieCheckBox3.Value * dimD);
                        end
                    end

                    chk = false;

                    if app.TrajMovieCheckBox1.Value == 1
                        cnt1 = cnt1 + 1;
                        chk = true;
                        if cnt1 > dimF
                            cnt1 = 1;
                            chk = false;
                        end
                        app.TrajMovieFrameEditField.Value = cnt1;
                    end

                    if (app.TrajMovieCheckBox2.Value == 1) && (chk == false)
                        cnt2 = cnt2 + 1;
                        chk = true;
                        if cnt2 > dimZ
                            cnt2 = 1;
                            chk = false;
                        end
                        app.TrajMovieSliceEditField.Value = cnt2;
                    end

                    if (app.TrajMovieCheckBox3.Value == 1) && (chk == false)
                        cnt3 = cnt3 + 1;
                        chk = true; %#ok<*NASGU>
                        if cnt3 > dimD
                            cnt3 = 1;
                            chk = false;
                        end
                        app.TrajMovieDynEditField.Value = cnt3;
                    end

                    % Plot the new movie frame
                    app.PlotTrajectoryMovieFrameFcn(app.trajPlotData{cnt1,cnt2,cnt3,1},app.trajPlotData{cnt1,cnt2,cnt3,2},app.trajPlotData{cnt1,cnt2,cnt3,3},dimX,false);

                    % Slow movie a bit down with small number of frames
                    mvTime = toc;

                    if mvTime < minTimePerFrame
                        pause(minTimePerFrame-mvTime);
                    end

                end

            catch
            end

        end

        % Button pushed function: TrajMovieStopButton
        function StopTrajMovieButtonPushed(app, event)
            
            % Stop the radial trajectory movie
            app.stopRadialMovieFlag = true;
       
            % Reset buttons
            app.TrajMovieButtonsFcn(true);
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Value changed function: TrajMovieDynEditField, 
        % ...and 2 other components
        function TrajMovieDimEditFieldValueChanged(app, event)
            
            if app.validSortFlag

                switch app.r.dataType

                    case {'2Dradial','2Dradialms','3Dute'}

                        % Image dimension
                        dimx = 2*max(app.r.kSpaceTraj(:));

                        % Set the counter
                        cnt1 = app.TrajMovieFrameEditField.Value;
                        cnt2 = app.TrajMovieSliceEditField.Value;
                        cnt3 = app.TrajMovieDynEditField.Value;

                        % Check limits of the dimensions
                        dimF = size(app.r.kSpaceTraj,1);
                        dimZ = size(app.r.kSpaceTraj,4);
                        dimD = size(app.r.kSpaceTraj,5);

                        cnt1(cnt1>dimF) = 1;
                        cnt2(cnt2>dimZ) = 1;
                        cnt3(cnt3>dimD) = 1;

                        app.TrajMovieFrameEditField.Value = cnt1;
                        app.TrajMovieSliceEditField.Value = cnt2;
                        app.TrajMovieDynEditField.Value = cnt3;

                        % Data
                        xc = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,1))';
                        yc = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,2))';
                        zc = squeeze(app.r.kSpaceTraj(cnt1,:,:,cnt2,cnt3,3))';
                       
                        % Remove all zero entries
                        ze = ~((xc(:,end)==0) & (yc(:,end)==0) & (zc(:,end)==0));
                        xc = xc(ze,[1 end])';
                        yc = yc(ze,[1 end])';
                        zc = zc(ze,[1 end])';

                        % Message
                        app.TextMessage('Displaying all spokes ...');

                        % Plot the new movie frame
                        app.PlotTrajectoryMovieFrameFcn(xc,yc,zc,dimx,true);

                end

            end

        end

        % Value changed function: TrajMovieCheckBox1, TrajMovieCheckBox2, 
        % ...and 1 other component
        function TrajMovieCheckBoxValueChanged(app, event)

            % At least first dimension of the trajectory movie should be active
            if (app.TrajMovieCheckBox1.Value == 0) && (app.TrajMovieCheckBox2.Value == 0) && (app.TrajMovieCheckBox3.Value == 0)
                app.TrajMovieCheckBox1.Value = 1;
            end

        end

        % Button pushed function: TrajMovie3DStartButton
        function StartTrajMovie3DButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Stop movie is false
            app.stop3DMovieFlag = false;

            % Stop other movies
            app.StopTrajMovieButtonPushed;
            app.StopMovieButtonPushed;

            % Buttons
            app.MovieButtonsFcn(true);
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);
        
            % Initialize
            numberOfPoints = 10;
            pointList = zeros(numberOfPoints,3);
            trajectoryPoints = app.r.trajectory'/max(app.r.trajectory(:));
            maxLength = size(trajectoryPoints,1);
            minTimePerFrame = 0.005;
         
            % Initialize figure
            cla(app.TrajectoryLargeFig);
            plot3(app.TrajectoryLargeFig,[],[],[]);
            view(app.TrajectoryLargeFig,[-37.5 30]);
            xlim(app.TrajectoryLargeFig,[-1.1 1.1]);
            ylim(app.TrajectoryLargeFig,[-1.1 1.1]);
            zlim(app.TrajectoryLargeFig,[-1.1 1.1]);
            set(app.TrajectoryLargeFig,'XTickLabel',[]);
            set(app.TrajectoryLargeFig,'YTickLabel',[]);
            set(app.TrajectoryLargeFig,'ZTickLabel',[]);
            box(app.TrajectoryLargeFig,'on');
            app.TrajectoryLargeFig.Interactions = rotateInteraction;
            app.TrajectoryLargeFig.Toolbar.Visible = 'off';

            % Create the plot elements
            hold(app.TrajectoryLargeFig,'on');
            traj3DPlot1a = plot3(app.TrajectoryLargeFig,pointList(1:5,1),pointList(1:5,2),pointList(1:5,3),'LineWidth',2,'Color',app.blue);
            traj3DPlot1b = plot3(app.TrajectoryLargeFig,pointList(5:8,1),pointList(5:8,2),pointList(5:8,3),'LineWidth',1,'Color',app.blue);
            traj3DPlot1c = plot3(app.TrajectoryLargeFig,pointList(8:10,1),pointList(8:10,2),pointList(8:10,3),'LineWidth',0.5,'Color',app.blue);
            traj3DPlot2 = plot3(app.TrajectoryLargeFig,[0 pointList(1,1)],[0 pointList(1,2)],[0 pointList(1,3)],'LineWidth',3,'Color',app.red);
            traj3DPlot3 = plot3(app.TrajectoryLargeFig,[-1.1 1.1],[0 0],[0 0],':','LineWidth',1,'Color',app.green);
            traj3DPlot4 = plot3(app.TrajectoryLargeFig,[0 0],[-1.1 1.1],[0 0],':','LineWidth',1,'Color',app.green);
            traj3DPlot5 = plot3(app.TrajectoryLargeFig,[0 0],[0 0],[-1.1 1.1],':','LineWidth',1,'Color',app.green);
            hold(app.TrajectoryLargeFig,'off');
            
            app.traj3Dcnt = app.SpokeEditField.Value;
            app.traj3Dcnt(app.traj3Dcnt > maxLength) = 1;

            try % To catch error during exit, while movie is still running

                while ~app.stop3DMovieFlag

                    % Timing
                    tic;

                    % FIFO
                    pointList = circshift(pointList,1);
                    pointList(1,:) = trajectoryPoints(app.traj3Dcnt,:);

                    % Update the animated plot elements
                    set(traj3DPlot1a ,'XData',pointList(1:5,1));
                    set(traj3DPlot1a ,'YData',pointList(1:5,2));
                    set(traj3DPlot1a ,'ZData',pointList(1:5,3));

                    set(traj3DPlot1b ,'XData',pointList(5:8,1));
                    set(traj3DPlot1b ,'YData',pointList(5:8,2));
                    set(traj3DPlot1b ,'ZData',pointList(5:8,3));

                    set(traj3DPlot1c ,'XData',pointList(8:10,1));
                    set(traj3DPlot1c ,'YData',pointList(8:10,2));
                    set(traj3DPlot1c ,'ZData',pointList(8:10,3));

                    set(traj3DPlot2 ,'XData',[0 pointList(1,1)]);
                    set(traj3DPlot2 ,'YData',[0 pointList(1,2)]);
                    set(traj3DPlot2 ,'ZData',[0 pointList(1,3)]);

                    app.traj3Dcnt = app.traj3Dcnt + 1;
                    app.traj3Dcnt(app.traj3Dcnt > maxLength) = 1;
                    app.SpokeEditField.Value = app.traj3Dcnt;

                    drawnow;
                    
                    % Limit speed
                    mvTime = toc;
                    if mvTime < minTimePerFrame
                        pause(minTimePerFrame-mvTime);
                    end

                end

            catch
            end
            
        end

        % Button pushed function: TrajMovie3DStopButton
        function StopTrajMovie3DButtonPushed(app, event)
            
            app.stop3DMovieFlag = true;
        
            % Reset buttons
            app.Traj3DMovieButtonsFcn(true);
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp5.Color = app.yellow;
            app.SetStatus(3);

            try

                % Search for dicom file
                app.TextMessage('DICOM export ...');

                % Assuming that MRD and Data directory share the same base-directory
                % Example: base / MRD / 343 / 8455 /

                % Base / data / 343 / DICOM / 8455 /
                dcmSpath{1} = replace(app.mrdImportPath,"MRD","Data");
                try
                    k = strfind(dcmSpath{1},"Data");
                    fs = strfind(dcmSpath{1}(k:end),filesep);
                    dcmSpath{1}= dcmSpath{1}(1:k+fs(3)-1);
                    dcmSpath{1} = insertAfter(dcmSpath{1},k+fs(2)-1,strcat("DICOM",filesep));
                    dcmIndx{1} = 2;
                catch
                    dcmSpath{1} = app.mrdImportPath;
                end

                % Base / data / 343 / DICOM / 8455 / X
                cnt = 1;
                for fn = 2:10
                    dcmSpath{fn} = strcat(dcmSpath{1},num2str(cnt),filesep); %#ok<*AGROW>
                    dcmIndx{fn} = 3; 
                    cnt = cnt + 1;
                end

                % B-type Base / pdata / X / dicom
                if app.r.scanner == "B-type"
                    
                    app.TextMessage('Searching B-type scanner dicom images ...',3);

                    cnt = 1;
                    for fn = 11:20
                        dcmSpath{fn} = strcat(app.mrdImportPath,filesep,"pdata",filesep,num2str(cnt),filesep,"dicom",filesep);
                        dcmIndx{fn} = 1; 
                    end

                    try
                        myPath  = app.mrdImportPath;
                        idcs   = strfind(myPath,filesep);
                        newdPath = myPath(1:idcs(end-2)-1);
                        newdPath = strcat(newdPath,filesep,"DICOM",filesep);
                        d = dir(newdPath);
                        isub = [d(:).isdir]; %# returns logical vector
                        nameFolds = {d(isub).name}';
                        nameFolds(ismember(nameFolds,{'.','..'})) = [];
                        dcmF = strcat(newdPath,filesep,nameFolds(1),filesep);

                        app.TextMessage(strcat("Searching B-type scanner dicom folder = ",dcmF),3);

                        dinfo = dir(dcmF);
                        full_filenames = fullfile({dinfo.folder}, {dinfo.name});
                        [~,stats] = cellfun(@fileattrib, full_filenames);
                        is_unwanted = [stats.hidden]==1 | [stats.system]==1;
                        dinfo(is_unwanted) = [];
                        full_filenames(is_unwanted) = [];
                        dinfo([dinfo.isdir]) = [];   % exclude directories
                        dinfo( startsWith({dinfo.name}, '.') ) = [];  % exclude hidden files, which is same as . files on MacOS
                        dinfo( contains({dinfo.folder}, {'.Spotlight-V100', '.Trashes','System Volume Information'}) ) = []; %exclude those particular directories

                        for k = 1:numel(dinfo)
                            old = fullfile(dinfo(k).folder,dinfo(k).name);
                            if ~contains(old,".dcm")
                                app.TextMessage(strcat("Adding dcm extension to file = ",old),3);
                                new = sprintf('%s.dcm',old);
                                movefile(old,new);
                            end
                        end

                        dcmSpath{21} = dcmF;
                        dcmIndx{21} = 2;

                    catch
                    end

                end

                % Check if one of the directories contains dicom files
                dcmExist = false;
                for fn = 1:length(dcmSpath)
                    if ~isempty(dir(strcat(dcmSpath{fn},"*.dcm")))
                        dcmPath = dcmSpath{fn};
                        dcmPath = strrep(dcmPath,[filesep,filesep],filesep);
                        dcmPath = convertStringsToChars(dcmPath);
                        app.TextMessage(strcat("Found DICOM information in ",dcmPath));
                        filesepIdx = strfind(dcmPath, filesep);
                        app.dicomExportPath = dcmPath(1:filesepIdx(end-dcmIndx{fn}));
                        dcmExist = true;
                        break;
                    end
                end

                % Export to DICOM
                if dcmExist

                    % Export, reading dicom information from dcmPath
                    try
                        % Check if directory is actually writable
                        mkdir(strcat(app.dicomExportPath,filesep,"testdir"));
                        rmdir(strcat(app.dicomExportPath,filesep,"testdir"));
                    catch
                        f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                        app.dicomExportPath = uigetdir(app.dicomExportPath,"DICOM export folder");
                        delete(f);
                        figure(app.UIFigure);  % Put the app back in the foreground
                        drawnow;
                        if ~ischar(app.dicomExportPath)
                            ME = MException('Retrospective:userCancel','User cancel ...');
                            throw(ME);
                        end
                    end
                    app.r.exportPath = retroExportDicomDcm(app,dcmPath);
                    app.r.exportRecoParametersFcn(app);

                else

                    % Export without image orientation information
                    f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                    app.dicomExportPath = uigetdir(app.dicomExportPath,"DICOM export folder");
                    delete(f);
                    figure(app.UIFigure);  % Put the app back in the foreground
                    drawnow;
                    if ~ischar(app.dicomExportPath)
                        ME = MException('Retrospective:userCancel','User cancel ...');
                        throw(ME);
                    end
                    app.TextMessage('WARNING: DICOM image orientation information not available ...');
                    app.SetStatus(1);
                    app.r.exportPath = retroExportDicomMat(app);
                    app.r.exportRecoParametersFcn(app);

                end

                % Export successful
                app.Lamp5.Color = app.brightgreen;
                app.SetStatus(0);

                % Try additional nifti export
                try
                    retroExportNiftiFcn(app);
                catch ME
                    app.TextMessage(ME.message);
                    app.TextMessage("ERROR: NIfti export failed ...");
                end

            catch ME

                % Export not successful
                app.TextMessage('ERROR: DICOM export failed ...');
                app.TextMessage(ME.message);
                app.dicomExportPath = '';
                app.Lamp5.Color = app.brightred;
                app.SetStatus(2);

            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(true);
         
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp6.Color = app.yellow;
            app.SetStatus(3);

            app.TextMessage('Animated GIF export ...');

            % Establish export folder
            try

                try

                    if app.r.scanner == "MRS"
                        gifexportPath = replace(app.mrdImportPath, "MRD", "Data");
                        k = strfind(gifexportPath,"Data");
                        fs = strfind(gifexportPath(k:end),filesep);
                        gifexportPath = gifexportPath(1:k+fs(2)-1);
                    else
                        myPath  = app.mrdImportPath;
                        idcs   = strfind(myPath,filesep);
                        gifexportPath  = myPath(1:idcs(end-2)-1);
                    end
                    
                    % Check if directory is actually writable
                    mkdir(strcat(gifexportPath,filesep,"testdir"));
                    rmdir(strcat(gifexportPath,filesep,"testdir"));

                catch

                    f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                    gifexportPath = uigetdir(app.gifExportPath,'GIF export path');
                    delete(f);
                    figure(app.UIFigure);  % Put the app back in the foreground
                    if ~ischar(app.gifExportPath)
                        ME = MException('Retrospective:userCancel','User cancel ...');
                        throw(ME);
                    end

                end

                % Export to animated gif
                app.TextMessage('GIF export ...');
                app.gifExportPath = gifexportPath;
                app.r.exportPath = retroExportGifFcn(app);
                app.r.exportRecoParametersFcn(app);
                app.Lamp6.Color = app.brightgreen;
                app.SetStatus(0);

            catch ME

                app.TextMessage('ERROR: GIF export failed ...');
                app.TextMessage(ME.message);
                app.gifExportPath = '';
                app.Lamp6.Color = app.brightred;
                app.SetStatus(2);

            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(true);
        
        end

        % Button pushed function: ExportMRDButton
        function ExportMRDButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp7.Color = app.yellow;
            app.SetStatus(3);
            
            app.TextMessage('MRD and SUR file export ...');

            try

                % Make new k-space from the movie
                app.r = app.r.backToKspace(app);

                % Set new parameters for MRD footer
                [app.r, par] = app.r.newMrdParameters(app);

                % Create new MRD footer
                app.r = app.r.makeMrdFooter(app, par);

                % Create new RPR data
                if app.r.rprFlag
                    app.r = app.r.makeRprFile(app, par);
                end

                % Export the new MRD and rpr files to the original MRD path
                app.mrdExportPath = app.mrdImportPath;
                
                % Check if directory is actually writable, else ask for different folder
                try

                    mkdir(strcat(app.mrdExportPath,filesep,"testdir"));
                    rmdir(strcat(app.mrdExportPath,filesep,"testdir"));

                catch

                    f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                    app.mrdExportPath = uigetdir(app.mrdExportPath,'MRD export folder');
                    delete(f);
                    figure(app.UIFigure);  % Put the app back in the foreground
                    if ~ischar(app.mrdExportPath)
                        ME = MException('Retrospective:userCancel','User cancel ...');
                        throw(ME);
                    end

                end

                % Construct final MRD and RPR path/filenames
                mrdExportFilename = strcat(app.mrdExportPath,filesep,'retro_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'.MRD');
                rprExportFilename = strcat(app.mrdExportPath,filesep,'retro_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'.rpr');

                % Write new MRD file
                app.r.writeDataToMrd(app, mrdExportFilename, par);

                % Write new RPR file
                if app.r.rprFlag
                    app.r.writeToRprFile(app, rprExportFilename);
                end

                % Export physiological logs
                app.r.writePhysLog(app, app.mrdExportPath);

                % Export reconstruction parameters log
                app.r.exportPath = app.mrdExportPath;
                app.r = app.r.exportRecoParametersFcn(app);

                % If PC, try to autoreconstruct and export SUR files
                if ispc

                    % Make sure path name has trailing filesep
                    app.mrdImportPath = strcat(app.mrdImportPath,filesep);
                    dblSlash = strcat(filesep,filesep);
                    app.mrdImportPath = strrep(app.mrdImportPath,dblSlash,filesep);

                    % Determine SUR directory
                    surPathFound = false;
                    try
                        
                        scanPos = strfind(app.mrdImportPath,filesep);
                        scanTag = strcat(app.mrdImportPath(scanPos(end-2)+1:scanPos(end-1)-1),filesep,'Image',filesep,app.mrdImportPath(scanPos(end-1)+1:scanPos(end)-1));
                        surPath = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','BaseStorageDirectory');
                        surPath1 = strcat(surPath,filesep,scanTag,filesep);
                        surPath2 = strcat(surPath,filesep,scanTag,filesep,'1',filesep);
                        % Check if directory is actually writable
                        mkdir(strcat(surPath2,filesep,"testdir"));
                        rmdir(strcat(surPath2,filesep,"testdir"));
                        app.TextMessage('Image directory found in Windows registry ...');
                        surPathFound = true;

                    catch
                    end

                    % If not found automatically, try different way
                    if ~surPathFound

                        try

                            surPath = replace(app.mrdImportPath,"MRD","Data");
                            k = strfind(surPath,"Data");
                            fs = strfind(surPath(k:end),filesep);
                            surPath = surPath(1:k+fs(3)-1);
                            imageSlash = strcat("Image",filesep);
                            surPath1 = insertAfter(surPath,k+fs(2)-1,imageSlash);
                            surPath1 = strrep(surPath1,dblSlash,filesep);
                            surPath2 = strcat(surPath1,filesep,'1',filesep);
                            surPath2 = strrep(surPath2,dblSlash,filesep);
                            % Check if directory is actually writable
                            mkdir(strcat(surPath2,filesep,"testdir"));
                            rmdir(strcat(surPath2,filesep,"testdir"));
                            surPathFound = true;

                        catch
                        end

                    end

                    % If SUR / image path successfully constructed
                    if surPathFound

                        try

                            % prefer /1/ path
                            surPath = surPath1;
                            if (exist(surPath2, 'dir'))
                                surPath = surPath2;
                            end

                            app.TextMessage(strcat("SUR directory = ",surPath));

                            % SUR file names
                            surSuffix = extractBefore(app.tag,'_');

                            % Make and save the new SUR files
                            app.r.recoSurFiles(app,surPath,surSuffix,mrdExportFilename,rprExportFilename);

                        catch ME1

                            app.TextMessage('WARNING: Automatic reconstruction of SUR files failed ...');
                            app.TextMessage(ME1.message);
                            app.SetStatus(1);

                        end

                    end

                end

                % Export data to Mat file
                app.r = app.r.exportToMatFile(app, par);

                % Completed MRD, SUR, and k-space export successfully
                app.Lamp7.Color = app.brightgreen;
                app.SetStatus(0);

            catch ME

                % No success
                app.TextMessage(ME.message)
                app.TextMessage('ERROR: MRD export failed ...');
                app.mrdExportPath = '';
                app.Lamp7.Color = app.brightred;
                app.SetStatus(2);

            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(true);
         
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

            key = event.Key;

            try

                switch key

                    case 'o'

                        % Toggle image orientations labels flag
                        app.orientationsFlag = ~app.orientationsFlag;
                        if app.orientationsFlag
                            app.TextMessage('Image orientation labels on ...');
                        else
                            app.TextMessage('Image Orientation labels off ...');
                        end

                    case 's'

                        % Save the app ui in a png image
                        app.TextMessage('Saving UI ...');
                        exportapp(app.UIFigure,"retrospective10UI.png");

                end

            catch ME

                app.TextMessage(ME.message);

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            % Stop all movies
            app.StopAllMoviesFcn;

            % Remove subfolders from search path
            if (~isdeployed)

                info = dir(which('retrospective10.mlapp'));
                app.appPath = info.folder;

                try
                    delete(strcat(app.appPath,filesep,'drim',filesep,'__pycache__',filesep,'*.pyc'));
                    delete(strcat(app.appPath,filesep,'drim',filesep,'m1',filesep,'logs',filesep,'*.txt'));
                    delete(strcat(app.appPath,filesep,'drim',filesep,'m2',filesep,'logs',filesep,'*.txt'));
                    delete(strcat(app.appPath,filesep,'drim',filesep,'m3',filesep,'logs',filesep,'*.txt'));
                    delete(strcat(app.appPath,filesep,'drim',filesep,'m4',filesep,'logs',filesep,'*.txt'));
                    delete(strcat(app.appPath,filesep,'drim',filesep,'m5',filesep,'logs',filesep,'*.txt'));
                catch
                end

                rmpath(genpath(app.appPath));
                cd(app.oldPath);
            end

            % Close log file
            fclose(app.logFile);

            % Exit
            app.delete;
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Ricon.png');
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Credits = uilabel(app.UIFigure);
            app.Credits.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.Credits.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulipImage = uiimage(app.UIFigure);
            app.MRSImage = uiimage(app.UIFigure);
            app.StatusBackgroundImage = uiimage(app.UIFigure);
            app.StatusBackgroundBorder = uieditfield(app.UIFigure, 'text');
            app.StatusImage = uiimage(app.UIFigure);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'status';
            app.ReconstructionTabGroup = uitabgroup(app.UIFigure);
            app.RecoTab = uitab(app.ReconstructionTabGroup);
            app.RecoTab.Title = 'Reco';
            app.MovieFig = uiaxes(app.RecoTab);
            app.MovieFig.XTick = [];
            app.MovieFig.YTick = [];
            app.MovieStopButton = uibutton(app.RecoTab, 'push');
            app.MovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopMovieButtonPushed, true);
            app.MovieStopButton.Enable = 'off';
            app.MovieStopButton.Tooltip = {'Stop the movie'};
            app.MovieStopButton.Text = 'Stop';
            app.MovieStartButton = uibutton(app.RecoTab, 'push');
            app.MovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartMovieButtonPushed, true);
            app.MovieStartButton.Enable = 'off';
            app.MovieStartButton.Tooltip = {'Start the movie'};
            app.MovieStartButton.Text = 'Start';
            app.ScaleLabel = uilabel(app.RecoTab);
            app.ScaleLabel.Tooltip = {'Level and Window'};
            app.ScaleLabel.Text = 'Scale';
            app.MoviecwButton = uibutton(app.RecoTab, 'push');
            app.MoviecwButton.ButtonPushedFcn = createCallbackFcn(app, @MovieRotateMoviecwButtonPushed, true);
            app.MoviecwButton.Enable = 'off';
            app.MoviecwButton.Tooltip = {'Rotate movie cw'};
            app.MoviecwButton.Text = '';
            app.MovieccwButton = uibutton(app.RecoTab, 'push');
            app.MovieccwButton.ButtonPushedFcn = createCallbackFcn(app, @MovieRotateMovieccwButtonPushed, true);
            app.MovieccwButton.Enable = 'off';
            app.MovieccwButton.Tooltip = {'Rotate movie cc'};
            app.MovieccwButton.Text = '';
            app.MovieRotateLabel = uilabel(app.RecoTab);
            app.MovieRotateLabel.Enable = 'off';
            app.MovieRotateLabel.Tooltip = {'Rotate movie'};
            app.MovieRotateLabel.Text = 'rotate';
            app.MovieFrameIncrButton = uibutton(app.RecoTab, 'push');
            app.MovieFrameIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieFrameIncrButtonPushed, true);
            app.MovieFrameIncrButton.Enable = 'off';
            app.MovieFrameIncrButton.Tooltip = {'Frame number +1'};
            app.MovieFrameIncrButton.Text = '+1';
            app.MovieFrameLabel = uilabel(app.RecoTab);
            app.MovieFrameLabel.Enable = 'off';
            app.MovieFrameLabel.Tooltip = {'Frame'};
            app.MovieFrameLabel.Text = 'frame';
            app.MovieFrameDecrButton = uibutton(app.RecoTab, 'push');
            app.MovieFrameDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieFrameDecrButtonPushed, true);
            app.MovieFrameDecrButton.Enable = 'off';
            app.MovieFrameDecrButton.Tooltip = {'Frame number -1'};
            app.MovieFrameDecrButton.Text = '-1';
            app.MovieSliceIncrButton = uibutton(app.RecoTab, 'push');
            app.MovieSliceIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieSliceIncrButtonPushed, true);
            app.MovieSliceIncrButton.Enable = 'off';
            app.MovieSliceIncrButton.Tooltip = {'Slice number +1'};
            app.MovieSliceIncrButton.Text = '+1';
            app.MovieSliceLabel = uilabel(app.RecoTab);
            app.MovieSliceLabel.Enable = 'off';
            app.MovieSliceLabel.Tooltip = {'Slice'};
            app.MovieSliceLabel.Text = 'slice';
            app.MovieSliceDecrButton = uibutton(app.RecoTab, 'push');
            app.MovieSliceDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieSliceDecrButtonPushed, true);
            app.MovieSliceDecrButton.Enable = 'off';
            app.MovieSliceDecrButton.Tooltip = {'Slice number -1'};
            app.MovieSliceDecrButton.Text = '-1';
            app.ButtonGroup = uibuttongroup(app.RecoTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MovieButtonGroupSelectionChanged, true);
            app.MovieButton = uiradiobutton(app.ButtonGroup);
            app.MovieButton.Enable = 'off';
            app.MovieButton.Tooltip = {'Movie display'};
            app.MovieButton.Text = 'movie';
            app.MovieButton.Value = true;
            app.AveragesButton = uiradiobutton(app.ButtonGroup);
            app.AveragesButton.Enable = 'off';
            app.AveragesButton.Tooltip = {'Map of the number of k-space signal averages'};
            app.AveragesButton.Text = 'averages';
            app.kSpaceButton = uiradiobutton(app.ButtonGroup);
            app.kSpaceButton.Enable = 'off';
            app.kSpaceButton.Tooltip = {'Absolute value of k-space'};
            app.kSpaceButton.Text = '|k-space|';
            app.SenseMapButton = uiradiobutton(app.ButtonGroup);
            app.SenseMapButton.Enable = 'off';
            app.SenseMapButton.Tooltip = {'Coil sensitivity maps'};
            app.SenseMapButton.Text = 'sense-map';
            app.MovieCoilSpinner = uispinner(app.RecoTab);
            app.MovieCoilSpinner.Limits = [1 16];
            app.MovieCoilSpinner.ValueDisplayFormat = '%.0f';
            app.MovieCoilSpinner.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.MovieCoilSpinner.Enable = 'off';
            app.MovieCoilSpinner.Value = 1;
            app.SenseMapSpinner1 = uispinner(app.RecoTab);
            app.SenseMapSpinner1.Limits = [1 16];
            app.SenseMapSpinner1.ValueDisplayFormat = '%.0f';
            app.SenseMapSpinner1.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.SenseMapSpinner1.Enable = 'off';
            app.SenseMapSpinner1.Value = 1;
            app.SenseMapSpinner2 = uispinner(app.RecoTab);
            app.SenseMapSpinner2.Limits = [1 16];
            app.SenseMapSpinner2.ValueDisplayFormat = '%.0f';
            app.SenseMapSpinner2.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.SenseMapSpinner2.Enable = 'off';
            app.SenseMapSpinner2.Value = 1;
            app.MovieDynIncrButton = uibutton(app.RecoTab, 'push');
            app.MovieDynIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieDynIncrButtonPushed, true);
            app.MovieDynIncrButton.Enable = 'off';
            app.MovieDynIncrButton.Tooltip = {'Dynamic number +1'};
            app.MovieDynIncrButton.Text = '+1';
            app.MovieDynLabel = uilabel(app.RecoTab);
            app.MovieDynLabel.Enable = 'off';
            app.MovieDynLabel.Tooltip = {'Dynamic'};
            app.MovieDynLabel.Text = 'dynamic';
            app.MovieDynDecrButton = uibutton(app.RecoTab, 'push');
            app.MovieDynDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieDynDecrButtonPushed, true);
            app.MovieDynDecrButton.Enable = 'off';
            app.MovieDynDecrButton.Tooltip = {'Dynamic number -1'};
            app.MovieDynDecrButton.Text = '-1';
            app.MovieCheckBox3 = uicheckbox(app.RecoTab);
            app.MovieCheckBox3.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox3.Enable = 'off';
            app.MovieCheckBox3.Text = '';
            app.MovieCheckBox2 = uicheckbox(app.RecoTab);
            app.MovieCheckBox2.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox2.Enable = 'off';
            app.MovieCheckBox2.Text = '';
            app.MovieCheckBox1 = uicheckbox(app.RecoTab);
            app.MovieCheckBox1.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox1.Enable = 'off';
            app.MovieCheckBox1.Text = '';
            app.MovieCheckBox1.Value = true;
            app.MovieFrameEditFieldLabel = uilabel(app.RecoTab);
            app.MovieFrameEditFieldLabel.Enable = 'off';
            app.MovieFrameEditFieldLabel.Tooltip = {'CINE frame number'};
            app.MovieFrameEditFieldLabel.Text = 'frame';
            app.MovieFrameEditField = uieditfield(app.RecoTab, 'numeric');
            app.MovieFrameEditField.Limits = [1 Inf];
            app.MovieFrameEditField.ValueDisplayFormat = '%.0f';
            app.MovieFrameEditField.ValueChangedFcn = createCallbackFcn(app, @MovieFrameEditFieldValueChanged, true);
            app.MovieFrameEditField.Enable = 'off';
            app.MovieFrameEditField.Tooltip = {'CINE frame number'};
            app.MovieFrameEditField.Value = 1;
            app.FinalHeartViewFieldLabel = uilabel(app.RecoTab);
            app.FinalHeartViewFieldLabel.Tooltip = {'Average heart rate.'};
            app.FinalHeartViewFieldLabel.Text = 'heart (bpm)';
            app.FinalHeartViewField = uieditfield(app.RecoTab, 'numeric');
            app.FinalHeartViewField.Editable = 'off';
            app.FinalHeartViewField.Tooltip = {'Heart rate (bpm)'};
            app.FinalRespViewFieldLabel = uilabel(app.RecoTab);
            app.FinalRespViewFieldLabel.Tooltip = {'Average respiration rate.'};
            app.FinalRespViewFieldLabel.Text = 'resp (bpm)';
            app.FinalRespViewField = uieditfield(app.RecoTab, 'numeric');
            app.FinalRespViewField.Editable = 'off';
            app.FinalRespViewField.Tooltip = {'Respiration rate (bpm)'};
            app.MovieSliceEditFieldLabel = uilabel(app.RecoTab);
            app.MovieSliceEditFieldLabel.Enable = 'off';
            app.MovieSliceEditFieldLabel.Tooltip = {'Slice number'};
            app.MovieSliceEditFieldLabel.Text = 'slice';
            app.MovieSliceEditField = uieditfield(app.RecoTab, 'numeric');
            app.MovieSliceEditField.Limits = [1 Inf];
            app.MovieSliceEditField.ValueDisplayFormat = '%.0f';
            app.MovieSliceEditField.ValueChangedFcn = createCallbackFcn(app, @MovieSliceEditFieldValueChanged, true);
            app.MovieSliceEditField.Enable = 'off';
            app.MovieSliceEditField.Tooltip = {'Slice number'};
            app.MovieSliceEditField.Value = 1;
            app.WindowScaleSliderLabel = uilabel(app.RecoTab);
            app.WindowScaleSliderLabel.Enable = 'off';
            app.WindowScaleSliderLabel.Tooltip = {'Intensity scale adjustment.'};
            app.WindowScaleSliderLabel.Text = 'W';
            app.WindowScaleSlider = uislider(app.RecoTab);
            app.WindowScaleSlider.Limits = [0 32767];
            app.WindowScaleSlider.MajorTicks = [];
            app.WindowScaleSlider.ValueChangedFcn = createCallbackFcn(app, @MovieWindowScaleSliderValueChanged, true);
            app.WindowScaleSlider.MinorTicks = [0 4681 9362 14043 18724 23405 28086 32767];
            app.WindowScaleSlider.Enable = 'off';
            app.WindowScaleSlider.Tooltip = {'Window'};
            app.WindowScaleSlider.Value = 16384;
            app.LevelScaleSliderLabel = uilabel(app.RecoTab);
            app.LevelScaleSliderLabel.Enable = 'off';
            app.LevelScaleSliderLabel.Tooltip = {'Intensity scale adjustment.'};
            app.LevelScaleSliderLabel.Text = 'L';
            app.LevelScaleSlider = uislider(app.RecoTab);
            app.LevelScaleSlider.Limits = [0 32767];
            app.LevelScaleSlider.MajorTicks = [];
            app.LevelScaleSlider.ValueChangedFcn = createCallbackFcn(app, @MovieLevelScaleSliderValueChanged, true);
            app.LevelScaleSlider.MinorTicks = [0 4681 9362 14043 18724 23405 28086 32767];
            app.LevelScaleSlider.Enable = 'off';
            app.LevelScaleSlider.Tooltip = {'Level'};
            app.LevelScaleSlider.Value = 16384;
            app.MovieDynEditField = uieditfield(app.RecoTab, 'numeric');
            app.MovieDynEditField.Limits = [1 Inf];
            app.MovieDynEditField.ValueDisplayFormat = '%.0f';
            app.MovieDynEditField.ValueChangedFcn = createCallbackFcn(app, @MovieDynEditFieldValueChanged, true);
            app.MovieDynEditField.Enable = 'off';
            app.MovieDynEditField.Tooltip = {'Dynamic or flip-angle number'};
            app.MovieDynEditField.Value = 1;
            app.MovieDynEditFieldLabel = uilabel(app.RecoTab);
            app.MovieDynEditFieldLabel.Enable = 'off';
            app.MovieDynEditFieldLabel.Tooltip = {'Dynamic or flip-angle number'};
            app.MovieDynEditFieldLabel.Text = 'dynamic';
            app.AutoWLButton = uibutton(app.RecoTab, 'state');
            app.AutoWLButton.ValueChangedFcn = createCallbackFcn(app, @AutoWLButtonValueChanged, true);
            app.AutoWLButton.Tooltip = {'Auto window and level'};
            app.AutoWLButton.Enable = 'off';
            app.AutoWLButton.Text = 'Auto';
            app.AutoWLButton.Value = true;
            app.OrientationLabel2 = uilabel(app.RecoTab);
            app.OrientationLabel2.Text = '';
            app.OrientationLabel1 = uilabel(app.RecoTab);
            app.OrientationLabel1.Text = '';
            app.OrientationLabel3 = uilabel(app.RecoTab);
            app.OrientationLabel3.Text = '';
            app.OrientationLabel4 = uilabel(app.RecoTab);
            app.OrientationLabel4.Text = '';
            app.ShowNavButton = uibutton(app.RecoTab, 'push');
            app.ShowNavButton.ButtonPushedFcn = createCallbackFcn(app, @ShowNavButtonPushed, true);
            app.ShowNavButton.Enable = 'off';
            app.ShowNavButton.Tooltip = {'Show or hide the external navigator slice'};
            app.ShowNavButton.Text = 'Nav';
            app.LcurveTab = uitab(app.ReconstructionTabGroup);
            app.LcurveTab.Title = 'L-curve';
            app.LcurveFig = uiaxes(app.LcurveTab);
            title(app.LcurveFig, 'L-curve')
            xlabel(app.LcurveFig, '|| F^{-1}x_{\lambda} - y ||_2')
            ylabel(app.LcurveFig, '|| TVcine x_{\lambda} ||_2')
            app.LcurveModelErrorFig = uiaxes(app.LcurveTab);
            title(app.LcurveModelErrorFig, 'Model error')
            xlabel(app.LcurveModelErrorFig, '\lambda')
            ylabel(app.LcurveModelErrorFig, ' || F^{-1}x_{\lambda} - y ||_2')
            app.LcurveStartButton = uibutton(app.LcurveTab, 'push');
            app.LcurveStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartLcurveButtonPushed, true);
            app.LcurveStartButton.Enable = 'off';
            app.LcurveStartButton.Tooltip = {'Start L-curve calculation'};
            app.LcurveStartButton.Text = 'Start';
            app.LcurveStopButton = uibutton(app.LcurveTab, 'push');
            app.LcurveStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopLcurveButtonPushed, true);
            app.LcurveStopButton.Enable = 'off';
            app.LcurveStopButton.Tooltip = {'Stop L-curve calculation'};
            app.LcurveStopButton.Text = 'Stop';
            app.LambdaLabel = uilabel(app.LcurveTab);
            app.LambdaLabel.Text = 'Lambda';
            app.Lambda1EditFieldLabel = uilabel(app.LcurveTab);
            app.Lambda1EditFieldLabel.Enable = 'off';
            app.Lambda1EditFieldLabel.Text = 'lower';
            app.Lambda1EditField = uieditfield(app.LcurveTab, 'numeric');
            app.Lambda1EditField.Limits = [0 0.1];
            app.Lambda1EditField.Enable = 'off';
            app.Lambda1EditField.Tooltip = {'Regularization lower bound'};
            app.Lambda1EditField.Value = 0.001;
            app.Lambda2EditFieldLabel = uilabel(app.LcurveTab);
            app.Lambda2EditFieldLabel.Enable = 'off';
            app.Lambda2EditFieldLabel.Text = 'upper';
            app.Lambda2EditField = uieditfield(app.LcurveTab, 'numeric');
            app.Lambda2EditField.Limits = [0.2 0.8];
            app.Lambda2EditField.Enable = 'off';
            app.Lambda2EditField.Tooltip = {'Regularization upper bound'};
            app.Lambda2EditField.Value = 0.5;
            app.LambdaStepsEditFieldLabel = uilabel(app.LcurveTab);
            app.LambdaStepsEditFieldLabel.Enable = 'off';
            app.LambdaStepsEditFieldLabel.Text = '#steps';
            app.LambdaStepsEditField = uieditfield(app.LcurveTab, 'numeric');
            app.LambdaStepsEditField.Limits = [6 30];
            app.LambdaStepsEditField.ValueDisplayFormat = '%.0f';
            app.LambdaStepsEditField.Enable = 'off';
            app.LambdaStepsEditField.Tooltip = {'Number of regularization values'};
            app.LambdaStepsEditField.Value = 12;
            app.LambdaIterationsEditFieldLabel = uilabel(app.LcurveTab);
            app.LambdaIterationsEditFieldLabel.Enable = 'off';
            app.LambdaIterationsEditFieldLabel.Text = '#iter';
            app.LambdaIterationsEditField = uieditfield(app.LcurveTab, 'numeric');
            app.LambdaIterationsEditField.Limits = [1 10];
            app.LambdaIterationsEditField.ValueDisplayFormat = '%.0f';
            app.LambdaIterationsEditField.Enable = 'off';
            app.LambdaIterationsEditField.Tooltip = {'Number of iterations'};
            app.LambdaIterationsEditField.Value = 1;
            app.RegularizationLabel = uilabel(app.LcurveTab);
            app.RegularizationLabel.Text = 'Regularization';
            app.LcurveWVxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveWVxyzCheckBox.Tooltip = {'Wavelet regularization in spatial domain'};
            app.LcurveWVxyzCheckBox.Enable = 'off';
            app.LcurveWVxyzCheckBox.Text = '';
            app.LcurveTVxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVxyzCheckBox.Tooltip = {'Total variation regularization in spatial domain'};
            app.LcurveTVxyzCheckBox.Enable = 'off';
            app.LcurveTVxyzCheckBox.Text = '';
            app.LcurveLLRxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveLLRxyzCheckBox.Tooltip = {'Locally-Low Rank regularization in spatial domain'};
            app.LcurveLLRxyzCheckBox.Enable = 'off';
            app.LcurveLLRxyzCheckBox.Text = '';
            app.LcurveTVcineCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVcineCheckBox.Tooltip = {'Total variation regularizaiton in spatial domain'};
            app.LcurveTVcineCheckBox.Enable = 'off';
            app.LcurveTVcineCheckBox.Text = '';
            app.LcurveTVcineCheckBox.Value = true;
            app.LcurveTVdynCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVdynCheckBox.Tooltip = {'Total variation regularizaiton in dynamics domain'};
            app.LcurveTVdynCheckBox.Enable = 'off';
            app.LcurveTVdynCheckBox.Text = '';
            app.LcurveWVxyzCheckBoxLabel = uilabel(app.LcurveTab);
            app.LcurveWVxyzCheckBoxLabel.Enable = 'off';
            app.LcurveWVxyzCheckBoxLabel.Tooltip = {'Wavelet regularization in spatial domain'};
            app.LcurveWVxyzCheckBoxLabel.Text = 'WVxyz';
            app.LcurveTVxyzCheckBoxLabel = uilabel(app.LcurveTab);
            app.LcurveTVxyzCheckBoxLabel.Enable = 'off';
            app.LcurveTVxyzCheckBoxLabel.Tooltip = {'Total variation regularization in spatial domain'};
            app.LcurveTVxyzCheckBoxLabel.Text = 'TVxyz';
            app.LcurveLLRxyzCheckBoxLabel = uilabel(app.LcurveTab);
            app.LcurveLLRxyzCheckBoxLabel.Enable = 'off';
            app.LcurveLLRxyzCheckBoxLabel.Tooltip = {'Locally-Low Rank regularization in spatial domain'};
            app.LcurveLLRxyzCheckBoxLabel.Text = 'LRxyz';
            app.LcurveTVcineCheckBoxLabel = uilabel(app.LcurveTab);
            app.LcurveTVcineCheckBoxLabel.Enable = 'off';
            app.LcurveTVcineCheckBoxLabel.Tooltip = {'Total variation regularizaiton in spatial domain'};
            app.LcurveTVcineCheckBoxLabel.Text = 'TVcine';
            app.LcurveTVdynCheckBoxLabel = uilabel(app.LcurveTab);
            app.LcurveTVdynCheckBoxLabel.Enable = 'off';
            app.LcurveTVdynCheckBoxLabel.Tooltip = {'Total variation regularizaiton in dynamics domain'};
            app.LcurveTVdynCheckBoxLabel.Text = 'TVdyn';
            app.RadialRecoTab = uitab(app.ReconstructionTabGroup);
            app.RadialRecoTab.Title = 'Radial';
            app.TrajectoryLargeFig = uiaxes(app.RadialRecoTab);
            app.TrajectoryLargeFig.XTick = [];
            app.TrajectoryLargeFig.YTick = [];
            app.TrajMovieDynEditFieldLabel = uilabel(app.RadialRecoTab);
            app.TrajMovieDynEditFieldLabel.Enable = 'off';
            app.TrajMovieDynEditFieldLabel.Tooltip = {'Dynamic number'};
            app.TrajMovieDynEditFieldLabel.Text = 'dynamic';
            app.TrajMovieSliceEditFieldLabel = uilabel(app.RadialRecoTab);
            app.TrajMovieSliceEditFieldLabel.Enable = 'off';
            app.TrajMovieSliceEditFieldLabel.Tooltip = {'Slice number'};
            app.TrajMovieSliceEditFieldLabel.Text = 'slice';
            app.TrajMovieFrameEditFieldLabel = uilabel(app.RadialRecoTab);
            app.TrajMovieFrameEditFieldLabel.Enable = 'off';
            app.TrajMovieFrameEditFieldLabel.Tooltip = {'CINE frame number'};
            app.TrajMovieFrameEditFieldLabel.Text = 'frame';
            app.TrajMovieCheckBox1 = uicheckbox(app.RadialRecoTab);
            app.TrajMovieCheckBox1.ValueChangedFcn = createCallbackFcn(app, @TrajMovieCheckBoxValueChanged, true);
            app.TrajMovieCheckBox1.Enable = 'off';
            app.TrajMovieCheckBox1.Text = '';
            app.TrajMovieCheckBox1.Value = true;
            app.TrajMovieCheckBox2 = uicheckbox(app.RadialRecoTab);
            app.TrajMovieCheckBox2.ValueChangedFcn = createCallbackFcn(app, @TrajMovieCheckBoxValueChanged, true);
            app.TrajMovieCheckBox2.Enable = 'off';
            app.TrajMovieCheckBox2.Text = '';
            app.TrajMovieCheckBox3 = uicheckbox(app.RadialRecoTab);
            app.TrajMovieCheckBox3.ValueChangedFcn = createCallbackFcn(app, @TrajMovieCheckBoxValueChanged, true);
            app.TrajMovieCheckBox3.Enable = 'off';
            app.TrajMovieCheckBox3.Text = '';
            app.TrajMovieFrameEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.TrajMovieFrameEditField.Limits = [1 240];
            app.TrajMovieFrameEditField.ValueDisplayFormat = '%.0f';
            app.TrajMovieFrameEditField.ValueChangedFcn = createCallbackFcn(app, @TrajMovieDimEditFieldValueChanged, true);
            app.TrajMovieFrameEditField.Enable = 'off';
            app.TrajMovieFrameEditField.Tooltip = {'CINE frame number'};
            app.TrajMovieFrameEditField.Value = 1;
            app.TrajMovieSliceEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.TrajMovieSliceEditField.Limits = [1 1024];
            app.TrajMovieSliceEditField.ValueDisplayFormat = '%.0f';
            app.TrajMovieSliceEditField.ValueChangedFcn = createCallbackFcn(app, @TrajMovieDimEditFieldValueChanged, true);
            app.TrajMovieSliceEditField.Enable = 'off';
            app.TrajMovieSliceEditField.Tooltip = {'Slice number'};
            app.TrajMovieSliceEditField.Value = 1;
            app.TrajMovieDynEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.TrajMovieDynEditField.Limits = [1 1024];
            app.TrajMovieDynEditField.ValueDisplayFormat = '%.0f';
            app.TrajMovieDynEditField.ValueChangedFcn = createCallbackFcn(app, @TrajMovieDimEditFieldValueChanged, true);
            app.TrajMovieDynEditField.Enable = 'off';
            app.TrajMovieDynEditField.Tooltip = {'Dynamic number'};
            app.TrajMovieDynEditField.Value = 1;
            app.TrajMovieStartButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrajMovieButtonPushed, true);
            app.TrajMovieStartButton.Enable = 'off';
            app.TrajMovieStartButton.Tooltip = {'Start the trajectory movie'};
            app.TrajMovieStartButton.Text = 'Start';
            app.TrajMovieStopButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrajMovieButtonPushed, true);
            app.TrajMovieStopButton.Enable = 'off';
            app.TrajMovieStopButton.Tooltip = {'Stop the trajectory movie'};
            app.TrajMovieStopButton.Text = 'Stop';
            app.SpokeEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.SpokeEditField.Limits = [1 Inf];
            app.SpokeEditField.ValueDisplayFormat = '%.0f';
            app.SpokeEditField.Enable = 'off';
            app.SpokeEditField.Tooltip = {'Spoke number'};
            app.SpokeEditField.Value = 1;
            app.SpokeEditFieldLabel = uilabel(app.RadialRecoTab);
            app.SpokeEditFieldLabel.Enable = 'off';
            app.SpokeEditFieldLabel.Text = 'spoke';
            app.Traj3DLabel = uilabel(app.RadialRecoTab);
            app.Traj3DLabel.Tooltip = {'2D radial trajectory'};
            app.Traj3DLabel.Text = '3D trajectory animation';
            app.TrajMovie3DStartButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovie3DStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrajMovie3DButtonPushed, true);
            app.TrajMovie3DStartButton.Enable = 'off';
            app.TrajMovie3DStartButton.Tooltip = {'Start the trajectory movie'};
            app.TrajMovie3DStartButton.Text = 'Start';
            app.TrajMovie3DStopButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovie3DStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrajMovie3DButtonPushed, true);
            app.TrajMovie3DStopButton.Enable = 'off';
            app.TrajMovie3DStopButton.Tooltip = {'Stop the trajectory movie'};
            app.TrajMovie3DStopButton.Text = 'Stop';
            app.ParametersTabGroup = uitabgroup(app.UIFigure);
            app.SequenceTab = uitab(app.ParametersTabGroup);
            app.SequenceTab.Title = 'Sequence';
            app.TrajectoryFig = uiaxes(app.SequenceTab);
            app.TrajectoryFig.XTick = [];
            app.TrajectoryFig.YTick = [];
            app.DataFile = uitextarea(app.SequenceTab);
            app.DataFile.ValueChangedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.DataFile.Editable = 'off';
            app.DataFile.Tooltip = {'Data file'};
            app.DataFile.Value = {' '};
            app.DataFileLabel = uilabel(app.SequenceTab);
            app.DataFileLabel.Text = 'data file';
            app.NyViewFieldLabel = uilabel(app.SequenceTab);
            app.NyViewFieldLabel.Tooltip = {'Number of pixels in phase-encoding direction'};
            app.NyViewFieldLabel.Text = 'phase  Ny';
            app.NyViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NyViewField.Limits = [0 Inf];
            app.NyViewField.ValueDisplayFormat = '%.0f';
            app.NyViewField.Editable = 'off';
            app.NyViewField.Tooltip = {'Number of pixels in phase-encoding direction'};
            app.NxViewFieldLabel = uilabel(app.SequenceTab);
            app.NxViewFieldLabel.Tooltip = {'Number of pixels in readout direction'};
            app.NxViewFieldLabel.Text = 'freq  Nx';
            app.NxViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NxViewField.Limits = [0 Inf];
            app.NxViewField.ValueDisplayFormat = '%.0f';
            app.NxViewField.Editable = 'off';
            app.NxViewField.Tooltip = {'Number of pixels in readout direction'};
            app.TrajectorySizeViewFieldLabel = uilabel(app.SequenceTab);
            app.TrajectorySizeViewFieldLabel.Tooltip = {'Length of the trajectory'};
            app.TrajectorySizeViewFieldLabel.Text = 'size';
            app.TrajectorySizeViewField = uieditfield(app.SequenceTab, 'numeric');
            app.TrajectorySizeViewField.Limits = [0 Inf];
            app.TrajectorySizeViewField.ValueDisplayFormat = '%.0f';
            app.TrajectorySizeViewField.Editable = 'off';
            app.TrajectorySizeViewField.Tooltip = {'Size of the trajectory'};
            app.RepetitionsViewFieldLabel = uilabel(app.SequenceTab);
            app.RepetitionsViewFieldLabel.Tooltip = {'Number of k-space repetitions'};
            app.RepetitionsViewFieldLabel.Text = '#repetitions';
            app.RepetitionsViewField = uieditfield(app.SequenceTab, 'numeric');
            app.RepetitionsViewField.Limits = [0 Inf];
            app.RepetitionsViewField.RoundFractionalValues = 'on';
            app.RepetitionsViewField.ValueDisplayFormat = '%.0f';
            app.RepetitionsViewField.Editable = 'off';
            app.RepetitionsViewField.Tooltip = {'Number of k-space repetitions'};
            app.NrKlinesViewFieldLabel = uilabel(app.SequenceTab);
            app.NrKlinesViewFieldLabel.Tooltip = {'Total number of acquired/reconstructed k-lines'};
            app.NrKlinesViewFieldLabel.Text = ' # k-lines';
            app.NrKlinesViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NrKlinesViewField.Limits = [0 Inf];
            app.NrKlinesViewField.ValueDisplayFormat = '%.0f';
            app.NrKlinesViewField.Editable = 'off';
            app.NrKlinesViewField.Tooltip = {'Total number of acquired/reconstructed k-lines'};
            app.AveragesViewFieldLabel = uilabel(app.SequenceTab);
            app.AveragesViewFieldLabel.Tooltip = {'Mean number of signal averages'};
            app.AveragesViewFieldLabel.Text = 'averages';
            app.AveragesViewField = uieditfield(app.SequenceTab, 'numeric');
            app.AveragesViewField.Limits = [0 Inf];
            app.AveragesViewField.ValueDisplayFormat = '%.2f';
            app.AveragesViewField.Editable = 'off';
            app.AveragesViewField.Tooltip = {'Mean number of signal averages'};
            app.FillingViewFieldLabel = uilabel(app.SequenceTab);
            app.FillingViewFieldLabel.Tooltip = {'K-space filling percentage'};
            app.FillingViewFieldLabel.Text = 'filling (%)';
            app.FillingViewField = uieditfield(app.SequenceTab, 'numeric');
            app.FillingViewField.Limits = [0 100];
            app.FillingViewField.ValueDisplayFormat = '%.0f';
            app.FillingViewField.Editable = 'off';
            app.FillingViewField.Tooltip = {'K-space filling percentage'};
            app.TRViewFieldLabel = uilabel(app.SequenceTab);
            app.TRViewFieldLabel.Tooltip = {'Repetition time'};
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.ValueDisplayFormat = '%.2f';
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TrajectoryViewFieldLabel = uilabel(app.SequenceTab);
            app.TrajectoryViewFieldLabel.Tooltip = {'K-space filling trajectory'};
            app.TrajectoryViewFieldLabel.Text = 'trajectory';
            app.TrajectoryViewField = uieditfield(app.SequenceTab, 'text');
            app.TrajectoryViewField.Editable = 'off';
            app.TrajectoryViewField.Tooltip = {'K-space filling trajectory'};
            app.TrajectoryViewField.Value = '-';
            app.NzViewFieldLabel = uilabel(app.SequenceTab);
            app.NzViewFieldLabel.Tooltip = {'Number of slices'};
            app.NzViewFieldLabel.Text = 'slices  Nz';
            app.NzViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NzViewField.Limits = [0 Inf];
            app.NzViewField.ValueDisplayFormat = '%.0f';
            app.NzViewField.Editable = 'off';
            app.NzViewField.Tooltip = {'Number of slices / 2nd phase-encoding direction'};
            app.NcViewFieldLabel = uilabel(app.SequenceTab);
            app.NcViewFieldLabel.Tooltip = {'Number of receiver coils'};
            app.NcViewFieldLabel.Text = 'coils  Nc';
            app.NcViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NcViewField.Limits = [0 Inf];
            app.NcViewField.ValueDisplayFormat = '%.0f';
            app.NcViewField.Editable = 'off';
            app.NcViewField.Tooltip = {'Number of receiver coils'};
            app.ScanTimeViewFieldLabel = uilabel(app.SequenceTab);
            app.ScanTimeViewFieldLabel.Tooltip = {'Total scan time'};
            app.ScanTimeViewFieldLabel.Text = 'scan time';
            app.ScanTimeViewField = uieditfield(app.SequenceTab, 'text');
            app.ScanTimeViewField.Editable = 'off';
            app.ScanTimeViewField.Tooltip = {'Total scan time'};
            app.ScanTimeViewField.Value = '-';
            app.TimeDynViewFieldLabel = uilabel(app.SequenceTab);
            app.TimeDynViewFieldLabel.Tooltip = {'Scan time per dynamic'};
            app.TimeDynViewFieldLabel.Text = 'time / dynamic';
            app.TimeDynViewField = uieditfield(app.SequenceTab, 'text');
            app.TimeDynViewField.Editable = 'off';
            app.TimeDynViewField.Tooltip = {'Scan time per dynamic'};
            app.TimeDynViewField.Value = '-';
            app.NrFlipAnglesViewFieldLabel = uilabel(app.SequenceTab);
            app.NrFlipAnglesViewFieldLabel.Tooltip = {'Number of flip-angles for T1-mapping'};
            app.NrFlipAnglesViewFieldLabel.Text = '#flip angles';
            app.NrFlipAnglesViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NrFlipAnglesViewField.Limits = [0 100];
            app.NrFlipAnglesViewField.ValueDisplayFormat = '%.0f';
            app.NrFlipAnglesViewField.Editable = 'off';
            app.NrFlipAnglesViewField.Tooltip = {'Number of flip-angles for T1-mapping'};
            app.FieldOfViewViewFieldLabel = uilabel(app.SequenceTab);
            app.FieldOfViewViewFieldLabel.Tooltip = {'Field of view'};
            app.FieldOfViewViewFieldLabel.Text = 'FOV (mm)';
            app.FieldOfViewViewField = uieditfield(app.SequenceTab, 'numeric');
            app.FieldOfViewViewField.Limits = [0 Inf];
            app.FieldOfViewViewField.ValueDisplayFormat = '%.2f';
            app.FieldOfViewViewField.Editable = 'off';
            app.FieldOfViewViewField.Tooltip = {'Field of view'};
            app.TEViewFiedLabel = uilabel(app.SequenceTab);
            app.TEViewFiedLabel.Tooltip = {'Echo time'};
            app.TEViewFiedLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceTab, 'numeric');
            app.TEViewField.Limits = [0 Inf];
            app.TEViewField.ValueDisplayFormat = '%.2f';
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.FlipAnglesViewFieldLabel = uilabel(app.SequenceTab);
            app.FlipAnglesViewFieldLabel.Tooltip = {'Flip angle(s)'};
            app.FlipAnglesViewFieldLabel.Text = 'flip angle(s)';
            app.FlipAnglesViewField = uieditfield(app.SequenceTab, 'text');
            app.FlipAnglesViewField.Editable = 'off';
            app.FlipAnglesViewField.Tooltip = {'Flip angle(s)'};
            app.FlipAnglesViewField.Value = '-';
            app.GeometryTab = uitab(app.ParametersTabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.PhaseOrientationViewFieldLabel = uilabel(app.GeometryTab);
            app.PhaseOrientationViewFieldLabel.Tooltip = {'Field of view'};
            app.PhaseOrientationViewFieldLabel.Text = 'phase orientation';
            app.PhaseOrientationViewField = uieditfield(app.GeometryTab, 'numeric');
            app.PhaseOrientationViewField.Limits = [0 Inf];
            app.PhaseOrientationViewField.ValueDisplayFormat = '%.0f';
            app.PhaseOrientationViewField.Editable = 'off';
            app.PhaseOrientationViewField.Tooltip = {'Phase orientation horizontal (1) or vertical (0)'};
            app.SliceThicknessViewFieldLabel = uilabel(app.GeometryTab);
            app.SliceThicknessViewFieldLabel.Tooltip = {'Field of view'};
            app.SliceThicknessViewFieldLabel.Text = 'slice thickness (mm)';
            app.SliceThicknessViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceThicknessViewField.Limits = [0 Inf];
            app.SliceThicknessViewField.ValueDisplayFormat = '%.2f';
            app.SliceThicknessViewField.Editable = 'off';
            app.SliceThicknessViewField.Tooltip = {'Slice thickness'};
            app.AspectRatioViewFieldLabel = uilabel(app.GeometryTab);
            app.AspectRatioViewFieldLabel.Tooltip = {'Field of view'};
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AspectRatioViewField.Limits = [0 Inf];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.FieldOfViewViewField2Label = uilabel(app.GeometryTab);
            app.FieldOfViewViewField2Label.Tooltip = {'Field of view'};
            app.FieldOfViewViewField2Label.Text = 'FOV (mm)';
            app.FieldOfViewViewField2 = uieditfield(app.GeometryTab, 'numeric');
            app.FieldOfViewViewField2.Limits = [0 Inf];
            app.FieldOfViewViewField2.ValueDisplayFormat = '%.2f';
            app.FieldOfViewViewField2.Editable = 'off';
            app.FieldOfViewViewField2.Tooltip = {'Field of view'};
            app.AngleZViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleZViewFieldLabel.Tooltip = {'Field of view'};
            app.AngleZViewFieldLabel.Text = 'angle Z (deg)';
            app.AngleZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleZViewField.ValueDisplayFormat = '%.0f';
            app.AngleZViewField.Editable = 'off';
            app.AngleZViewField.Tooltip = {'Z angulation'};
            app.AngleYViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleYViewFieldLabel.Tooltip = {'Field of view'};
            app.AngleYViewFieldLabel.Text = 'angle Y (deg)';
            app.AngleYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleYViewField.ValueDisplayFormat = '%.0f';
            app.AngleYViewField.Editable = 'off';
            app.AngleYViewField.Tooltip = {'Y angulation'};
            app.AngleXViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleXViewFieldLabel.Tooltip = {'Field of view'};
            app.AngleXViewFieldLabel.Text = 'angle X (deg)';
            app.AngleXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleXViewField.ValueDisplayFormat = '%.0f';
            app.AngleXViewField.Editable = 'off';
            app.AngleXViewField.Tooltip = {'X angulation'};
            app.OffsetZViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetZViewFieldLabel.Tooltip = {'Field of view'};
            app.OffsetZViewFieldLabel.Text = 'offset Z (mm)';
            app.OffsetZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetZViewField.ValueDisplayFormat = '%.2f';
            app.OffsetZViewField.Editable = 'off';
            app.OffsetZViewField.Tooltip = {'Slice / 2nd phase-encoding offset'};
            app.OffsetYViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetYViewFieldLabel.Tooltip = {'Field of view'};
            app.OffsetYViewFieldLabel.Text = 'offset Y (mm)';
            app.OffsetYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetYViewField.ValueDisplayFormat = '%.2f';
            app.OffsetYViewField.Editable = 'off';
            app.OffsetYViewField.Tooltip = {'Phase-encoding offset'};
            app.OffsetXViewFieldlabel = uilabel(app.GeometryTab);
            app.OffsetXViewFieldlabel.Tooltip = {'Field of view'};
            app.OffsetXViewFieldlabel.Text = 'offset X (mm)';
            app.OffsetXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetXViewField.ValueDisplayFormat = '%.2f';
            app.OffsetXViewField.Editable = 'off';
            app.OffsetXViewField.Tooltip = {'Readout offset'};
            app.SliceGapViewFieldLabel = uilabel(app.GeometryTab);
            app.SliceGapViewFieldLabel.Tooltip = {'Field of view'};
            app.SliceGapViewFieldLabel.Text = 'slice gap (mm)';
            app.SliceGapViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceGapViewField.ValueDisplayFormat = '%.2f';
            app.SliceGapViewField.Editable = 'off';
            app.SliceGapViewField.Tooltip = {'Slice gap'};
            app.RecoParametersTab = uitab(app.ParametersTabGroup);
            app.RecoParametersTab.Title = 'Reco Parameters';
            app.WVxyzEditFieldLabel = uilabel(app.RecoParametersTab);
            app.WVxyzEditFieldLabel.Enable = 'off';
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1 regularization in spatial domain'};
            app.TVxyzEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total Variation regularization in spatial domain'};
            app.LLRxyzEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.LLRxyzEditField.Limits = [0 20];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.LLRxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Tooltip = {'Locally Low-Rank regularization in spatial domain'};
            app.TVcineEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.TVcineEditField.Limits = [0 20];
            app.TVcineEditField.ValueDisplayFormat = '%.4f';
            app.TVcineEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVcineEditField.Enable = 'off';
            app.TVcineEditField.Tooltip = {'Total Variation regularization in CINE dimension'};
            app.TVcineEditField.Value = 0.1;
            app.TVdynEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.4f';
            app.TVdynEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total Variation regularization in dynamic dimension'};
            app.ESPIRiTCheckBox = uicheckbox(app.RecoParametersTab);
            app.ESPIRiTCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.ESPIRiTCheckBox.Tooltip = {'ESPIRiT reconstruction of multi-coil data'};
            app.ESPIRiTCheckBox.Enable = 'off';
            app.ESPIRiTCheckBox.Text = '';
            app.NLMFCheckBox = uicheckbox(app.RecoParametersTab);
            app.NLMFCheckBox.Tooltip = {'Non-local means filter image denoising'};
            app.NLMFCheckBox.Enable = 'off';
            app.NLMFCheckBox.Text = '';
            app.NLMFfilterStrengthEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.NLMFfilterStrengthEditField.Limits = [0.0001 1];
            app.NLMFfilterStrengthEditField.ValueDisplayFormat = '%.4f';
            app.NLMFfilterStrengthEditField.Enable = 'off';
            app.NLMFfilterStrengthEditField.Tooltip = {'Non-local means filter image denoising strength'};
            app.NLMFfilterStrengthEditField.Value = 0.005;
            app.RingRmCheckBox = uicheckbox(app.RecoParametersTab);
            app.RingRmCheckBox.Tooltip = {'Gibbs ringing suppression'};
            app.RingRmCheckBox.Enable = 'off';
            app.RingRmCheckBox.Text = '';
            app.SharingEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.SharingEditField.Limits = [0 30];
            app.SharingEditField.ValueDisplayFormat = '%.0f';
            app.SharingEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.SharingEditField.Enable = 'off';
            app.SharingEditField.Tooltip = {'Sharing of k-lines from neighboring frames to improve k-space filling at the expense of temporal smoothing.'};
            app.AutoGuessRecoCheckBox = uicheckbox(app.RecoParametersTab);
            app.AutoGuessRecoCheckBox.Tooltip = {'Automatically guess of type of reconstruction'};
            app.AutoGuessRecoCheckBox.Text = '';
            app.AutoGuessRecoCheckBox.Value = true;
            app.RecoPresetsButtonGroup = uibuttongroup(app.RecoParametersTab);
            app.RecoPresetsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RecoPresetsButtonGroupSelectionChanged, true);
            app.RecoPresetsButtonGroup.Tooltip = {'Preset reconstruction settings'};
            app.RecoPresetsButtonGroup.ButtonDownFcn = createCallbackFcn(app, @RecoPresetsButtonGroupButtonDown, true);
            app.ScoutButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.ScoutButton.Enable = 'off';
            app.ScoutButton.Tooltip = {'Single frame scout reconstruction'};
            app.ScoutButton.Text = 'scout';
            app.SystolicFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.SystolicFunctionButton.Enable = 'off';
            app.SystolicFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring systolic function'};
            app.SystolicFunctionButton.Text = 'systolic function';
            app.SystolicFunctionButton.Value = true;
            app.DiastolicFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.DiastolicFunctionButton.Enable = 'off';
            app.DiastolicFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring diastolic function'};
            app.DiastolicFunctionButton.Text = 'diastolic function';
            app.RespFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.RespFunctionButton.Enable = 'off';
            app.RespFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring respiratory motion'};
            app.RespFunctionButton.Text = 'respiratory function';
            app.RealTimeFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.RealTimeFunctionButton.Enable = 'off';
            app.RealTimeFunctionButton.Tooltip = {'Suggested reconstruction settings for realtime imaging = CINE movie per heartbeat'};
            app.RealTimeFunctionButton.Text = 'realtime';
            app.VfaFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.VfaFunctionButton.Enable = 'off';
            app.VfaFunctionButton.Tooltip = {'Suggested reconstruction settings for T1-mapping with the variable-flip-angle method'};
            app.VfaFunctionButton.Text = 'variable flip-angle';
            app.ReconstructionpresetsLabel = uilabel(app.RecoParametersTab);
            app.ReconstructionpresetsLabel.Tooltip = {'Preset reconstruction settings'};
            app.ReconstructionpresetsLabel.Text = 'Reconstruction presets';
            app.AutoGuessRecoCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.AutoGuessRecoCheckBoxLabel.Tooltip = {'Automatically guess of type of reconstruction'};
            app.AutoGuessRecoCheckBoxLabel.Text = 'guess reco';
            app.MatlabRecoCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.MatlabRecoCheckBoxLabel.Tooltip = {'Force Matlab reconstruction'};
            app.MatlabRecoCheckBoxLabel.Text = 'Matlab reco';
            app.MatlabRecoCheckBox = uicheckbox(app.RecoParametersTab);
            app.MatlabRecoCheckBox.Tooltip = {'Force Matlab reconstruction'};
            app.MatlabRecoCheckBox.Text = '';
            app.FramesharingLabel = uilabel(app.RecoParametersTab);
            app.FramesharingLabel.Text = 'Frame sharing';
            app.SharingEditFieldLabel = uilabel(app.RecoParametersTab);
            app.SharingEditFieldLabel.Enable = 'off';
            app.SharingEditFieldLabel.Text = '# frames';
            app.NLMFCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.NLMFCheckBoxLabel.Enable = 'off';
            app.NLMFCheckBoxLabel.Tooltip = {'Non-local means filter image denoising'};
            app.NLMFCheckBoxLabel.Text = 'NLMF';
            app.RingRmCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.RingRmCheckBoxLabel.Enable = 'off';
            app.RingRmCheckBoxLabel.Tooltip = {'Gibbs ringing suppression'};
            app.RingRmCheckBoxLabel.Text = 'ringing';
            app.CSrecoLabel = uilabel(app.RecoParametersTab);
            app.CSrecoLabel.Text = 'CS reco';
            app.VariousLabel = uilabel(app.RecoParametersTab);
            app.VariousLabel.Text = 'Various';
            app.ESPIRiTCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.ESPIRiTCheckBoxLabel.Enable = 'off';
            app.ESPIRiTCheckBoxLabel.Tooltip = {'ESPIRiT reconstruction of multi-coil data'};
            app.ESPIRiTCheckBoxLabel.Text = 'ESPIRiT';
            app.TVcineEditFieldLabel = uilabel(app.RecoParametersTab);
            app.TVcineEditFieldLabel.Enable = 'off';
            app.TVcineEditFieldLabel.Tooltip = {'Total-variation regularization in the CINE dimension'};
            app.TVcineEditFieldLabel.Text = 'TVcine';
            app.TVxyzEditFieldLabel = uilabel(app.RecoParametersTab);
            app.TVxyzEditFieldLabel.Enable = 'off';
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVdynEditFieldLabel = uilabel(app.RecoParametersTab);
            app.TVdynEditFieldLabel.Enable = 'off';
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.LLRxyzEditFieldLabel = uilabel(app.RecoParametersTab);
            app.LLRxyzEditFieldLabel.Enable = 'off';
            app.LLRxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.RadialTab = uitab(app.ParametersTabGroup);
            app.RadialTab.Title = 'Radial';
            app.GradDelayCalibrationCheckBox = uicheckbox(app.RadialTab);
            app.GradDelayCalibrationCheckBox.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBox.Enable = 'off';
            app.GradDelayCalibrationCheckBox.Text = '';
            app.GradDelayCalibrationCheckBoxLabel = uilabel(app.RadialTab);
            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
            app.GradDelayCalibrationCheckBoxLabel.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBoxLabel.Text = 'calibration';
            app.GradientdelaysLabel = uilabel(app.RadialTab);
            app.GradientdelaysLabel.Tooltip = {'Gradient delay settings'};
            app.GradientdelaysLabel.Text = 'Gradient delays';
            app.GxdelayEditFieldLabel = uilabel(app.RadialTab);
            app.GxdelayEditFieldLabel.Enable = 'off';
            app.GxdelayEditFieldLabel.Text = 'Gx delay';
            app.GxDelayEditField = uieditfield(app.RadialTab, 'numeric');
            app.GxDelayEditField.Limits = [-10 10];
            app.GxDelayEditField.ValueDisplayFormat = '%.5f';
            app.GxDelayEditField.Enable = 'off';
            app.GxDelayEditField.Tooltip = {'X-gradient delay'};
            app.GydelayEditFieldLabel = uilabel(app.RadialTab);
            app.GydelayEditFieldLabel.Enable = 'off';
            app.GydelayEditFieldLabel.Text = 'Gy delay';
            app.GyDelayEditField = uieditfield(app.RadialTab, 'numeric');
            app.GyDelayEditField.Limits = [-10 10];
            app.GyDelayEditField.ValueDisplayFormat = '%.5f';
            app.GyDelayEditField.Enable = 'off';
            app.GyDelayEditField.Tooltip = {'Y-gradient delay'};
            app.GzdelayEditFieldLabel = uilabel(app.RadialTab);
            app.GzdelayEditFieldLabel.Enable = 'off';
            app.GzdelayEditFieldLabel.Text = 'Gz delay';
            app.GzDelayEditField = uieditfield(app.RadialTab, 'numeric');
            app.GzDelayEditField.Limits = [-10 10];
            app.GzDelayEditField.ValueDisplayFormat = '%.5f';
            app.GzDelayEditField.Enable = 'off';
            app.GzDelayEditField.Tooltip = {'Z-gradient delay'};
            app.DataOffsetRadialEditFieldLabel = uilabel(app.RadialTab);
            app.DataOffsetRadialEditFieldLabel.Enable = 'off';
            app.DataOffsetRadialEditFieldLabel.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.DataOffsetRadialEditFieldLabel.Text = 'data offset';
            app.DataOffsetRadialEditField = uieditfield(app.RadialTab, 'numeric');
            app.DataOffsetRadialEditField.Limits = [0 10];
            app.DataOffsetRadialEditField.ValueDisplayFormat = '%.0f';
            app.DataOffsetRadialEditField.ValueChangedFcn = createCallbackFcn(app, @TrajectorySettingsEditFieldValueChanged, true);
            app.DataOffsetRadialEditField.Enable = 'off';
            app.DataOffsetRadialEditField.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.RingMethodCheckBox = uicheckbox(app.RadialTab);
            app.RingMethodCheckBox.Tooltip = {'Ring method calibration [Rosenzweig et al., MRM 81:1898-1906 (2019)]'};
            app.RingMethodCheckBox.Enable = 'off';
            app.RingMethodCheckBox.Text = '';
            app.RingMethodCheckBoxLabel = uilabel(app.RadialTab);
            app.RingMethodCheckBoxLabel.Enable = 'off';
            app.RingMethodCheckBoxLabel.Tooltip = {'Ring method calibration [Rosenzweig et al., MRM 81:1898-1906 (2019)]'};
            app.RingMethodCheckBoxLabel.Text = 'ring method';
            app.RadialTrajectoryLabel = uilabel(app.RadialTab);
            app.RadialTrajectoryLabel.Tooltip = {'2D radial trajectory'};
            app.RadialTrajectoryLabel.Text = 'Radial trajectory';
            app.TrajectoryButtonGroup = uibuttongroup(app.RadialTab);
            app.TrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrajectoryButtonGroupSelectionChanged, true);
            app.UserDefinedRadialButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.UserDefinedRadialButton.Enable = 'off';
            app.UserDefinedRadialButton.Tooltip = {'User-defined angle increment per k-step'};
            app.UserDefinedRadialButton.Text = 'user defined';
            app.FullCircleRadialButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.FullCircleRadialButton.Enable = 'off';
            app.FullCircleRadialButton.Tooltip = {'Spokes 0 - 360 degrees'};
            app.FullCircleRadialButton.Text = 'full circle';
            app.HalfCircleRadialButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.HalfCircleRadialButton.Enable = 'off';
            app.HalfCircleRadialButton.Tooltip = {'Spokes 0 - 180 degrees'};
            app.HalfCircleRadialButton.Text = 'half circle';
            app.HalfCircleRadialButton.Value = true;
            app.RadialAngleEditField = uieditfield(app.TrajectoryButtonGroup, 'numeric');
            app.RadialAngleEditField.Limits = [1 360];
            app.RadialAngleEditField.ValueDisplayFormat = '%.2f';
            app.RadialAngleEditField.Enable = 'off';
            app.RadialAngleEditField.Tooltip = {'K-space angle increment (degrees)'};
            app.RadialAngleEditField.Value = 1;
            app.PhaseCorrectCheckBox = uicheckbox(app.RadialTab);
            app.PhaseCorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrajectorySettingsEditFieldValueChanged, true);
            app.PhaseCorrectCheckBox.Tooltip = {'Check to apply phase correction'};
            app.PhaseCorrectCheckBox.Enable = 'off';
            app.PhaseCorrectCheckBox.Text = '';
            app.PhaseCorrectCheckBox.Value = true;
            app.PhaseCorrectCheckBoxLabel = uilabel(app.RadialTab);
            app.PhaseCorrectCheckBoxLabel.Enable = 'off';
            app.PhaseCorrectCheckBoxLabel.Tooltip = {'Check to apply phase correction'};
            app.PhaseCorrectCheckBoxLabel.Text = 'phase correction';
            app.CenterEchoCheckBox = uicheckbox(app.RadialTab);
            app.CenterEchoCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrajectorySettingsEditFieldValueChanged, true);
            app.CenterEchoCheckBox.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBox.Enable = 'off';
            app.CenterEchoCheckBox.Text = '';
            app.CenterEchoCheckBoxLabel = uilabel(app.RadialTab);
            app.CenterEchoCheckBoxLabel.Enable = 'off';
            app.CenterEchoCheckBoxLabel.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBoxLabel.Text = 'center echo';
            app.DensityCorrectCheckBox = uicheckbox(app.RadialTab);
            app.DensityCorrectCheckBox.Tooltip = {'Check to apply k-space density correction'};
            app.DensityCorrectCheckBox.Enable = 'off';
            app.DensityCorrectCheckBox.Text = '';
            app.DensityCorrectCheckBoxLabel = uilabel(app.RadialTab);
            app.DensityCorrectCheckBoxLabel.Enable = 'off';
            app.DensityCorrectCheckBoxLabel.Tooltip = {'Check to apply k-space density correction'};
            app.DensityCorrectCheckBoxLabel.Text = 'density correction';
            app.NavAnalysisTabGroup = uitabgroup(app.UIFigure);
            app.NavAnalysisTab = uitab(app.NavAnalysisTabGroup);
            app.NavAnalysisTab.Title = 'Navigator analysis';
            app.RespRateTimeFig = uiaxes(app.NavAnalysisTab);
            title(app.RespRateTimeFig, 'Respiration rate')
            xlabel(app.RespRateTimeFig, 'Time (s)')
            ylabel(app.RespRateTimeFig, 'Rate (bpm)')
            app.HeartRateTimeFig = uiaxes(app.NavAnalysisTab);
            title(app.HeartRateTimeFig, 'Heart rate')
            xlabel(app.HeartRateTimeFig, 'Time (s)')
            ylabel(app.HeartRateTimeFig, 'Rate (bpm)')
            app.HeartRateTimeFig.ButtonDownFcn = createCallbackFcn(app, @SpeciesButtonSelectionChanged, true);
            app.RespNavFig = uiaxes(app.NavAnalysisTab);
            title(app.RespNavFig, 'Respiration navigator')
            xlabel(app.RespNavFig, 'Time (s)')
            ylabel(app.RespNavFig, 'Signal')
            app.RespNavFig.YTick = [];
            app.HeartNavFig = uiaxes(app.NavAnalysisTab);
            title(app.HeartNavFig, 'Heart navigator')
            xlabel(app.HeartNavFig, 'Time (s)')
            ylabel(app.HeartNavFig, 'Signal')
            app.HeartNavFig.YTick = [];
            app.DisplayRangeLabel = uilabel(app.NavAnalysisTab);
            app.DisplayRangeLabel.Tooltip = {'Selects the starting point and range of the heart- and respiratory-navigator windows'};
            app.DisplayRangeLabel.Text = 'Display range';
            app.startsLabel = uilabel(app.NavAnalysisTab);
            app.startsLabel.Enable = 'off';
            app.startsLabel.Tooltip = {''};
            app.startsLabel.Text = 'start (s)';
            app.StartEditField = uieditfield(app.NavAnalysisTab, 'numeric');
            app.StartEditField.Limits = [0 Inf];
            app.StartEditField.ValueDisplayFormat = '%.2f';
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.StartEditField.Enable = 'off';
            app.StartEditField.Tooltip = {'Start of plotted navigator window'};
            app.StartEditField.Value = 120;
            app.StartSlider = uislider(app.NavAnalysisTab);
            app.StartSlider.Limits = [0 6000];
            app.StartSlider.MajorTicks = [];
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.StartSlider.MinorTicks = [0 300 600 900 1200 1500 1800 2100 2400 2700 3000 3300 3600 3900 4200 4500 4800 5100 5400 5700 6000];
            app.StartSlider.Enable = 'off';
            app.StartSlider.Tooltip = {'Start slider'};
            app.StartSlider.Value = 3000;
            app.RangeEditFieldLabel = uilabel(app.NavAnalysisTab);
            app.RangeEditFieldLabel.Enable = 'off';
            app.RangeEditFieldLabel.Tooltip = {''};
            app.RangeEditFieldLabel.Text = 'range (s)';
            app.RangeEditField = uieditfield(app.NavAnalysisTab, 'numeric');
            app.RangeEditField.Limits = [0 Inf];
            app.RangeEditField.ValueDisplayFormat = '%.2f';
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.RangeEditField.Enable = 'off';
            app.RangeEditField.Tooltip = {'Range of plotted navigator window'};
            app.RangeSlider = uislider(app.NavAnalysisTab);
            app.RangeSlider.Limits = [0 2000];
            app.RangeSlider.MajorTicks = [];
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.RangeSlider.MinorTicks = [0 100 200 300 400 500 600 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 1900 2000];
            app.RangeSlider.Enable = 'off';
            app.RangeSlider.Tooltip = {'Range slider'};
            app.RangeSlider.Value = 1000;
            app.NavStartButton = uibutton(app.NavAnalysisTab, 'push');
            app.NavStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartNavTimeButtonPushed, true);
            app.NavStartButton.Enable = 'off';
            app.NavStartButton.Tooltip = {'Start navigator-time movie'};
            app.NavStartButton.Text = ' ';
            app.NavStopButton = uibutton(app.NavAnalysisTab, 'push');
            app.NavStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopNavTimeButtonPushed, true);
            app.NavStopButton.Enable = 'off';
            app.NavStopButton.Tooltip = {'Stop navigator-time movie'};
            app.NavStopButton.Text = ' ';
            app.NavFastSlowSwitch = uiswitch(app.NavAnalysisTab, 'rocker');
            app.NavFastSlowSwitch.Items = {'slow', 'fast'};
            app.NavFastSlowSwitch.ValueChangedFcn = createCallbackFcn(app, @NavFastSlowSwitchValueChanged, true);
            app.NavFastSlowSwitch.Enable = 'off';
            app.NavFastSlowSwitch.Tooltip = {'Navigator movie fast or slow'};
            app.NavFastSlowSwitch.Value = 'slow';
            app.IncludeCheckBox = uicheckbox(app.NavAnalysisTab);
            app.IncludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeCheckBoxValueChanged, true);
            app.IncludeCheckBox.Tooltip = {'Switch include window on/off'};
            app.IncludeCheckBox.Enable = 'off';
            app.IncludeCheckBox.Text = '';
            app.IncludeCheckBoxLabel = uilabel(app.NavAnalysisTab);
            app.IncludeCheckBoxLabel.Enable = 'off';
            app.IncludeCheckBoxLabel.Tooltip = {'Windows the data to reconstruct only part of the acquisition. '; 'Only the green selection area will be included. '; 'Window can be adjusted with the sliders.'};
            app.IncludeCheckBoxLabel.Text = 'Include';
            app.IncludeStartSlider = uislider(app.NavAnalysisTab);
            app.IncludeStartSlider.Limits = [1 6000];
            app.IncludeStartSlider.MajorTicks = [];
            app.IncludeStartSlider.ValueChangedFcn = createCallbackFcn(app, @IncludeSliderValueChanged, true);
            app.IncludeStartSlider.MinorTicks = [1 301 601 901 1201 1501 1801 2101 2401 2701 3001 3301 3601 3901 4201 4501 4801 5101 5401 5701 6000];
            app.IncludeStartSlider.Enable = 'off';
            app.IncludeStartSlider.Tooltip = {'Include data start'};
            app.IncludeStartSlider.Value = 1000;
            app.IncludeEndSlider = uislider(app.NavAnalysisTab);
            app.IncludeEndSlider.Limits = [1 6000];
            app.IncludeEndSlider.MajorTicks = [];

            app.IncludeEndSlider.ValueChangedFcn = createCallbackFcn(app, @IncludeSliderValueChanged, true);
            app.IncludeEndSlider.MinorTicks = [1 301 601 901 1201 1501 1801 2101 2401 2701 3001 3301 3601 3901 4201 4501 4801 5101 5401 5701 6000];
            app.IncludeEndSlider.Enable = 'off';
            app.IncludeEndSlider.Tooltip = {'Include data end'};
            app.IncludeEndSlider.Value = 1000;
            app.ExcludeCheckBox = uicheckbox(app.NavAnalysisTab);
            app.ExcludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeCheckBoxValueChanged, true);
            app.ExcludeCheckBox.Tooltip = {'Switch include window on/off'};
            app.ExcludeCheckBox.Enable = 'off';
            app.ExcludeCheckBox.Text = '';
            app.ExcludeCheckBoxLabel = uilabel(app.NavAnalysisTab);
            app.ExcludeCheckBoxLabel.Enable = 'off';
            app.ExcludeCheckBoxLabel.Tooltip = {'Windows the data to exclude part of the acquisition. '; 'The red selection area will be excluded. '; 'Window can be adjusted with the sliders.'};
            app.ExcludeCheckBoxLabel.Text = 'Exclude';
            app.ExcludeStartSlider = uislider(app.NavAnalysisTab);
            app.ExcludeStartSlider.Limits = [1 6000];
            app.ExcludeStartSlider.MajorTicks = [];
            app.ExcludeStartSlider.ValueChangedFcn = createCallbackFcn(app, @ExcludeSliderValueChanged, true);
            app.ExcludeStartSlider.MinorTicks = [1 301 601 901 1201 1501 1801 2101 2401 2701 3001 3301 3601 3901 4201 4501 4801 5101 5401 5701 6000];
            app.ExcludeStartSlider.Enable = 'off';
            app.ExcludeStartSlider.Tooltip = {'Exclude data start'};
            app.ExcludeStartSlider.Value = 1000;
            app.ExcludeEndSlider = uislider(app.NavAnalysisTab);
            app.ExcludeEndSlider.Limits = [1 6000];
            app.ExcludeEndSlider.MajorTicks = [];
            app.ExcludeEndSlider.ValueChangedFcn = createCallbackFcn(app, @ExcludeSliderValueChanged, true);
            app.ExcludeEndSlider.MinorTicks = [1 301 601 901 1201 1501 1801 2101 2401 2701 3001 3301 3601 3901 4201 4501 4801 5101 5401 5701 6000];
            app.ExcludeEndSlider.Enable = 'off';
            app.ExcludeEndSlider.Tooltip = {'Exclude data end'};
            app.ExcludeEndSlider.Value = 1000;
            app.NavTabGroup = uitabgroup(app.UIFigure);
            app.NavTab = uitab(app.NavTabGroup);
            app.NavTab.Title = 'Navigator';
            app.Nav2Fig = uiaxes(app.NavTab);
            title(app.Nav2Fig, 'Respiration navigator')
            xlabel(app.Nav2Fig, 'Samples')
            ylabel(app.Nav2Fig, 'Signal')
            app.Nav2Fig.YTick = [];
            app.Nav1Fig = uiaxes(app.NavTab);
            title(app.Nav1Fig, 'Heart navigator')
            xlabel(app.Nav1Fig, 'Samples')
            ylabel(app.Nav1Fig, 'Signal')
            app.Nav1Fig.YTick = [];
            app.NavEnd1EditFieldLabel = uilabel(app.NavTab);
            app.NavEnd1EditFieldLabel.Enable = 'off';
            app.NavEnd1EditFieldLabel.Tooltip = {'Primary navigator point.'};
            app.NavEnd1EditFieldLabel.Text = 'primary';
            app.NavEnd1EditField = uieditfield(app.NavTab, 'numeric');
            app.NavEnd1EditField.Limits = [1 1024];
            app.NavEnd1EditField.ValueDisplayFormat = '%.0f';
            app.NavEnd1EditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavEnd1EditField.Enable = 'off';
            app.NavEnd1EditField.Tooltip = {'Primary navigator point analyzed'};
            app.NavEnd1EditField.Value = 10;
            app.NavNRPoints1EditFieldLabel = uilabel(app.NavTab);
            app.NavNRPoints1EditFieldLabel.Enable = 'off';
            app.NavNRPoints1EditFieldLabel.Tooltip = {'Number of points that are considered for navigator analysis.'};
            app.NavNRPoints1EditFieldLabel.Text = '# points';
            app.NavNRPoints1EditField = uieditfield(app.NavTab, 'numeric');
            app.NavNRPoints1EditField.Limits = [1 24];
            app.NavNRPoints1EditField.ValueDisplayFormat = '%.0f';
            app.NavNRPoints1EditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavNRPoints1EditField.Enable = 'off';
            app.NavNRPoints1EditField.Tooltip = {'Number of navigator points analyzed'};
            app.NavNRPoints1EditField.Value = 1;
            app.Nav1ButtonGroup = uibuttongroup(app.NavTab);
            app.Nav1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NavButtonGroupSelectionChanged, true);
            app.SliceFID1Button = uiradiobutton(app.Nav1ButtonGroup);
            app.SliceFID1Button.Enable = 'off';
            app.SliceFID1Button.Tooltip = {'Slice FID navigator'};
            app.SliceFID1Button.Text = 'slice FID';
            app.SliceFID1Button.Value = true;
            app.ExtFID1Button = uiradiobutton(app.Nav1ButtonGroup);
            app.ExtFID1Button.Enable = 'off';
            app.ExtFID1Button.Tooltip = {'External FID navigator'};
            app.ExtFID1Button.Text = 'external FID';
            app.ExtECHO1Button = uiradiobutton(app.Nav1ButtonGroup);
            app.ExtECHO1Button.Enable = 'off';
            app.ExtECHO1Button.Tooltip = {'External ECHO navigator (readout profile)'};
            app.ExtECHO1Button.Text = 'external ECHO';
            app.Nav2ButtonGroup = uibuttongroup(app.NavTab);
            app.Nav2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NavButtonGroupSelectionChanged, true);
            app.SliceFID2Button = uiradiobutton(app.Nav2ButtonGroup);
            app.SliceFID2Button.Enable = 'off';
            app.SliceFID2Button.Tooltip = {'Slice FID navigator'};
            app.SliceFID2Button.Text = 'slice FID';
            app.SliceFID2Button.Value = true;
            app.ExtFID2Button = uiradiobutton(app.Nav2ButtonGroup);
            app.ExtFID2Button.Enable = 'off';
            app.ExtFID2Button.Tooltip = {'External FID navigator'};
            app.ExtFID2Button.Text = 'external FID';
            app.ExtECHO2Button = uiradiobutton(app.Nav2ButtonGroup);
            app.ExtECHO2Button.Enable = 'off';
            app.ExtECHO2Button.Tooltip = {'External ECHO navigator (readout profile)'};
            app.ExtECHO2Button.Text = 'external ECHO';
            app.NavEnd2EditFieldLabel = uilabel(app.NavTab);
            app.NavEnd2EditFieldLabel.Enable = 'off';
            app.NavEnd2EditFieldLabel.Tooltip = {'Primary navigator point.'};
            app.NavEnd2EditFieldLabel.Text = 'primary';
            app.NavEnd2EditField = uieditfield(app.NavTab, 'numeric');
            app.NavEnd2EditField.Limits = [1 1024];
            app.NavEnd2EditField.ValueDisplayFormat = '%.0f';
            app.NavEnd2EditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavEnd2EditField.Enable = 'off';
            app.NavEnd2EditField.Tooltip = {'Primary navigator point analyzed'};
            app.NavEnd2EditField.Value = 10;
            app.NavNRPoints2EditFieldLabel = uilabel(app.NavTab);
            app.NavNRPoints2EditFieldLabel.Enable = 'off';
            app.NavNRPoints2EditFieldLabel.Tooltip = {'Number of points that are considered for navigator analysis.'};
            app.NavNRPoints2EditFieldLabel.Text = '# points';
            app.NavNRPoints2EditField = uieditfield(app.NavTab, 'numeric');
            app.NavNRPoints2EditField.Limits = [1 24];
            app.NavNRPoints2EditField.ValueDisplayFormat = '%.0f';
            app.NavNRPoints2EditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavNRPoints2EditField.Enable = 'off';
            app.NavNRPoints2EditField.Tooltip = {'Number of navigator points analyzed'};
            app.NavNRPoints2EditField.Value = 1;
            app.StartMotionButton = uibutton(app.NavTab, 'push');
            app.StartMotionButton.ButtonPushedFcn = createCallbackFcn(app, @StartRawNavButtonPushed, true);
            app.StartMotionButton.Enable = 'off';
            app.StartMotionButton.Tooltip = {'Start raw navigator movie'};
            app.StartMotionButton.Text = ' ';
            app.StopMotionButton = uibutton(app.NavTab, 'push');
            app.StopMotionButton.ButtonPushedFcn = createCallbackFcn(app, @StopRawNavButtonPushed, true);
            app.StopMotionButton.Enable = 'off';
            app.StopMotionButton.Tooltip = {'Stop raw navigator movie'};
            app.StopMotionButton.Text = ' ';
            app.RespCoilSpinner1 = uispinner(app.NavTab);
            app.RespCoilSpinner1.Limits = [1 16];
            app.RespCoilSpinner1.ValueDisplayFormat = '%.0f';
            app.RespCoilSpinner1.ValueChangedFcn = createCallbackFcn(app, @NavCoilSpinnerValueChanged, true);
            app.RespCoilSpinner1.Enable = 'off';
            app.RespCoilSpinner1.Tooltip = {'RF receiver coil number'};
            app.RespCoilSpinner1.Value = 1;
            app.RespCoilSpinner1Label = uilabel(app.NavTab);
            app.RespCoilSpinner1Label.Enable = 'off';
            app.RespCoilSpinner1Label.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.RespCoilSpinner1Label.Text = 'coil';
            app.HeartCoilSpinner1 = uispinner(app.NavTab);
            app.HeartCoilSpinner1.Limits = [1 16];
            app.HeartCoilSpinner1.ValueDisplayFormat = '%.0f';
            app.HeartCoilSpinner1.ValueChangedFcn = createCallbackFcn(app, @NavCoilSpinnerValueChanged, true);
            app.HeartCoilSpinner1.Enable = 'off';
            app.HeartCoilSpinner1.Tooltip = {'RF receiver coil number'};
            app.HeartCoilSpinner1.Value = 1;
            app.HeartCoilSpinner1Label = uilabel(app.NavTab);
            app.HeartCoilSpinner1Label.Enable = 'off';
            app.HeartCoilSpinner1Label.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.HeartCoilSpinner1Label.Text = 'coil';
            app.HeartTab = uitab(app.NavTabGroup);
            app.HeartTab.Tooltip = {'Flip navigator signal'};
            app.HeartTab.Title = 'Heart';
            app.HeartRawNavFig = uiaxes(app.HeartTab);
            title(app.HeartRawNavFig, 'Raw navigator')
            xlabel(app.HeartRawNavFig, 'Time (s)')
            ylabel(app.HeartRawNavFig, 'Signal')
            app.HeartRawNavFig.YTick = [];
            app.HeartSpectrumFig = uiaxes(app.HeartTab);
            title(app.HeartSpectrumFig, 'Navigator Spectrum')
            xlabel(app.HeartSpectrumFig, 'Frequency (bpm)')
            ylabel(app.HeartSpectrumFig, 'Power')
            app.HeartCoilSpinner2 = uispinner(app.HeartTab);
            app.HeartCoilSpinner2.Limits = [1 16];
            app.HeartCoilSpinner2.ValueDisplayFormat = '%.0f';
            app.HeartCoilSpinner2.ValueChangedFcn = createCallbackFcn(app, @NavCoilSpinnerValueChanged, true);
            app.HeartCoilSpinner2.Enable = 'off';
            app.HeartCoilSpinner2.Tooltip = {'RF receiver coil number'};
            app.HeartCoilSpinner2.Value = 1;
            app.HeartCoilSpinner2Label = uilabel(app.HeartTab);
            app.HeartCoilSpinner2Label.Enable = 'off';
            app.HeartCoilSpinner2Label.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.HeartCoilSpinner2Label.Text = 'coil';
            app.HeartLogLinButton = uibutton(app.HeartTab, 'push');
            app.HeartLogLinButton.ButtonPushedFcn = createCallbackFcn(app, @HeartLogLinButtonPushed, true);
            app.HeartLogLinButton.Enable = 'off';
            app.HeartLogLinButton.Text = 'lin';
            app.HeartNavFlipSwitchLabel = uilabel(app.HeartTab);
            app.HeartNavFlipSwitchLabel.Enable = 'off';
            app.HeartNavFlipSwitchLabel.Text = 'flip';
            app.HeartNavFlipSwitch = uiswitch(app.HeartTab, 'toggle');
            app.HeartNavFlipSwitch.Items = {'Down', 'Up'};
            app.HeartNavFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.HeartNavFlipSwitch.Enable = 'off';
            app.HeartNavFlipSwitch.Tooltip = {'Flip navigator signal'};
            app.HeartNavFlipSwitch.Value = 'Down';
            app.HeartMinEditFieldLabel = uilabel(app.HeartTab);
            app.HeartMinEditFieldLabel.Enable = 'off';
            app.HeartMinEditFieldLabel.Text = 'min';
            app.HeartMinEditField = uieditfield(app.HeartTab, 'numeric');
            app.HeartMinEditField.Limits = [0 1000];
            app.HeartMinEditField.RoundFractionalValues = 'on';
            app.HeartMinEditField.ValueDisplayFormat = '%.0f';
            app.HeartMinEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumEditFieldValueChanged, true);
            app.HeartMinEditField.Enable = 'off';
            app.HeartMinEditField.Tooltip = {'Minimum frequency'};
            app.HeartMinEditField.Value = 300;
            app.HeartMaxEditFieldLabel = uilabel(app.HeartTab);
            app.HeartMaxEditFieldLabel.Enable = 'off';
            app.HeartMaxEditFieldLabel.Text = 'max';
            app.HeartMaxEditField = uieditfield(app.HeartTab, 'numeric');
            app.HeartMaxEditField.Limits = [0 1000];
            app.HeartMaxEditField.RoundFractionalValues = 'on';
            app.HeartMaxEditField.ValueDisplayFormat = '%.0f';
            app.HeartMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumEditFieldValueChanged, true);
            app.HeartMaxEditField.Enable = 'off';
            app.HeartMaxEditField.Tooltip = {'Maximum frequency'};
            app.HeartMaxEditField.Value = 700;
            app.RespTab = uitab(app.NavTabGroup);
            app.RespTab.Title = 'Respiration';
            app.RespRawNavFig = uiaxes(app.RespTab);
            title(app.RespRawNavFig, 'Raw navigator')
            xlabel(app.RespRawNavFig, 'Time (s)')
            ylabel(app.RespRawNavFig, 'Signal')
            app.RespRawNavFig.YTick = [];
            app.RespSpectrumFig = uiaxes(app.RespTab);
            title(app.RespSpectrumFig, 'Navigator Spectrum')
            xlabel(app.RespSpectrumFig, 'Frequency (bpm)')
            ylabel(app.RespSpectrumFig, 'Power')
            app.RespCoilSpinner2 = uispinner(app.RespTab);
            app.RespCoilSpinner2.Limits = [1 16];
            app.RespCoilSpinner2.ValueDisplayFormat = '%.0f';
            app.RespCoilSpinner2.ValueChangedFcn = createCallbackFcn(app, @NavCoilSpinnerValueChanged, true);
            app.RespCoilSpinner2.Enable = 'off';
            app.RespCoilSpinner2.Tooltip = {'RF receiver coil number'};
            app.RespCoilSpinner2.Value = 1;
            app.RespCoilSpinner2Label = uilabel(app.RespTab);
            app.RespCoilSpinner2Label.Enable = 'off';
            app.RespCoilSpinner2Label.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.RespCoilSpinner2Label.Text = 'coil';
            app.RespLogLinButton = uibutton(app.RespTab, 'push');
            app.RespLogLinButton.ButtonPushedFcn = createCallbackFcn(app, @RespLogLinButtonPushed, true);
            app.RespLogLinButton.Enable = 'off';
            app.RespLogLinButton.Text = 'lin';
            app.RespNavFlipSwitchLabel = uilabel(app.RespTab);
            app.RespNavFlipSwitchLabel.Enable = 'off';
            app.RespNavFlipSwitchLabel.Text = 'flip';
            app.RespNavFlipSwitch = uiswitch(app.RespTab, 'toggle');
            app.RespNavFlipSwitch.Items = {'Down', 'Up'};
            app.RespNavFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.RespNavFlipSwitch.Enable = 'off';
            app.RespNavFlipSwitch.Tooltip = {'Flip navigator signal'};
            app.RespNavFlipSwitch.Value = 'Down';
            app.RespMinEditFieldLabel = uilabel(app.RespTab);
            app.RespMinEditFieldLabel.Enable = 'off';
            app.RespMinEditFieldLabel.Text = 'min';
            app.RespMinEditField = uieditfield(app.RespTab, 'numeric');
            app.RespMinEditField.Limits = [0 1000];
            app.RespMinEditField.RoundFractionalValues = 'on';
            app.RespMinEditField.ValueDisplayFormat = '%.0f';
            app.RespMinEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumEditFieldValueChanged, true);
            app.RespMinEditField.Enable = 'off';
            app.RespMinEditField.Tooltip = {'Minimum frequency'};
            app.RespMinEditField.Value = 10;
            app.RespMaxEditFieldLabel = uilabel(app.RespTab);
            app.RespMaxEditFieldLabel.Enable = 'off';
            app.RespMaxEditFieldLabel.Text = 'max';
            app.RespMaxEditField = uieditfield(app.RespTab, 'numeric');
            app.RespMaxEditField.Limits = [0 1000];
            app.RespMaxEditField.RoundFractionalValues = 'on';
            app.RespMaxEditField.ValueDisplayFormat = '%.0f';
            app.RespMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumEditFieldValueChanged, true);
            app.RespMaxEditField.Enable = 'off';
            app.RespMaxEditField.Tooltip = {'Maximum frequency'};
            app.RespMaxEditField.Value = 200;
            app.NavSettingsTab = uitab(app.NavTabGroup);
            app.NavSettingsTab.Title = 'Settings';
            app.DiscardEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.DiscardEditField.Limits = [0 1024];
            app.DiscardEditField.ValueDisplayFormat = '%.0f';
            app.DiscardEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.DiscardEditField.Enable = 'off';
            app.DiscardEditField.Tooltip = {'Number of frequency encoding points discarded after navigator. Increase if FID artifact is present in movie.'};
            app.DiscardEditField.Value = 35;
            app.RespToggleCheckBox = uicheckbox(app.NavSettingsTab);
            app.RespToggleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RespirationToggleCheckBoxValueChanged, true);
            app.RespToggleCheckBox.Tooltip = {'Reconstruct cardiac CINE frames in inspiration (not checked) or expiration (checked)'};
            app.RespToggleCheckBox.Enable = 'off';
            app.RespToggleCheckBox.Text = '';
            app.IgnoreRespToggleCheckBox = uicheckbox(app.NavSettingsTab);
            app.IgnoreRespToggleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RespirationToggleCheckBoxValueChanged, true);
            app.IgnoreRespToggleCheckBox.Tooltip = {'Check to ignore respiration and use all data'};
            app.IgnoreRespToggleCheckBox.Enable = 'off';
            app.IgnoreRespToggleCheckBox.Text = '';
            app.AutoSoundsCheckBox = uicheckbox(app.NavSettingsTab);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.MessageLevelSpinner = uispinner(app.NavSettingsTab);
            app.MessageLevelSpinner.Limits = [1 3];
            app.MessageLevelSpinner.Value = 1;
            app.ColorsButtonGroup = uibuttongroup(app.NavSettingsTab);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Value = true;
            app.PresetsLabel = uilabel(app.NavSettingsTab);
            app.PresetsLabel.Text = 'Presets';
            app.SpeciesButton = uibuttongroup(app.NavSettingsTab);
            app.SpeciesButton.SelectionChangedFcn = createCallbackFcn(app, @SpeciesButtonSelectionChanged, true);
            app.SpeciesButton.Tooltip = {'Preset navigator filter values for mouse and rat'};
            app.SpeciesButton.ButtonDownFcn = createCallbackFcn(app, @SpeciesButtonButtonDown, true);
            app.MouseButton = uiradiobutton(app.SpeciesButton);
            app.MouseButton.Enable = 'off';
            app.MouseButton.Tooltip = {'Mouse preset navigator filter settings'};
            app.MouseButton.Text = 'mouse';
            app.MouseButton.Value = true;
            app.RatButton = uiradiobutton(app.SpeciesButton);
            app.RatButton.Enable = 'off';
            app.RatButton.Tooltip = {'Rat preset navigator filter settings'};
            app.RatButton.Text = 'rat';
            app.ZebraFishButton = uiradiobutton(app.SpeciesButton);
            app.ZebraFishButton.Enable = 'off';
            app.ZebraFishButton.Tooltip = {'Zebrafish preset navigator filter settings'};
            app.ZebraFishButton.Text = 'zebrafish';
            app.FilterOrderEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.FilterOrderEditField.Limits = [1 6];
            app.FilterOrderEditField.ValueDisplayFormat = '%.0f';
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.FilterOrderEditField.Enable = 'off';
            app.FilterOrderEditField.Tooltip = {'Butterworth filter order'};
            app.FilterOrderEditField.Value = 4;
            app.MinHeartRateEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.MinHeartRateEditField.Limits = [1 1000];
            app.MinHeartRateEditField.ValueDisplayFormat = '%.0f';
            app.MinHeartRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MinHeartRateEditField.Enable = 'off';
            app.MinHeartRateEditField.Tooltip = {'Minimum of search range heart rate'};
            app.MinHeartRateEditField.Value = 100;
            app.MaxHeartRateEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.MaxHeartRateEditField.Limits = [1 1000];
            app.MaxHeartRateEditField.ValueDisplayFormat = '%.0f';
            app.MaxHeartRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MaxHeartRateEditField.Enable = 'off';
            app.MaxHeartRateEditField.Tooltip = {'Maxiumum of search range heart rate'};
            app.MaxHeartRateEditField.Value = 1000;
            app.MinRespRateEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.MinRespRateEditField.Limits = [1 1000];
            app.MinRespRateEditField.ValueDisplayFormat = '%.0f';
            app.MinRespRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MinRespRateEditField.Enable = 'off';
            app.MinRespRateEditField.Tooltip = {'Minimum search range respiration rate'};
            app.MinRespRateEditField.Value = 5;
            app.MaxRespRateEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.MaxRespRateEditField.Limits = [1 1000];
            app.MaxRespRateEditField.ValueDisplayFormat = '%.0f';
            app.MaxRespRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MaxRespRateEditField.Enable = 'off';
            app.MaxRespRateEditField.Tooltip = {'Maximum search range respiration rate'};
            app.MaxRespRateEditField.Value = 400;
            app.RespShiftEditField = uieditfield(app.NavSettingsTab, 'numeric');
            app.RespShiftEditField.Limits = [-100 100];
            app.RespShiftEditField.ValueDisplayFormat = '%.0f';
            app.RespShiftEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.RespShiftEditField.Enable = 'off';
            app.RespShiftEditField.Tooltip = {'Shifts the respiration window left or right'};
            app.FilterPerSliceCheckBox = uicheckbox(app.NavSettingsTab);
            app.FilterPerSliceCheckBox.Tooltip = {'Filter cardiac and respiratory motion for each slice separately'};
            app.FilterPerSliceCheckBox.Enable = 'off';
            app.FilterPerSliceCheckBox.Text = '';
            app.PhysioFilteringLabel = uilabel(app.NavSettingsTab);
            app.PhysioFilteringLabel.Text = 'Physio filtering';
            app.ColorsLabel = uilabel(app.NavSettingsTab);
            app.ColorsLabel.Text = 'Colors';
            app.SoundMessagesLabel = uilabel(app.NavSettingsTab);
            app.SoundMessagesLabel.Text = 'Sounds/messages';
            app.RespLabel = uilabel(app.NavSettingsTab);
            app.RespLabel.Text = 'Respiration';
            app.NavLabel2 = uilabel(app.NavSettingsTab);
            app.NavLabel2.Text = 'Navigator';
            app.DiscardEditFieldLabel = uilabel(app.NavSettingsTab);
            app.DiscardEditFieldLabel.Enable = 'off';
            app.DiscardEditFieldLabel.Text = 'freq Nx discard';
            app.RespToggleCheckBoxLabel = uilabel(app.NavSettingsTab);
            app.RespToggleCheckBoxLabel.Enable = 'off';
            app.RespToggleCheckBoxLabel.Tooltip = {'Reconstruct cardiac CINE frames in inspiration (not checked) or expiration (checked)'};
            app.RespToggleCheckBoxLabel.Text = 'toggle in/expiration';
            app.IgnoreRespToggleCheckBoxLabel = uilabel(app.NavSettingsTab);
            app.IgnoreRespToggleCheckBoxLabel.Enable = 'off';
            app.IgnoreRespToggleCheckBoxLabel.Tooltip = {'Check to ignore respiration and use all data'};
            app.IgnoreRespToggleCheckBoxLabel.Text = 'ignore respiration';
            app.AutoSoundsCheckBoxLabel = uilabel(app.NavSettingsTab);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.MessageLevelSpinnerLabel = uilabel(app.NavSettingsTab);
            app.MessageLevelSpinnerLabel.Text = 'message level';
            app.FilterOrderEditFieldLabel = uilabel(app.NavSettingsTab);
            app.FilterOrderEditFieldLabel.Enable = 'off';
            app.FilterOrderEditFieldLabel.Text = 'filter order';
            app.MinHeartRateEditFieldLabel = uilabel(app.NavSettingsTab);
            app.MinHeartRateEditFieldLabel.Enable = 'off';
            app.MinHeartRateEditFieldLabel.Text = 'min heart rate';
            app.MaxHeartRateEditFieldLabel = uilabel(app.NavSettingsTab);
            app.MaxHeartRateEditFieldLabel.Enable = 'off';
            app.MaxHeartRateEditFieldLabel.Text = 'max heart rate';
            app.MinRespRateEditFieldLabel = uilabel(app.NavSettingsTab);
            app.MinRespRateEditFieldLabel.Enable = 'off';
            app.MinRespRateEditFieldLabel.Text = 'min resp rate';
            app.MaxRespRateEditFieldLabel = uilabel(app.NavSettingsTab);
            app.MaxRespRateEditFieldLabel.Enable = 'off';
            app.MaxRespRateEditFieldLabel.Text = 'max resp rate';
            app.RespShiftEditFieldLabel = uilabel(app.NavSettingsTab);
            app.RespShiftEditFieldLabel.Enable = 'off';
            app.RespShiftEditFieldLabel.Text = 'resp shift (samples)';
            app.FilterPerSliceLabel = uilabel(app.NavSettingsTab);
            app.FilterPerSliceLabel.Enable = 'off';
            app.FilterPerSliceLabel.Tooltip = {'Filter cardiac and respiratory motion for each slice separately'};
            app.FilterPerSliceLabel.Text = 'filter per slice';
            app.DashboardTabGroup = uitabgroup(app.UIFigure);
            app.DashboardTab = uitab(app.DashboardTabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.Lamp2 = uilamp(app.DashboardTab);
            app.Lamp3 = uilamp(app.DashboardTab);
            app.Lamp4 = uilamp(app.DashboardTab);
            app.Lamp5 = uilamp(app.DashboardTab);
            app.Lamp6 = uilamp(app.DashboardTab);
            app.Lamp7 = uilamp(app.DashboardTab);
            app.DetectedRatesBpmPanel = uipanel(app.DashboardTab);
            app.DetectedRatesBpmPanel.Title = 'Heart / respiration filters';
            app.HeartEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.HeartEditField.Limits = [1 1000];
            app.HeartEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartEditField.Enable = 'off';
            app.HeartEditField.Tooltip = {'Heart rate estimate'};
            app.HeartEditField.Value = 500;
            app.HeartWidthEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.HeartWidthEditField.Limits = [20 300];
            app.HeartWidthEditField.ValueDisplayFormat = '%.0f';
            app.HeartWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartWidthEditField.Enable = 'off';
            app.HeartWidthEditField.Tooltip = {'Heart rate variation estimate'};
            app.HeartWidthEditField.Value = 100;
            app.RespEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.RespEditField.Limits = [1 1000];
            app.RespEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespEditField.Enable = 'off';
            app.RespEditField.Tooltip = {'Respiration rate estimate'};
            app.RespEditField.Value = 50;
            app.RespWidthEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.RespWidthEditField.Limits = [5 100];
            app.RespWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespWidthEditField.Enable = 'off';
            app.RespWidthEditField.Tooltip = {'Respiration rate variation estimate'};
            app.RespWidthEditField.Value = 40;
            app.RespWindowEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.RespWindowEditField.Limits = [5 95];
            app.RespWindowEditField.ValueDisplayFormat = '%.0f';
            app.RespWindowEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespWindowEditField.Enable = 'off';
            app.RespWindowEditField.Tooltip = {'Respiration discard window width in percentage of respiration period'};
            app.RespWindowEditField.Value = 30;
            app.ResetFilterValuesButton = uibutton(app.DetectedRatesBpmPanel, 'push');
            app.ResetFilterValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFilterValuesButtonPushed, true);
            app.ResetFilterValuesButton.Enable = 'off';
            app.ResetFilterValuesButton.Tooltip = {'Reset filters'};
            app.ResetFilterValuesButton.Text = 'reset';
            app.CenterLabel = uilabel(app.DetectedRatesBpmPanel);
            app.CenterLabel.Enable = 'off';
            app.CenterLabel.Text = 'center';
            app.WidthLabel = uilabel(app.DetectedRatesBpmPanel);
            app.WidthLabel.Enable = 'off';
            app.WidthLabel.Text = 'width';
            app.HeartEditFieldLabel = uilabel(app.DetectedRatesBpmPanel);
            app.HeartEditFieldLabel.Enable = 'off';
            app.HeartEditFieldLabel.Text = 'heart (bpm)';
            app.RespEditFieldLabel = uilabel(app.DetectedRatesBpmPanel);
            app.RespEditFieldLabel.Enable = 'off';
            app.RespEditFieldLabel.Text = 'resp. (bpm)';
            app.RespWindowEditFieldLabel = uilabel(app.DetectedRatesBpmPanel);
            app.RespWindowEditFieldLabel.Enable = 'off';
            app.RespWindowEditFieldLabel.Text = 'window (%)';
            app.CinePanel = uipanel(app.DashboardTab);
            app.CinePanel.Title = 'Cine';
            app.RecoTypeDropDown = uidropdown(app.CinePanel);
            app.RecoTypeDropDown.Items = {'cardiac', 'cardiac abs', 'respiratory', 'realtime'};
            app.RecoTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.RecoTypeDropDown.Enable = 'off';
            app.RecoTypeDropDown.Tooltip = {'Data sorting:'; '- cardiac phase binning'; '- cardiac absolute binning'; '- respiratory phase binning'; '- realtime, 1 dynamic per heart beat'};
            app.RecoTypeDropDown.Value = 'cardiac';
            app.FramesEditField = uieditfield(app.CinePanel, 'numeric');
            app.FramesEditField.Limits = [1 512];
            app.FramesEditField.RoundFractionalValues = 'on';
            app.FramesEditField.ValueDisplayFormat = '%.0f';
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.FramesEditField.Enable = 'off';
            app.FramesEditField.Tooltip = {'Number of desired CINE frames'};
            app.FramesEditField.Value = 12;
            app.DynamicsEditField = uieditfield(app.CinePanel, 'numeric');
            app.DynamicsEditField.Limits = [1 Inf];
            app.DynamicsEditField.RoundFractionalValues = 'on';
            app.DynamicsEditField.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.DynamicsEditField.Enable = 'off';
            app.DynamicsEditField.Tooltip = {'Number of desired dynamics'};
            app.DynamicsEditField.Value = 1;
            app.RecoTypeDropDownLabel = uilabel(app.CinePanel);
            app.RecoTypeDropDownLabel.Enable = 'off';
            app.RecoTypeDropDownLabel.Text = 'movie';
            app.FramesEditFieldLabel = uilabel(app.CinePanel);
            app.FramesEditFieldLabel.Enable = 'off';
            app.FramesEditFieldLabel.Text = '# CINE frames';
            app.DynamicsEditFieldLabel = uilabel(app.CinePanel);
            app.DynamicsEditFieldLabel.Enable = 'off';
            app.DynamicsEditFieldLabel.Tooltip = {'Number of desired dynamics'};
            app.DynamicsEditFieldLabel.Text = '# dynamics';
            app.ReconstructionPanel = uipanel(app.DashboardTab);
            app.ReconstructionPanel.Title = 'Reconstruction';
            app.ExportPanel = uipanel(app.DashboardTab);
            app.ExportPanel.Title = 'Export';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDButton.Enable = 'off';
            app.LoadMRDButton.Tooltip = {'Select MRD file - '; 'for multicoil data select one of the MRD files'};
            app.LoadMRDButton.Text = ' Load MRD';
            app.FilterButton = uibutton(app.DashboardTab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Tooltip = {'Filters the navigator signal to extract cardiac and respiratory triggering points'};
            app.FilterButton.Text = ' Filter';
            app.SortKspaceButton = uibutton(app.DashboardTab, 'push');
            app.SortKspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SortKspaceButtonPushed, true);
            app.SortKspaceButton.Enable = 'off';
            app.SortKspaceButton.Tooltip = {'Sort the data in the desired number of cardiac or respiratory frames'};
            app.SortKspaceButton.Text = 'Sort k-space';
            app.ReconstructButton = uibutton(app.DashboardTab, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @RecoMoviesButtonPushed, true);
            app.ReconstructButton.Enable = 'off';
            app.ReconstructButton.Tooltip = {'Reconstruct the CINE movie'};
            app.ReconstructButton.Text = 'Reconstruct';
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export movie(s) to DICOM'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export movie(s) to animated GIF'};
            app.ExportGIFButton.Text = 'Export GIF';
            app.ExportMRDButton = uibutton(app.DashboardTab, 'push');
            app.ExportMRDButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMRDButtonPushed, true);
            app.ExportMRDButton.Enable = 'off';
            app.ExportMRDButton.Tooltip = {'Export movie(s) to a new MRD file'};
            app.ExportMRDButton.Text = 'Export MRD';
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = '     Exit';
            app.ProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.ProgressGauge.MajorTicks = [0 25 50 75 100];
            app.ReconstructionTypeButtonGroup = uibuttongroup(app.DashboardTab);
            app.CompressedSensingButton = uiradiobutton(app.ReconstructionTypeButtonGroup);
            app.CompressedSensingButton.Enable = 'off';
            app.CompressedSensingButton.Tooltip = {'Compressed sensing reconstruction'};
            app.CompressedSensingButton.Text = 'Compressed Sensing';
            app.CompressedSensingButton.Value = true;
            app.DeepLearningButton = uiradiobutton(app.ReconstructionTypeButtonGroup);
            app.DeepLearningButton.Enable = 'off';
            app.DeepLearningButton.Tooltip = {'Dynamic recurrent inference machine reconstruction.'; 'Requires 2D data and #frames > 4'};
            app.DeepLearningButton.Text = 'Deep Learning';
            app.RetrospectiveCreditLabel = uilabel(app.UIFigure);
            app.RetrospectiveCreditLabel.Text = 'Retrospective can be credited by citing this paper:';
            app.JoveHyperlink = uihyperlink(app.UIFigure);
            app.JoveHyperlink.URL = 'https://www.jove.com/t/62595/quantification-mouse-heart-left-ventricular-function-myocardial';
            app.JoveHyperlink.Text = 'JOVE';
            app.RetrospectiveTitleImage = uiimage(app.UIFigure);
            app.AutoRecoCheckBox = uicheckbox(app.UIFigure);
            app.AutoRecoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRecoCheckBoxValueChanged, true);
            app.AutoRecoCheckBox.Tooltip = {'Check for automatic reconstruction'};
            app.AutoRecoCheckBox.Text = '';
            app.AutoRecoLabel = uilabel(app.UIFigure);
            app.AutoRecoLabel.Tooltip = {'Check for automatic reconstruction'};
            app.AutoRecoLabel.Text = 'auto reco';
            app.MessageBox = uitextarea(app.UIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ...'};
            app.GitHubHyperlink = uihyperlink(app.UIFigure);
            app.GitHubHyperlink.URL = 'https://github.com/Moby1971/';
            app.GitHubHyperlink.Text = 'GitHub';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retrospective10

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end